classdef SASHIMI_v0_beta < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        mainFigure                    matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        loadImageMenu                 matlab.ui.container.Menu
        loadObjectMenu                matlab.ui.container.Menu
        saveObjectMenu                matlab.ui.container.Menu
        saveObjectAsMenu              matlab.ui.container.Menu
        exportMaskMenu                matlab.ui.container.Menu
        SliceLabel                    matlab.ui.control.Label
        SliceSlider                   matlab.ui.control.Slider
        ObjectsPanel                  matlab.ui.container.Panel
        DrawButton                    matlab.ui.control.Button
        ObjectTable                   matlab.ui.control.Table
        RemoveButton                  matlab.ui.control.Button
        splineCheckBox                matlab.ui.control.CheckBox
        TrackingPanel                 matlab.ui.container.Panel
        SlicetotrackSpinnerLabel      matlab.ui.control.Label
        SlicetotrackSpinner           matlab.ui.control.Spinner
        SmoothingSpinnerLabel         matlab.ui.control.Label
        SmoothingSpinner              matlab.ui.control.Spinner
        PyramidlevelsSpinnerLabel     matlab.ui.control.Label
        PyramidlevelsSpinner          matlab.ui.control.Spinner
        IterationsSpinnerLabel        matlab.ui.control.Label
        IterationsSpinner             matlab.ui.control.Spinner
        UpdateButton                  matlab.ui.control.Button
        TrackButton                   matlab.ui.control.Button
        OverwritelabelsDropDownLabel  matlab.ui.control.Label
        OverwriteLabelsDropdown       matlab.ui.control.DropDown
        StatusLabel                   matlab.ui.control.Label
        imagePanel                    matlab.ui.container.Panel
        slice_up                      matlab.ui.control.Button
        slice_down                    matlab.ui.control.Button
        StatusLight                   matlab.ui.control.Lamp
        ContrastPanel                 matlab.ui.container.Panel
        minContrastSlider             matlab.ui.control.Slider
        maxContrastSlider             matlab.ui.control.Slider
        autoContrastButton            matlab.ui.control.Button
        contrastRangeLabel            matlab.ui.control.Label
        holdContrast                  matlab.ui.control.StateButton
        MinLabel                      matlab.ui.control.Label
        MaxLabel                      matlab.ui.control.Label
        StackLabel                    matlab.ui.control.Label
        StackSpinner                  matlab.ui.control.Spinner
    end

    
    properties (Access = private)
        img           % image data (3D - current stack if data is 4D)
        img_4D        % image data (4D)
        spacing       % pixel spacing
        dim           % image dimensions
        maxObjects    % maximum number objects
        currentLabel_nr % number of the selected object in the UITable
        objectPos     % cell with position of nodes of polygon objects for all slice and objects
        slice_nr      % number of currently displayed slice
        stack_nr      % number of currently displayed stack (for multi-channel data)
        slice_nr_ref  % number of reference slice
        slice_nr_pred % number of predicted slice
        prev_gap      % previous gap value - used to exclude 0 gap
        objectFilename % filename of object structure
        metadata      % image NIfTI header
        colors        % color map
        tableData     % list of label numbers and names
        h_img         % image object
        h_mask        % mask object
        mask          % mask array
        rgbmask       % rgb mask of the current slice
        h_poly        % polygon graphics objects
        h_spline      % spline plot objects
        useSpline     % flat (true/false) to use spline (true) or polygon curve (false)
        showLabelName % flag (true/false) to show/hide label names
        polyLineWidth % line width of polygon objects
        polyStripeColor % stripe color of polygon objects
        polyFaceAlpha  % alpha-transparency value for polygon objects
        splineLineWidth % line width of spline
        splineLineStyle % line style of spline objects
        maskAlpha      % alpha-transparency value for mask overlay
        clim           % clim (contrast) property of image
        isPressed      % cell array with keys currently held
        lastSavedObjectPos % objectPos structure that was saved last.
        isBusy         % flag (true/false) indicating that no key presses can be received
        
    end
    
    properties (Access = public)
        h_ax      matlab.graphics.axis.Axes 
    end
    
    methods (Access = private)
        
        function loadImage(app,filename)
            
            % Update status bar
            [~,a,b] = fileparts(filename);
            short_filename = [a b];
            app.StatusLabel.Text = sprintf('Loading %s...',short_filename);
            app.StatusLight.Color =  [1.00,0.41,0.16];
            pause(0.01)
            
            % Load the data
            app.img      = niftiread(filename); % image data
            app.metadata = niftiinfo(filename); % metadata
            
            [~,b,c] = fileparts(filename);
            app.StatusLabel.Text = sprintf('%s loaded',[b,c]);
            app.StatusLight.Color = [0 1 0];
            
            % Update slice slider
            app.SliceSlider.Limits     = [1 app.metadata.ImageSize(3)];
            majorTicks                 = round(linspace(0,app.metadata.ImageSize(3),11));
            majorTicks(1)              = 1;
            app.SliceSlider.MajorTicks = majorTicks;
            app.SliceSlider.MinorTicks = [];
            app.slice_nr               = round(app.metadata.ImageSize(3)/2);
                
            % Check if 3D or 4D data was selected.
            if ndims(app.img) == 4
                % The image is 4D. Enable the stack spinner.
                app.stack_nr = 1;
                app.StackLabel.Enable   = 'on';
                app.StackSpinner.Enable = 'on';
                app.StackSpinner.Limits = [1 size(app.img,4)];
                app.StackSpinner.Value = app.stack_nr;
                app.img_4D = app.img;
                app.img = app.img_4D(:,:,:,app.stack_nr);
            elseif ndims(app.img) == 3
                app.StackLabel.Enable   = 'off';
                app.StackSpinner.Enable = 'off';
                app.stack_nr = 1;
                app.StackSpinner.Value = 1;
                app.StackSpinner.Limits = [1 2];
            else
                errordlg(sprintf('SASHIMI only accepts 3-D or 4-D data, but %d-D data was selected.',ndim(app.img)),...
                    'Wrong image dimensions')
                return
            end
            
            % Update image filename
            app.imagePanel.Title = short_filename;
            
            % Create empty object cell.
            app.objectPos = cell2struct(cell(2,app.metadata.ImageSize(3)), {'position','label_nr'}, 1);
            
            % Reset the object table.
            resetTable(app)
            
            % Delete all graphics objects from the current figure.
            delete(findobj(app.h_ax,'Type','images.roi.polygon'))
            app.h_poly         = [];
            app.h_spline       = [];
            app.objectFilename = [];
            
            % Enable objects
            app.slice_up.Enable         = 'on';
            app.slice_down.Enable       = 'on'; 
            app.SliceSlider.Enable      = 'on';
            app.SliceLabel.Enable       = 'on';
            app.TrackButton.Enable      = 'on';
            app.loadImageMenu.Enable    = 'on';
            app.loadObjectMenu.Enable   = 'on';
            app.saveObjectAsMenu.Enable = 'on';
            app.saveObjectMenu.Enable   = 'off';
            app.exportMaskMenu.Enable   = 'on';
            set(get(app.ContrastPanel,'Children'),'Enable','On');
            set(get(app.ObjectsPanel,'Children'),'Enable','On');
            
            % Display the middle slice and update the axes properties
            app.slice_nr = round(app.metadata.ImageSize(3)/2);
            
            % Set XData and YData for the current image
            set(app.h_img,...
                'XData',[0 (app.metadata.ImageSize(2)-1)*app.metadata.PixelDimensions(1)],...
                'YData',[0 (app.metadata.ImageSize(1)-1)*app.metadata.PixelDimensions(2)],...
                'CData',zeros(app.metadata.ImageSize(1:2)))
            
            % Set limits and values of contrast sliders
            data_range = double([min(app.img(:)),max(app.img(:))]);
            app.minContrastSlider.Limits = data_range - [0 1];
            app.maxContrastSlider.Limits = data_range + [1 0];            
            app.minContrastSlider.Value = data_range(1);
            app.maxContrastSlider.Value = data_range(2);
            
            % Create empty mask and RGB-mask object and set some properties
            app.mask = zeros(app.metadata.ImageSize(1:3));
            set(app.h_mask,...
                'XData',[0 (app.metadata.ImageSize(2)-1)*app.metadata.PixelDimensions(1)],...
                'YData',[0 (app.metadata.ImageSize(1)-1)*app.metadata.PixelDimensions(2)],...
                'CData',app.rgbmask,...
                'Visible','on');
            setRGBMask(app)
            
            % Set axis properties
            set(app.h_ax,...
                'XLim',[0 (app.metadata.ImageSize(2)-1)*app.metadata.PixelDimensions(1)],...
                'YLim',[0 (app.metadata.ImageSize(1)-1)*app.metadata.PixelDimensions(2)],...
                'YDir','reverse',...
                'Position',[0 0 1 1])
            axis(app.h_ax,'equal')
            
            % Update the image display
            showSlice(app)            
            
            % Disable the update button
            app.UpdateButton.Enable = 'off';               
        end
              
        function addAxes(app)
            % Create classic Axes object into the Panel
            app.h_ax = axes(app.imagePanel,...
                'Position',[0,0,1,1]);
        end
        
        function updateObject(app,src,event)
            
            % Get the number of the graphics object from the object's UserData.
            obj_nr = get(src,'UserData');
            switch event.EventName
                case 'ROIClicked'
                    if strcmp(event.SelectionType,'double')
                        % Point was inserted. Update the position.
                        pos = get(src,'Position');
                    else
                        return
                    end
                case {'MovingROI','ROIMoved'} % ROI is moving/has moved
                    % Update the cell with object position data of the current polygon graphics 
                    % objects so that interactive changes are saved.
%                     obj_nr = cellfun(@(x) isequal(x,event.PreviousPosition),app.objectPos(app.slice_nr).position);
                    pos = event.CurrentPosition;
            end
            
            % Update objectPos with the new position data.
            app.objectPos(app.slice_nr).position{obj_nr} = pos;
            
            % Update the spline curve.
            SP = fit_spline(pos);
            set(app.h_spline(obj_nr),...
                'XData',SP(:,1),...
                'YData',SP(:,2),...
                'Visible',app.useSpline)
            
            % Update the mask for the current slice
            updateMask(app)
            setRGBMask(app)
        end
        
        function showSlice(app)
            % Update image.
            set(app.h_img,...
                'CData',app.img(:,:,app.slice_nr))
            
            % Update mask
            setRGBMask(app)            
            
            if app.holdContrast.Value == false
                % Automatically set the contrast to the data range
                evt.Source.Tag = 'auto';    
                setContrast(app,evt);
            end
            
            % Update the polygon and spline graphics objects
            % ... first, delete all existing graphics objects.
            delete(findobj(app.h_ax,'Type','images.roi.polygon'))
            delete(findobj(app.h_ax,'Type','line'))
            app.h_poly   = [];
            app.h_spline = [];
            for obj_nr = 1 : length(app.objectPos(app.slice_nr).position)
                    % Create an interactive polygon.
                    pos = app.objectPos(app.slice_nr).position{obj_nr};
                    label_nr = app.objectPos(app.slice_nr).label_nr(obj_nr);                    
                    if app.showLabelName == true
                        lbl = app.tableData{label_nr,2};
                    else
                        lbl = '';
                    end
                    app.h_poly(obj_nr) = images.roi.Polygon('Parent',app.h_ax);
                    set(app.h_poly(obj_nr),...
                        'Label',lbl,...
                        'Color',app.colors(label_nr,:),...
                        'FaceAlpha',app.polyFaceAlpha,...
                        'Position',pos,...
                        'InteractionsAllowed','all',...
                        'UserData',obj_nr,...
                        'LineWidth',app.polyLineWidth,...
                        'StripeColor',app.polyStripeColor);
                    
                    % Add listeners for detecting changes in position/clicks.
                    addlistener(app.h_poly(obj_nr), 'MovingROI', @(src,event) updateObject(app,src,event));
                    addlistener(app.h_poly(obj_nr), 'ROIClicked', @(src,event) updateObject(app,src,event));
                    
                    % Update the spline curve.
                    SP = fit_spline(pos);
                    app.h_spline(obj_nr) = plot(app.h_ax,...
                        SP(:,1),SP(:,2),...
                        'LineWidth',app.splineLineWidth,...
                        'Color',app.colors(label_nr,:),...
                        'Visible',app.useSpline);
            end
            
            % If no objects are available on the current slice, disable the track button.
            if isempty(app.h_poly)
                app.TrackButton.Enable = 'off';
            else
                app.TrackButton.Enable = 'on';
            end
            
            % Disable the update button.
            app.UpdateButton.Enable = 'off';
            
            % Update slider
            app.SliceSlider.Value = app.slice_nr;
            app.SliceLabel.Text   = sprintf('%d/%d',app.slice_nr,app.metadata.ImageSize(3));
        end
        
        function toggleLabelName(app)
                if app.showLabelName == false
                    app.showLabelName = true;
                else
                    app.showLabelName = false;
                end
                for obj_nr = 1 : length(app.h_poly)
                    if app.showLabelName == true
                        lbl = app.tableData{app.objectPos(app.slice_nr).label_nr(obj_nr),2};
                        app.StatusLabel.Text = 'Label names shown';
                    else
                        lbl = '';
                        app.StatusLabel.Text = 'Label names hidden';
                    end
                    set(app.h_poly(obj_nr),'Label',lbl)
                end
            
        end
        
        function deletePoint(app)
                % Delete the point closest to the last clicked location.
                p = get(app.h_ax,'CurrentPoint');
                
                % If the last clicked location is more than 2 pixels removed from the nearest point,
                % no point is deleted.
                mindist = 2*max(app.metadata.PixelDimensions(1:2));
                min_idx = [];
                min_obj_nr = [];
                for obj_nr = 1 : length(app.h_poly)
                    pos = get(app.h_poly(obj_nr),'Position');
                    [d,idx] = min(sqrt(sum( (pos-p(1,1:2)).^2,2)));
                    if d < mindist
                        mindist = d;
                        min_idx = idx;
                        min_obj_nr = obj_nr;
                    end
                end
                if ~isempty(min_idx)
                    pos = get(app.h_poly(min_obj_nr),'Position');
                    pos(min_idx,:) = [];
                    if size(pos,1) < 3
                        % Delete the object if fewer than 3 points are left in the polygon.
                        delete(app.h_poly(min_obj_nr))
                        app.h_poly(min_obj_nr) = [];
                        app.objectPos(app.slice_nr).position{min_obj_nr} = [];
                        app.objectPos(app.slice_nr).label_nr(min_obj_nr) = [];
                        app.StatusLabel.Text = 'Object removed because fewer than 3 points were left.';
                    else
                        % Update objectPos and the graphics objects
                        set(app.h_poly(min_obj_nr),...
                            'Position',pos)
                        SP = fit_spline(pos);
                        set(app.h_spline(min_obj_nr),...
                            'XData',SP(:,1),...
                            'YData',SP(:,2));                        
                        app.objectPos(app.slice_nr).position{min_obj_nr} = pos;
                        app.StatusLabel.Text = 'One point deleted.';
                    end
                    % Update the mask.
                    updateMask(app)
                    setRGBMask(app)
                end            
            
        end
        
        function resetTable(app)
            % Resets the table.
            app.maxObjects = 12;
            app.tableData = cell(app.maxObjects,2);
            for obj_nr = 1 : app.maxObjects
                app.tableData{obj_nr ,1} = sprintf('%d',obj_nr);
                app.tableData{obj_nr,2}  = sprintf('Label%02d',obj_nr);
                app.tableData{obj_nr,3}  = true;
            end
            app.ObjectTable.Data = app.tableData;
            app.colors = [...                
                141,211,199;
                255,255,179;
                190,186,218;
                251,128,114;
                128,177,211;
                253,180,98;
                179,222,105;
                252,205,229;
                217,217,217;
                188,128,189;
                204,235,197;
                255,237,111] / 255;
            
            app.ObjectTable.BackgroundColor = app.colors(1:app.maxObjects,:);            
        end
        
        
        function setRGBMask(app)
            % Make an RGB overlay mask of the current slice.
            
            R = zeros(app.metadata.ImageSize(1:2));
            G = zeros(app.metadata.ImageSize(1:2));
            B = zeros(app.metadata.ImageSize(1:2));
            label_nrs = unique(app.objectPos(app.slice_nr).label_nr);
            for i = 1 : length(label_nrs)
                label_nr = label_nrs(i);
                R(app.mask(:,:,app.slice_nr) == label_nr) = app.colors(label_nr,1);
                G(app.mask(:,:,app.slice_nr) == label_nr) = app.colors(label_nr,2);
                B(app.mask(:,:,app.slice_nr) == label_nr) = app.colors(label_nr,3);
            end
            app.rgbmask = cat(3,R,G,B);
            
            % Overlay the mask with the selected transparency. Pixels that are not labeled
            % have a transparency of 0 (fully transparent).
            set(app.h_mask,...
                'CData',app.rgbmask,...
                'AlphaData',(app.mask(:,:,app.slice_nr) ~= 0)*app.maskAlpha)
        end
        
        function updateMask(app,slice_nrs)
            % Make a mask with pixels within each object assigned the object's label number.
            % If a pixel belongs to multiple labels (i.e. overlapping objects with different label numbers),
            % the pixel gets the label value of the last created object.
            if nargin == 1
                % Updates the mask for the current slice only.
                slice_nrs = app.slice_nr;
            end
            
            % Update the mask, one slice at a time.
            for slice_nr = slice_nrs
                n_obj = length(app.objectPos(slice_nr).position);
                slice_mask = zeros(app.metadata.ImageSize(1),app.metadata.ImageSize(2));
                for obj_nr = 1 : n_obj
                    
                    % Get position and label number for the current object
                    pos      = app.objectPos(slice_nr).position{obj_nr};
                    label_nr = app.objectPos(slice_nr).label_nr(obj_nr);
                    if app.useSpline == true
                        % Update mask with pixels encapsulated by the spline curve.
                        pos = fit_spline(pos);
                    end
                    
                    % Convert polygon position from mri coordinates to pixel coordinates. Then create a binary mask of all pixels inside the object.
                    p_voxel = pos./app.metadata.PixelDimensions(1:2) + 1;
                    binaryMask = poly2mask(...
                        p_voxel(:,1),...
                        p_voxel(:,2),...
                        app.metadata.ImageSize(1),app.metadata.ImageSize(2));
                    
                    % Assign the object's label number to the pixels inside the object.
                    slice_mask(binaryMask) = label_nr;
                end
                app.mask(:,:,slice_nr) = slice_mask;
            end
        end
        
        function toggleMaskVisibility(app)
            switch get(app.h_mask,'Visible')
                case 'on'
                    set(app.h_mask,'Visible','off')                    
                    app.StatusLabel.Text = 'Mask hidden';
                case 'off'
                    set(app.h_mask,'Visible','on')
                    app.StatusLabel.Text = 'Mask shown';
            end
            
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                     
            % Prefill table
            resetTable(app)
            app.showLabelName = false;
            
            % Set some tags.
            app.slice_up.Tag     = 'up';
            app.slice_down.Tag   = 'down';
            app.SliceSlider.Tag  = 'slider';
            app.TrackButton.Tag  = 'track';
            app.UpdateButton.Tag = 'update';     
            app.saveObjectAsMenu.Tag   = 'saveas';
            app.saveObjectMenu.Tag     = 'save';
            app.minContrastSlider.Tag = 'min';
            app.maxContrastSlider.Tag = 'max';
            app.autoContrastButton.Tag = 'auto';
            
            % Add the axes object.
            app.addAxes;
            
%           % Create an image object.
            hold(app.h_ax,'on')
            app.h_img = image(app.h_ax,...
                zeros(2,2),...
                'CDataMapping','scaled');
            
            % Create a mask object.
            app.h_mask = image(app.h_ax,...
                zeros(2,2,3));
            
            % Set the default colormap as grayscale.
            colormap(app.h_ax,gray(2000))
            app.h_ax.CLim = [0 1];
            axis(app.h_ax,'off') % hide the x- and y-axis
            hold(app.h_ax,'on');
            
            % Set previous gap value to the default value.
            app.prev_gap = app.SlicetotrackSpinner.Value;

            % Set default label number to 1
            app.currentLabel_nr = 1;
            
            % Set some default graphics properties
            app.polyLineWidth   = 1.5;
            app.polyFaceAlpha   = 0;
            app.splineLineWidth = 1.5;
            app.splineLineStyle = '-';
            app.maskAlpha       = 0.5;            
            app.useSpline       = false; % by default, use the polygon object instead of the spline
            app.polyStripeColor = 'none';
            
            app.isPressed = {};
            app.isBusy = false;
            app.objectPos = cell2struct(cell(2,1), {'position','label_nr'}, 1);
        end

        % Menu selected function: loadImageMenu
        function selectImage(app, event)
            [filename,pathname] = uigetfile({'*.nii;*.nii.gz;',...
                'Image files (*.nii,*.nii.gz)'},...
                'Select an image file',[],'MultiSelect','off');
            if pathname == 0
                return
            else
                loadImage(app,fullfile(pathname,filename));
            end
        end

        % Button pushed function: DrawButton
        function drawObject(app, event)
            % Get selected object from the labelTable.
            if isempty(app.currentLabel_nr)
                app.StatusLabel.Text = 'No label selected.';
                return
            end
            
            % Set the status label and color.
            app.StatusLight.Color = [1.00,0.41,0.16];
            app.StatusLabel.Text = 'Select corners. Press ''Enter'' to close polygon...';
            
            % Draw a polygon
            obj_nr = length(app.h_poly) + 1;
            app.isBusy = true;
            app.h_poly(obj_nr) = drawpolygon(...
                'Color',app.colors(app.currentLabel_nr,:),...
                'FaceAlpha',app.polyFaceAlpha,...
                'Parent',app.h_ax,...   
                'InteractionsAllowed','all',...
                'UserData',obj_nr,...
                'LineWidth',app.polyLineWidth,...
                'StripeColor',app.polyStripeColor);
            app.isBusy = false;
            
            % Delete the object if fewer than 3 points were selected.
            if size(get(app.h_poly(end),'Position'),1) < 3
                app.StatusLabel.Text = 'Minimum of 3 points should be selected.';
                delete(app.h_poly(end))
                app.h_poly(end) = [];
                return                    
            end
            % Add listeners for updating objectPos and masks when the objects is changed (moved, point added, point deleted).
            addlistener(app.h_poly(obj_nr), 'MovingROI', @(src,event) updateObject(app,src,event));
            addlistener(app.h_poly(obj_nr), 'ROIClicked', @(src,event) updateObject(app,src,event));
            if app.showLabelName == true
                set(app.h_poly(end),...
                    'Label',app.tableData{app.currentLabel_nr,2})
            end
            app.StatusLabel.Text = '';
            app.StatusLight.Color = [0 1 0];                
            
            % Add position/label data to objectPos
            pos = get(app.h_poly(end),'Position');
            app.objectPos(app.slice_nr).position{obj_nr} = ...
                pos;
            app.objectPos(app.slice_nr).label_nr(obj_nr) = ...
                app.currentLabel_nr;
            
            % Create and show the spline curve
            C = fit_spline(pos);
            app.h_spline(obj_nr) = plot(...
                app.h_ax,C(:,1),C(:,2),...
                'Color',app.colors(app.currentLabel_nr,:),...
                'LineStyle',app.splineLineStyle,...
                'LineWidth',app.splineLineWidth,...
                'Visible',app.useSpline);
            
            % Update the mask
            updateMask(app)
            setRGBMask(app)
            
            % Enable the track button and disable the update button.
            app.TrackButton.Enable  = 'on';
            app.UpdateButton.Enable = 'off';
        end

        % Cell selection callback: ObjectTable
        function setCurrentObject(app, event)
            % Set selected label to the current object number.
            app.currentLabel_nr = event.Indices(1);
            app.StatusLabel.Text = sprintf('Current label set to %d',app.currentLabel_nr);
        end

        % Cell edit callback: ObjectTable
        function updateTable(app, event)
            app.tableData{event.Indices(1),event.Indices(2)} = event.NewData;
            
        end

        % Button pushed function: TrackButton, UpdateButton
        function trackObjects(app, event)
% 
%             fprintf('objects in predicted slice before tracking:\n')
%             app.objectPos(app.slice_nr_pred)

            
            % Register slices with the selected settings            
            switch event.Source.Tag
                case 'track'
                    % Track new slice. Set the reference and to-be-predicted slice numbers.                   
                    app.slice_nr_ref  = app.slice_nr;
                    app.slice_nr_pred = app.slice_nr + app.SlicetotrackSpinner.Value;
                case 'update'
                    % Track again with updated settings.
%                     app.slice_nr = app.slice_nr_pred;
            end
            
            if app.slice_nr_pred < 1 || app.slice_nr_pred > app.metadata.ImageSize(3)
                warndlg('Tried to predict outside the image range. Change the slice or slice gap and try again',...
                    'Slice outside range')
                return
            end
            
            % ------------- Determine which objects to track -------------
            % Only track objects for which the 'track' checkbox in the table was set to true.
            label_nrs_to_track = find(cell2mat(app.tableData(:,3)));
            object_nrs_to_track = find(ismember(app.objectPos(app.slice_nr_ref).label_nr,label_nrs_to_track));
            
            if isempty(object_nrs_to_track)
                app.StatusLabel.Text = 'Nothing to track.';
                return
            end
            
            % ------------- Perform demons registration -------------
            % Update the status label and set the status light to orange.
            app.StatusLabel.Text = 'Registering images...';
            app.StatusLight.Color = [1.00,0.41,0.16];            
            pause(0.01) % a short pause is set here to make sure the status light color changes.
            
            tic
            [~,~,new_pos] = ...
                register_slices(...
                app.img(:,:,app.slice_nr_pred),app.img(:,:,app.slice_nr_ref),...
                'N',                        app.IterationsSpinner.Value,...
                'PyramidLevels',            app.PyramidlevelsSpinner.Value,...
                'AccumulatedFieldSmoothing',app.SmoothingSpinner.Value,...
                'points',                   app.objectPos(app.slice_nr_ref).position(object_nrs_to_track),...
                'spacing',                  app.metadata.PixelDimensions(1:2));
            new_label_nr = app.objectPos(app.slice_nr_ref).label_nr(object_nrs_to_track);
            t_elapsed = toc;
            
            % Report back how long it took to register the images and change the status light back to green.
            app.StatusLabel.Text = sprintf('It took %.2f sec to track.',t_elapsed);
            app.StatusLight.Color = [0 1 0];            
            
            % ------------- Update position data and graphics objects  -------------
            
            % Determine which label numbers from the predicted slice should be kept/removed.
            label_nrs_pred = unique(app.objectPos(app.slice_nr_pred).label_nr);
            switch app.OverwriteLabelsDropdown.Value
                case 'Tracked'
                    % Only keep objects in the predicted slice with label numbers other than the tracked objects.
                    object_nrs_to_keep = find(~ismember(label_nrs_pred,label_nrs_to_track));
                case 'None'
                    % No objects should be removed. Keep all previously existing objects in the predicted slice.
                    object_nrs_to_keep = 1 : length(app.objectPos(app.slice_nr_pred).label_nr);
                case 'All'
                    % All objecst should be removed.
                    object_nrs_to_keep = [];
            end
            
            % Add the tracked objects to the already existing objects.
            app.objectPos(app.slice_nr_pred).position = [app.objectPos(app.slice_nr_pred).position(object_nrs_to_keep) new_pos];
            app.objectPos(app.slice_nr_pred).label_nr = [app.objectPos(app.slice_nr_pred).label_nr(object_nrs_to_keep) new_label_nr];
            
            % Update the figure. Show the tracked slice.
            app.slice_nr = app.slice_nr_pred;
            updateMask(app)
            showSlice(app)
                        
            % Enable the update button.
            app.UpdateButton.Enable = 'on';
            
%             fprintf('objects in predicted slice after tracking:\n')
%             app.objectPos(app.slice_nr_pred)
            
        end

        % Callback function: SliceSlider, slice_down, slice_up
        function changeSlice(app, event)
        
            switch event.Source.Tag
                case 'up'
                    % Scroll one slice up
%                     updateObject(app)
                    app.slice_nr = min([app.metadata.ImageSize(3),app.slice_nr + 1]);
                    app.UpdateButton.Enable = 'off';
                case 'down'
                    % Scroll one slice down
%                     updateObject(app)
                    app.slice_nr = max([1,app.slice_nr - 1]);
                    app.UpdateButton.Enable = 'off';
                case 'slider'
                    % Show the slider-selected slice
%                     updateObject(app)
                    app.slice_nr = round(app.SliceSlider.Value);
                    app.UpdateButton.Enable = 'off';
                case 'track'
                    % Show the registration-predicted slice
                    app.slice_nr = app.slice_nr_pred;
            end
            
            showSlice(app)
        end

        % Button pushed function: RemoveButton
        function removeObject(app, event)
            % Delete the polygons which contain the last selected point on the screen.
            p = get(app.h_ax,'CurrentPoint');
            tobeRemoved = false(length(app.h_poly),1);
            for obj_nr = 1 : length(app.h_poly)
                pos                 = get(app.h_poly(obj_nr),'Position');
                tobeRemoved(obj_nr) = inpolygon(p(1,1),p(1,2),pos(:,1),pos(:,2));
            end
            
            if any(tobeRemoved)
                % remove graphics object and update objectPos
                delete(app.h_poly(tobeRemoved))
                delete(app.h_spline(tobeRemoved))
                app.objectPos(app.slice_nr).position(tobeRemoved) = [];
                app.objectPos(app.slice_nr).label_nr(tobeRemoved) = [];
                app.h_poly(tobeRemoved)                           = [];
                app.h_spline(tobeRemoved)                         = [];
                app.StatusLabel.Text = sprintf('%d object(s) removed.',sum(tobeRemoved));
                
                % Update the UserData (object numbers)
                for obj_nr = 1 : length(app.h_poly)
                    set(app.h_poly(obj_nr),'UserData',obj_nr)
                end
                
                % Update the mask
                updateMask(app)
                setRGBMask(app)
                
            end
        end

        % Size changed function: mainFigure
        function changeSize(app, event)
            position = app.mainFigure.Position;
            minsize = [900 750];
            if position(3) < minsize(1)
                position(3) = minsize(1);                
            end
            if position(4) < minsize(2)
                position(4) = minsize(2);                
            end
            app.mainFigure.Position = position;
            
            % Status light and text
            p = app.StatusLight.Position;
            app.StatusLight.Position = [position(3)-30 p(2:4)];
            p = app.StatusLabel.Position;
            app.StatusLabel.Position = [position(3)-210 p(2:4)];
%             app.StatusLabel.Text = 'Status';
            
            % Object and tracking panel
            % Align bottom of tracking panel with bottom of image panel
            p = app.TrackingPanel.Position;
            app.TrackingPanel.Position = [position(3)-205 p(2) p(3:4)];
            
            % Place the contrast panel on top of the tracking panel with 5 pixels spacing.
            p = app.ContrastPanel.Position;
            yPos = app.TrackingPanel.Position(2) + app.TrackingPanel.Position(4) + 5;
            app.ContrastPanel.Position = [position(3)-205 yPos p(3:4)];
            
            % Place the object panel on top of the contrast panel with 5 pixel spacing.
            % Align the top of the contrast panel with the top of the image panel.
            p = app.ObjectsPanel.Position;
            yPos = app.ContrastPanel.Position(2) + app.ContrastPanel.Position(4) + 5;
            h = position(4) - yPos - 5;
            app.ObjectsPanel.Position = [position(3)-205 yPos  p(3) h];
            
            % Image panel
            p = app.imagePanel.Position;
            app.imagePanel.Position = [p(1) p(2) position(3)-220 position(4)-80];
            
            % Slice slider and stack spinners
            p = app.SliceSlider.Position;
            app.SliceSlider.Position = [p(1:2) position(3)-380 p(4)];
            p = app.slice_up.Position;
            app.slice_up.Position = [position(3)-320 p(2:4)];
            p = app.StackSpinner.Position;
            app.StackSpinner.Position = [position(3)-255 p(2:4)];
            p = app.StackLabel.Position;
            app.StackLabel.Position = [position(3)-295 p(2:4)];
            
            % Set axis equal again to mainrain stretch-to-fill behavior
            axis(app.h_ax,'equal')
            
        end

        % Window key press function: mainFigure
        function keyPress(app, event)
            key = event.Key;
            
            % Add the pressed key to isPressed
            app.isPressed{end+1} = key;
            
            if app.isBusy == true
                return
            end
%             app.isPressed
            
            % Don't do anything. If a modifier key is pressed.            
            if any(ismember(app.isPressed,{'control','alt','shift','windows'}))                
                return
            end
            
            if startsWith(key,'numpad')
                % Remove 'numpad' from the key string if numbers on the numeric pad were pressed.
                key = key(7:end);
            end
            if strcmpi(key,'t') % track
                if strcmp(app.TrackButton.Enable,'on')
                    event.Source.Tag = 'track';
                    trackObjects(app,event)
                end
            elseif strcmpi(key,'u') % update tracking
                if strcmp(app.UpdateButton.Enable,'on')
                    event.Source.Tag = 'update';
                    trackObjects(app,event)
                end
            elseif strcmpi(key,'d') % draw object
                if strcmp(app.DrawButton.Enable, 'on')
                    drawObject(app,event)
                end
            elseif strcmpi(key,'r') % remove object
                if strcmp(app.RemoveButton.Enable, 'on')
                    removeObject(app,event)
                end
            elseif strcmpi(key,'downarrow') % show previous slice
                event.Source.Tag = 'down';
                changeSlice(app,event);
            elseif strcmpi(key,'uparrow') % show next slice
                event.Source.Tag = 'up';
                changeSlice(app,event);
            elseif any(strcmpi(key,{'z','delete'})) % delete points
                % Remove point nearest to the last clicked cursor point.
                deletePoint(app)
            elseif strcmpi(key,'v') % toggle label name visibility
                toggleLabelName(app)                
            elseif strcmp(key,'m') % preview mask
                toggleMaskVisibility(app)        
            elseif strcmp(key,'x') % toggle spline on/off
                toggleSpline(app)
            elseif isnumeric(str2double(key)) && ~isnan(str2double(key)) % numeric input.
                % set selected number to the current label number
                num = real(str2double(key));
                if num ~= 0
                    app.currentLabel_nr = num;
                    app.StatusLabel.Text = sprintf('Current label set to %d',num);
                end
            elseif strcmpi(key,'period')
                % increase mask tranparency
                app.maskAlpha = min([1,app.maskAlpha + 0.1]);
                set(app.h_mask,...
                    'ALphaData',(app.mask(:,:,app.slice_nr) ~= 0)*app.maskAlpha)
                app.StatusLabel.Text = sprintf('Mask tranparency set to %.1f',app.maskAlpha);
            elseif strcmpi(key,'comma')
                % Decrease mask tranparency
                app.maskAlpha = max([0.2,app.maskAlpha - 0.1]);
                set(app.h_mask,...
                    'ALphaData',(app.mask(:,:,app.slice_nr) ~= 0)*app.maskAlpha)
                app.StatusLabel.Text = sprintf('Mask tranparency set to %.1f',app.maskAlpha);
            end
        end

        % Value changed function: SlicetotrackSpinner
        function sliceGapSpinner(app, event)
            % This function makes sure that the value of the gap spinner is never 0.
            value = app.SlicetotrackSpinner.Value;
            if value == 0
                if app.prev_gap > 0
                    app.SlicetotrackSpinner.Value = -1;
                    app.prev_gap = -1;
                else
                    app.SlicetotrackSpinner.Value = 1;
                    app.prev_gap = 1;
                end
            end
                
        end

        % Menu selected function: saveObjectAsMenu, saveObjectMenu
        function saveObjects(app, event)
            if strcmp(event.Source.Tag,'saveas') || isempty(app.objectFilename)
                % Select a file or set a new filename.
                [file,path] = uiputfile('*.mat','Select/set a filename to save object to');
                if path == 0
                    return
                else
                    app.objectFilename = fullfile(path,file);
                end
            end
            objectPos = app.objectPos;
            tableData = app.tableData;
            save(app.objectFilename,'objectPos','tableData')
            [~,b,c] = fileparts(app.objectFilename);
            app.StatusLabel.Text = sprintf('Objects saved as %s',[b,c]);
            app.saveObjectMenu.Enable = 'on';
            app.lastSavedObjectPos = objectPos;
        end

        % Menu selected function: exportMaskMenu
        function exportMask(app, event)
            
            % Select a file or set a new filename
            [file,path] = uiputfile({'*.nii;*.nii.gz','NIfTI image files'},'Export to NIfTI segmentation','mask');
            if file == 0
                return
            else                
                fname_mask = fullfile(path,file);                
            end
            
            if endsWith(fname_mask,'.gz')
                fname_mask = fname_mask(1:end-3);
            end
            % Create a mask from the polygon objects for all slices.
            updateMask(app,1:app.metadata.ImageSize(3))

            % Update some of the header information and export as a NIfTI file.
            info = app.metadata;
            info.Filename = fname_mask;
            info.BitsPerPixel = 16;
            info.Datatype = 'int16';
            info.raw.dim(1) = 3;
            info.raw.dim(5) = 1;
            info.raw.datatype  = 4;
            info.raw.bitpix    = 16;
            info.raw.scl_slope = 1;
            info.raw.scl_inter = 0;
            niftiwrite(int16(app.mask),fname_mask,info,...
                'Compressed',true,...
                'Combined',true)
            [~,b,c] = fileparts(fname_mask);
            app.StatusLabel.Text = sprintf('Mask exported as %s',[b,c]);
        end

        % Menu selected function: loadObjectMenu
        function loadObject(app, event)
            [file,path] = uigetfile({'*.mat','Matlab MAT-file'},'Select an object file');
            if file == 0
                return
            else
                filedata = load(fullfile(path,file));
                if isfield(filedata,'objectPos')
                    if length(filedata.objectPos) ~= app.metadata.ImageSize(3)
                        errordlg(sprintf('Object could not be loaded because the selected object does not match the slice number of the current image.\nThe number of slices should be %d but was %d.',...
                            app.metadata.ImageSize(3),length(filedata.objectPos)),...
                            'Wrong object dimensions')
                        return
                    end
                        
                    % Update objectPos and the object table
                    app.objectPos        = filedata.objectPos;
                    app.ObjectTable.Data = filedata.tableData;
                    app.StatusLabel.Text = sprintf('Object file %s loaded',file);
                    
                    % Update the mask and update the graphics objects.
                    updateMask(app,1:app.metadata.ImageSize(3))
                    showSlice(app)
                else
                    errordlg('Variable ''objectPos'' not found in the selecte MAT-file. Object data could not be loaded.')
                end
            end
                
        end

        % Value changed function: splineCheckBox
        function toggleSpline(app, event)
            if nargin == 1
                if app.splineCheckBox.Value == true
                    app.useSpline = false;
                    app.splineCheckBox.Value = false;
                else
                    app.useSpline = true;
                    app.splineCheckBox.Value = true;
                end
            else 
                app.useSpline = app.splineCheckBox.Value;
            end
            set(app.h_spline,'Visible',app.useSpline)
            if app.useSpline == true
                app.polyStripeColor = 'k';
                set(app.h_poly,'StripeColor',app.polyStripeColor)
            else
                app.polyStripeColor = 'none';
                set(app.h_poly,'StripeColor',app.polyStripeColor)
            end
            updateMask(app)
            setRGBMask(app)
            
        end

        % Callback function: autoContrastButton, maxContrastSlider, 
        % minContrastSlider
        function setContrast(app, event)
            switch event.Source.Tag
                case 'min'
                     app.clim(1) = app.minContrastSlider.Value;
                     if app.clim(1) >= app.maxContrastSlider.Value
                         app.clim(2) = app.clim(1) + 1;
                         app.maxContrastSlider.Value = app.clim(2);
                     end
                case 'max'
                     app.clim(2) = app.maxContrastSlider.Value;
                     if app.clim(2) <= app.minContrastSlider.Value
                         app.clim(1) = app.clim(2) - 1;
                         app.minContrastSlider.Value = app.clim(1);
                     end
                case 'auto'
                    % Set to minimum and maximum of current slice.
                    app.clim = double([min(min(get(app.h_img,'CData'))),max(max(get(app.h_img,'CData')))]);
                    app.minContrastSlider.Value = app.clim(1);
                    app.maxContrastSlider.Value = app.clim(2);                    
            end
            % Update range label
            app.contrastRangeLabel.Text = sprintf('Range: %.1f  - %.1f',app.clim(1),app.clim(2));
            set(app.h_ax,'CLim',app.clim)
            
        end

        % Window key release function: mainFigure
        function keyRelease(app, event)
            key = event.Key;
            
            % Remove released key from isPressed array
            app.isPressed = app.isPressed(~strcmp(key,app.isPressed));
        end

        % Close request function: mainFigure
        function closeRequest(app, event)
            
            % Check if the current objectPos is the same as the last saved version AND contains any data.
            % If not, ask the user to save the changes before closing SASHIMI.
            objectPosIsEmpty = all(all(cellfun(@(x)isempty(x),struct2cell(app.objectPos))));
            isEqualToLastSaved = isequal(app.lastSavedObjectPos,app.objectPos);
            if ~isEqualToLastSaved && ~objectPosIsEmpty
                answer = questdlg('There are unsaved changes to the objects. Do you want to save the changes before closing SASHIMI?',...
                    'Unsaved changes','Yes','No (close SASHIMI)','Cancel','Yes');
                switch answer
                    case 'Yes' % Save changes.
                        evt.Source.Tag = 'save';
                        saveObjects(app,evt)
                        delete(app)
                    case 'No (close SASHIMI)' % Do not save changed. Close SASHIMI.
                        delete(app)
                    case 'Cancel'
                        % Do nothing. Return to SASHIMI.
                end
            else
                delete(app)                
            end
        end

        % Value changed function: StackSpinner
        function changeStack(app, event)
            % Get the stack number
            app.stack_nr = app.StackSpinner.Value;
            
            % Update app.img
            app.img = app.img_4D(:,:,:,app.stack_nr);
            set(app.h_img,...
                'CData',app.img(:,:,app.slice_nr));
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.mainFigure = uifigure;
            app.mainFigure.Name = 'SASHIMI Segmentation - v1.0 beta';
            app.mainFigure.CloseRequestFcn = createCallbackFcn(app, @closeRequest, true);
            app.mainFigure.SizeChangedFcn = createCallbackFcn(app, @changeSize, true);
            app.mainFigure.WindowKeyPressFcn = createCallbackFcn(app, @keyPress, true);
            app.mainFigure.WindowKeyReleaseFcn = createCallbackFcn(app, @keyRelease, true);
            app.FileMenu = uimenu(app.mainFigure);
            app.FileMenu.Text = 'File';
            app.loadImageMenu = uimenu(app.FileMenu);
            app.loadImageMenu.MenuSelectedFcn = createCallbackFcn(app, @selectImage, true);
            app.loadImageMenu.Separator = 'on';
            app.loadImageMenu.Accelerator = 'o';
            app.loadImageMenu.Text = 'Load image...';
            app.loadObjectMenu = uimenu(app.FileMenu);
            app.loadObjectMenu.MenuSelectedFcn = createCallbackFcn(app, @loadObject, true);
            app.loadObjectMenu.Enable = 'off';
            app.loadObjectMenu.Accelerator = 'l';
            app.loadObjectMenu.Text = 'Load objects...';
            app.saveObjectMenu = uimenu(app.FileMenu);
            app.saveObjectMenu.MenuSelectedFcn = createCallbackFcn(app, @saveObjects, true);
            app.saveObjectMenu.Enable = 'off';
            app.saveObjectMenu.Separator = 'on';
            app.saveObjectMenu.Accelerator = 's';
            app.saveObjectMenu.Text = 'Save objects';
            app.saveObjectAsMenu = uimenu(app.FileMenu);
            app.saveObjectAsMenu.MenuSelectedFcn = createCallbackFcn(app, @saveObjects, true);
            app.saveObjectAsMenu.Enable = 'off';
            app.saveObjectAsMenu.Text = 'Save objects as...';
            app.exportMaskMenu = uimenu(app.FileMenu);
            app.exportMaskMenu.MenuSelectedFcn = createCallbackFcn(app, @exportMask, true);
            app.exportMaskMenu.Enable = 'off';
            app.exportMaskMenu.Separator = 'on';
            app.exportMaskMenu.Accelerator = 'e';
            app.exportMaskMenu.Text = 'Export to NIfTI segmentation...';
            app.SliceLabel = uilabel(app.mainFigure);
            app.SliceLabel.Enable = 'off';
            app.SliceLabel.Text = 'Slice';
            app.SliceSlider = uislider(app.mainFigure);
            app.SliceSlider.Limits = [1 10];
            app.SliceSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.SliceSlider.ValueChangedFcn = createCallbackFcn(app, @changeSlice, true);
            app.SliceSlider.MinorTicks = [1 1.1 1.2 1.3 1.4 1.5 1.6 1.7 1.8 1.9 2 2.1 2.2 2.3 2.4 2.5 2.6 2.7 2.8 2.9 3 3.1 3.2 3.3 3.4 3.5 3.6 3.7 3.8 3.9 4 4.1 4.2 4.3 4.4 4.5 4.6 4.7 4.8 4.9 5 5.1 5.2 5.3 5.4 5.5 5.6 5.7 5.8 5.9 6 6.1 6.2 6.3 6.4 6.5 6.6 6.7 6.8 6.9 7 7.1 7.2 7.3 7.4 7.5 7.6 7.7 7.8 7.9 8 8.1 8.2 8.3 8.4 8.5 8.6 8.7 8.8 8.9 9 9.1 9.2 9.3 9.4 9.5 9.6 9.7 9.8 9.9 10];
            app.SliceSlider.Enable = 'off';
            app.SliceSlider.Value = 1;
            app.ObjectsPanel = uipanel(app.mainFigure);
            app.ObjectsPanel.Title = 'Objects';
            app.DrawButton = uibutton(app.ObjectsPanel, 'push');
            app.DrawButton.ButtonPushedFcn = createCallbackFcn(app, @drawObject, true);
            app.DrawButton.Enable = 'off';
            app.DrawButton.Tooltip = {'Draw polygon object.'; ''; 'short-key = d'};
            app.DrawButton.Text = 'Draw';
            app.ObjectTable = uitable(app.ObjectsPanel);
            app.ObjectTable.ColumnName = {'#'; 'Name'; 'Track'};
            app.ObjectTable.RowName = {};
            app.ObjectTable.ColumnEditable = [false true true];
            app.ObjectTable.CellEditCallback = createCallbackFcn(app, @updateTable, true);
            app.ObjectTable.CellSelectionCallback = createCallbackFcn(app, @setCurrentObject, true);
            app.RemoveButton = uibutton(app.ObjectsPanel, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @removeObject, true);
            app.RemoveButton.Enable = 'off';
            app.RemoveButton.Tooltip = {'Remove object.'; ''; 'short-key = r'};
            app.RemoveButton.Text = 'Remove';
            app.splineCheckBox = uicheckbox(app.ObjectsPanel);
            app.splineCheckBox.ValueChangedFcn = createCallbackFcn(app, @toggleSpline, true);
            app.splineCheckBox.Enable = 'off';
            app.splineCheckBox.Text = 'spline';
            app.TrackingPanel = uipanel(app.mainFigure);
            app.TrackingPanel.Title = 'Tracking';
            app.SlicetotrackSpinnerLabel = uilabel(app.TrackingPanel);
            app.SlicetotrackSpinnerLabel.Text = 'Slice to track';
            app.SlicetotrackSpinner = uispinner(app.TrackingPanel);
            app.SlicetotrackSpinner.Limits = [-10 10];
            app.SlicetotrackSpinner.RoundFractionalValues = 'on';
            app.SlicetotrackSpinner.ValueDisplayFormat = '%d';
            app.SlicetotrackSpinner.ValueChangedFcn = createCallbackFcn(app, @sliceGapSpinner, true);
            app.SlicetotrackSpinner.Tooltip = {'Select how many slices to skip.'; 'positive = track up'; 'negative = track down'};
            app.SlicetotrackSpinner.Value = 1;
            app.SmoothingSpinnerLabel = uilabel(app.TrackingPanel);
            app.SmoothingSpinnerLabel.Text = 'Smoothing';
            app.SmoothingSpinner = uispinner(app.TrackingPanel);
            app.SmoothingSpinner.Step = 0.5;
            app.SmoothingSpinner.Limits = [0.5 3];
            app.SmoothingSpinner.ValueDisplayFormat = '%3.1f';
            app.SmoothingSpinner.Tooltip = {'This parameter controls the amount of diffusion-like regularization.'; ''; 'imregdemons applies the standard deviation of the Gaussian smoothing to regularize the accumulated field at each iteration. Larger values result in smoother output displacement fields. Smaller values result in more localized deformation in the output displacement field.'};
            app.SmoothingSpinner.Value = 1.5;
            app.PyramidlevelsSpinnerLabel = uilabel(app.TrackingPanel);
            app.PyramidlevelsSpinnerLabel.Text = 'Pyramid levels';
            app.PyramidlevelsSpinner = uispinner(app.TrackingPanel);
            app.PyramidlevelsSpinner.Limits = [1 3];
            app.PyramidlevelsSpinner.ValueDisplayFormat = '%d';
            app.PyramidlevelsSpinner.Tooltip = {'Number of multi-resolution image pyramid levels to use.'};
            app.PyramidlevelsSpinner.Value = 1;
            app.IterationsSpinnerLabel = uilabel(app.TrackingPanel);
            app.IterationsSpinnerLabel.Text = 'Iterations';
            app.IterationsSpinner = uispinner(app.TrackingPanel);
            app.IterationsSpinner.Step = 50;
            app.IterationsSpinner.Limits = [50 500];
            app.IterationsSpinner.ValueDisplayFormat = '%3d';
            app.IterationsSpinner.Tooltip = {'Number of iterations per resolution level.'};
            app.IterationsSpinner.Value = 150;
            app.UpdateButton = uibutton(app.TrackingPanel, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @trackObjects, true);
            app.UpdateButton.Enable = 'off';
            app.UpdateButton.Tooltip = {'Update tracking.'; ''; 'short-key = u'};
            app.UpdateButton.Text = 'Update';
            app.TrackButton = uibutton(app.TrackingPanel, 'push');
            app.TrackButton.ButtonPushedFcn = createCallbackFcn(app, @trackObjects, true);
            app.TrackButton.Enable = 'off';
            app.TrackButton.Tooltip = {'Track objects between slices.'; ''; 'short-key = t'};
            app.TrackButton.Text = 'Track';
            app.OverwritelabelsDropDownLabel = uilabel(app.TrackingPanel);
            app.OverwritelabelsDropDownLabel.Text = 'Overwrite labels';
            app.OverwriteLabelsDropdown = uidropdown(app.TrackingPanel);
            app.OverwriteLabelsDropdown.Items = {'Tracked', 'All', 'None'};
            app.OverwriteLabelsDropdown.Tooltip = {'Determines which objects are overwritten when tracking a slice that already contains objects.'; ''; 'all = all objects are overwritten '; 'tracked = only tracked objects are overwritten'; 'none = no objects are overwritten'};
            app.OverwriteLabelsDropdown.Value = 'Tracked';
            app.StatusLabel = uilabel(app.mainFigure);
            app.StatusLabel.Text = '';
            app.imagePanel = uipanel(app.mainFigure);
            app.imagePanel.Title = '<filename>';
            app.slice_up = uibutton(app.mainFigure, 'push');
            app.slice_up.ButtonPushedFcn = createCallbackFcn(app, @changeSlice, true);
            app.slice_up.Enable = 'off';
            app.slice_up.Text = '>';
            app.slice_down = uibutton(app.mainFigure, 'push');
            app.slice_down.ButtonPushedFcn = createCallbackFcn(app, @changeSlice, true);
            app.slice_down.Enable = 'off';
            app.slice_down.Text = '<';
            app.StatusLight = uilamp(app.mainFigure);
            app.ContrastPanel = uipanel(app.mainFigure);
            app.ContrastPanel.Title = 'Contrast';
            app.minContrastSlider = uislider(app.ContrastPanel);
            app.minContrastSlider.MajorTicks = [];
            app.minContrastSlider.ValueChangedFcn = createCallbackFcn(app, @setContrast, true);
            app.minContrastSlider.MinorTicks = [];
            app.minContrastSlider.Enable = 'off';
            app.maxContrastSlider = uislider(app.ContrastPanel);
            app.maxContrastSlider.MajorTicks = [];
            app.maxContrastSlider.ValueChangedFcn = createCallbackFcn(app, @setContrast, true);
            app.maxContrastSlider.MinorTicks = [];
            app.maxContrastSlider.Enable = 'off';
            app.maxContrastSlider.Value = 100;
            app.autoContrastButton = uibutton(app.ContrastPanel, 'push');
            app.autoContrastButton.ButtonPushedFcn = createCallbackFcn(app, @setContrast, true);
            app.autoContrastButton.Enable = 'off';
            app.autoContrastButton.Tooltip = {'Sets the contrast limits to the data range of the current slice.'};
            app.autoContrastButton.Text = 'Auto';
            app.contrastRangeLabel = uilabel(app.ContrastPanel);
            app.contrastRangeLabel.Enable = 'off';
            app.contrastRangeLabel.Text = 'Range';
            app.holdContrast = uibutton(app.ContrastPanel, 'state');
            app.holdContrast.Enable = 'off';
            app.holdContrast.Tooltip = {'Holds the contrast the same when scrolling between slices.'};
            app.holdContrast.Text = 'Hold';
            app.MinLabel = uilabel(app.ContrastPanel);
            app.MinLabel.Enable = 'off';
            app.MinLabel.Text = 'Min';
            app.MaxLabel = uilabel(app.ContrastPanel);
            app.MaxLabel.Enable = 'off';
            app.MaxLabel.Text = 'Max';
            app.StackSpinner = uispinner(app.mainFigure);
            app.StackSpinner.Limits = [1 Inf];
            app.StackSpinner.ValueChangedFcn = createCallbackFcn(app, @changeStack, true);
            app.StackSpinner.Enable = 'off';
            app.StackSpinner.Value = 1;
            app.StackLabel = uilabel(app.mainFigure);
            app.StackLabel.Enable = 'off';
            app.StackLabel.Text = 'Stack';
        end
    end

    methods (Access = public)

        % Construct app
        function app = SASHIMI_v0_beta

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.mainFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.mainFigure)
        end
    end
end