classdef SASHIMI_v1_0 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        mainFigure                    matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        loadImageMenu                 matlab.ui.container.Menu
        loadObjectMenu                matlab.ui.container.Menu
        saveObjectMenu                matlab.ui.container.Menu
        saveObjectAsMenu              matlab.ui.container.Menu
        loadLabelsMenu                matlab.ui.container.Menu
        exportMaskMenu                matlab.ui.container.Menu
        ObjectMenu                    matlab.ui.container.Menu
        undoMenu                      matlab.ui.container.Menu
        redoMenu                      matlab.ui.container.Menu
        addObjectMenu                 matlab.ui.container.Menu
        removeObjectMenu              matlab.ui.container.Menu
        deletePointMenu               matlab.ui.container.Menu
        trackObjectMenu               matlab.ui.container.Menu
        updateTrackinguMenu           matlab.ui.container.Menu
        snapMenu                      matlab.ui.container.Menu
        ViewMenu                      matlab.ui.container.Menu
        FlipaxesMenu                  matlab.ui.container.Menu
        flipxMenu                     matlab.ui.container.Menu
        flipyMenu                     matlab.ui.container.Menu
        flipBothMenu                  matlab.ui.container.Menu
        showhideAxesMenu              matlab.ui.container.Menu
        showhideMaskMenu              matlab.ui.container.Menu
        increaseMaskTransparency      matlab.ui.container.Menu
        decreaseMaskTransparency      matlab.ui.container.Menu
        showhideLabelnamesMenu        matlab.ui.container.Menu
        showhideObjectsMenu           matlab.ui.container.Menu
        SliceLabel                    matlab.ui.control.Label
        SliceSlider                   matlab.ui.control.Slider
        ObjectsPanel                  matlab.ui.container.Panel
        AddButton                     matlab.ui.control.Button
        ObjectTable                   matlab.ui.control.Table
        RemoveButton                  matlab.ui.control.Button
        LabelTable                    matlab.ui.control.Table
        TrackingPanel                 matlab.ui.container.Panel
        SlicetotrackSpinnerLabel      matlab.ui.control.Label
        SlicetotrackSpinner           matlab.ui.control.Spinner
        SmoothingSpinnerLabel         matlab.ui.control.Label
        SmoothingSpinner              matlab.ui.control.Spinner
        PyramidlevelsSpinnerLabel     matlab.ui.control.Label
        PyramidlevelsSpinner          matlab.ui.control.Spinner
        IterationsSpinnerLabel        matlab.ui.control.Label
        IterationsSpinner             matlab.ui.control.Spinner
        UpdateButton                  matlab.ui.control.Button
        TrackButton                   matlab.ui.control.Button
        OverwritelabelsDropDownLabel  matlab.ui.control.Label
        OverwriteLabelsDropdown       matlab.ui.control.DropDown
        SnaptoedgesCheckBox           matlab.ui.control.CheckBox
        mmSpinnerLabel                matlab.ui.control.Label
        maxsnapSpinner                matlab.ui.control.Spinner
        StatusLabel                   matlab.ui.control.Label
        slice_up                      matlab.ui.control.Button
        slice_down                    matlab.ui.control.Button
        StatusLight                   matlab.ui.control.Lamp
        ContrastPanel                 matlab.ui.container.Panel
        minContrastSlider             matlab.ui.control.Slider
        maxContrastSlider             matlab.ui.control.Slider
        autoContrastButton            matlab.ui.control.Button
        contrastRangeLabel            matlab.ui.control.Label
        holdContrast                  matlab.ui.control.StateButton
        MinLabel                      matlab.ui.control.Label
        MaxLabel                      matlab.ui.control.Label
        StackSpinner                  matlab.ui.control.Spinner
        StackLabel                    matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % Image
        
%         img           % image data (3D - current stack if data is 4D)
        img_4D        % image data (4D)
        spacing       % pixel spacing
%         dim           % image dimensions
%         metadata      % image NIfTI header
        mask          % mask array
        rgbmask       % rgb mask of the current slice
        slice_nr      % number of currently displayed slice
        stack_nr      % number of currently displayed stack (for multi-channel data)
        slice_nr_ref  % number of reference slice
        slice_nr_to_track % number of tracked slice
        
        % Objects
%         ObjectData     % structure with position of nodes of polygon objects for all slice and objects
        objectFilename % filename of object structure        
        lastSavedObjectData % ObjectData structure that was saved last.
        showObjects    % flag (true/false) to show/hide polygon and spline objects
        
        % Tracking
        prev_slice_gap      % previous gap value - used to exclude 0 gap
        
        % Labels
        nLabels         % number of labels
        currentLabel_nr % number of the selected object in the UITable
%         LabelNames      % label names
        showLabelName   % flag (true/false) to show/hide label names
        label_str       % string of label numbers (used to populate object table dropdown menu)
        defaultColors % default color map
        
        % Handles and plot settings
        h_poly        % polygon graphics objects
        h_spline      % spline plot objects
        h_img         % image object
        h_mask        % mask object
        % clim           % clim (contrast) property of image
        maskAlpha      % alpha-transparency value for mask overlay
        polyLineWidth % line width of polygon objects
        polyFaceAlpha  % alpha-transparency value for polygon objects
        splineLineWidth % line width of spline
        splineLineStyle % line style of spline objects
        
        % Other
        isPressed      % cell array with keys currently held
        isBusy         % flag (true/false) indicating that no key presses can be received
        busyColor      % status light colors
        readyColor
        errorColor
        UndoData       % Cell with undo data
        undo_idx       % index of action to undo
        RedoData       % Cell with redo data
        redo_idx       % index of action to redo
        viewangle      % rotation angle of image
        MouseDown      % flag indicating if the mouse is pressed (true)
        previousPos    % mouse position in figure window used for panning
        R              % rotation matrix from axis to figure coordinated (for panning)
        xlim           % x-limits of axes
        ylim           % y-limits of axes  
        sigma          % Standard deviation of Gaussian smoothing for snapping
    end
    
    properties (Access = public)

        ObjectData    % structure with position of nodes of polygon objects for all slice and objects
        img           % image data (3D - current stack if data is 4D)
        metadata      % image NIfTI header
        LabelNames    % label names
        clim          % clim (contrast) property of image
        colors        % current color map
        xvec          % vector with x-positions of voxels (2nd dimension of img)
        yvec          % vector with y-positions of voxels (1st dimension of img)
        zvec          % vector with z-positions of voxels (3rd dimension of img)
        img_filename  % full filename of image
    end
    
    methods (Access = private)
        
        function objectListener(app,src,event)
            % Listener callback for changes in polygon ROI object position.
            % Get the number of the graphics object from the object's UserData.
            persistent counter
            obj_nr = get(src,'UserData');
            action = event.EventName;
            
            % Save objects of current slice for undo action
            undoFunction(app,...
                {'insert/move point',... % action
                app.slice_nr,...  % slice number to be changed
                app.ObjectData(app.slice_nr)}); % slice object data before change
                    
            switch action
                case 'ROIClicked'
                    if strcmp(event.SelectionType,'double')
                        % Point was inserted. Update the position.
                        pos = get(src,'Position');
                        
                    else
                        % Start of dragging to move a point.
                        counter = 0;
                        undoFunction(app,'cancel')                        
                        return
                    end
                case {'MovingROI','ROIMoved'} % ROI is moving/has moved

                    % The counter is used to ensure that when a point is moved, only one entry is added to
                    % the UndoData.
%                     
                    counter = counter + 1;                    
                    if counter > 1                
                        % Remove the last line
                        undoFunction(app,'cancel')
                    end
                    
                    % Update the cell with object position data of the current polygon graphics 
                    % objects so that interactive changes are saved.
                    pos = event.CurrentPosition;
                    
                    % The whole object was translated, which is not allowed. Reset to previous position.
                    if any(all(event.PreviousPosition - event.CurrentPosition ~= 0))
                        set(src,'Position',event.PreviousPosition);
                        return
                    end
            end
            
            % Update ObjectData with the new position data.
            app.ObjectData(app.slice_nr).position{1,obj_nr} = pos;
                
            % Update the spline curve.
            SP = fit_spline(pos);
            set(app.h_spline(obj_nr),...
                'XData',SP(:,1),...
                'YData',SP(:,2))
            
            % Update the mask for the current slice
            maskFunction(app,'updateslice');
        end
                
        function slice_mask = object2mask(app,slice_nr)
            % Makes a mask with pixels inside each object assigned the object's label number.
            % If a pixel belongs to multiple labels (i.e. overlapping objects with different label numbers),
            % the pixel gets the label value of the last created object.
            
            n_objects = length(app.ObjectData(slice_nr).position);
            slice_mask = zeros(app.metadata.ImageSize(1),app.metadata.ImageSize(2));
            for obj_nr = 1 : n_objects
                
                % Get position and label number for the current object
                pos      = app.ObjectData(slice_nr).position{1,obj_nr};
                label_nr = app.ObjectData(slice_nr).label_nr(1,obj_nr);
                isSpline = strcmp(app.ObjectData(slice_nr).type{1,obj_nr},'spline');
                if isSpline == true
                    % Update mask with pixels encapsulated by the spline curve.
                    pos = fit_spline(pos);
                end
                
                % Convert polygon position from mri coordinates to pixel coordinates. Then create a binary mask of all pixels inside the object.
                p_voxel = pos./app.metadata.PixelDimensions([2 1]) + 0.5;
                binaryMask = poly2mask(...
                    p_voxel(:,1),...
                    p_voxel(:,2),...
                    app.metadata.ImageSize(1),...
                    app.metadata.ImageSize(2));
                
                % Assign the object's label number to the pixels inside the object.
                slice_mask(binaryMask) = label_nr;
            end            
        end
        
        
        function [pos,obj_nrs,idx] = selectPoints(app)
            % Update the status bar.
            app.StatusLabel.Text = {'Select points.','Press Esc to cancel.'};
            app.StatusLight.Color = app.busyColor;
            
            % Select a rectangle
            h = drawrectangle(app.UIAxes,...
                'FaceAlpha',0,...
                'Color','y',....
                'Visible','on',...
                'LineWidth',1);
            pos = [];
            obj_nrs = [];
            idx = [];
            if isgraphics(h)
                rect = get(h,'Position'); % p = [x y w h];
                if ~isempty(rect)
                    for obj_nr = 1 : length(app.ObjectData(app.slice_nr).position)
                        obj_pos = app.ObjectData(app.slice_nr).position{obj_nr};
                        inRect = find(....
                            obj_pos(:,1) > rect(1) & obj_pos(:,1) < (rect(1)+rect(3)) & ...
                            obj_pos(:,2) > rect(2) & obj_pos(:,2) < (rect(2)+rect(4)));
                        pos = [pos;obj_pos(inRect,:)];
                        obj_nrs = [obj_nrs;ones(length(inRect),1)*obj_nr];
                        idx = [idx;inRect];
                    end
                end
            end
            % Delete the point object.
            app.StatusLabel.Text = '';
            app.StatusLight.Color = app.readyColor;
            delete(findobj(app.mainFigure,'Type','images.roi.Rectangle'))
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Set some tags.
            app.slice_up.Tag           = 'up';
            app.slice_down.Tag         = 'down';
            app.SliceSlider.Tag        = 'slider';
            app.TrackButton.Tag        = 'track';
            app.UpdateButton.Tag       = 'update';
            app.minContrastSlider.Tag  = 'min';
            app.maxContrastSlider.Tag  = 'max';
            app.autoContrastButton.Tag = 'auto';
            app.AddButton.Tag          = 'add';
            app.RemoveButton.Tag       = 'remove';
            app.LabelTable.Tag         = 'labeltable';
            app.ObjectTable.Tag        = 'objecttable';
            app.StackSpinner.Tag       = 'changestack';
            app.SlicetotrackSpinner.Tag = 'slice_to_track';
            
            % Menu items
            % File menu
            app.loadObjectMenu.Tag     = 'load';
            app.saveObjectAsMenu.Tag   = 'saveas';
            app.saveObjectMenu.Tag     = 'save';
            app.loadImageMenu.Tag      = 'load';
            app.exportMaskMenu.Tag     = 'export';
            app.loadLabelsMenu.Tag     = 'load';
            
            % Object menu
            app.undoMenu.Tag           = 'undo';
            app.redoMenu.Tag           = 'redo';
            app.addObjectMenu.Tag      = 'add';
            app.removeObjectMenu.Tag   = 'remove';
            app.deletePointMenu.Tag    = 'deletepoint';
            app.trackObjectMenu.Tag    = 'track';
            app.updateTrackinguMenu.Tag = 'remove';
            app.snapMenu.Tag           = 'snap';
            
            % View menu
            app.flipxMenu.Tag          = 'x';
            app.flipyMenu.Tag          = 'y';
            app.flipBothMenu.Tag       = 'both';
            app.showhideAxesMenu.Tag   = 'showaxes';
            app.showhideMaskMenu.Tag   = 'visible';
            app.showhideLabelnamesMenu.Tag = 'visible';
            app.decreaseMaskTransparency.Tag = 'decrease';
            app.increaseMaskTransparency.Tag = 'increase'; 
            app.showhideObjectsMenu.Tag   = 'visible';
%             app.cwrotateMenu.Tag       = 'cw';
%             app.ccwrotateMenu.Tag       = 'ccw';
                     
            % Create empty ObjectData structure.
            objectFunction(app,'init');
            
            % Create UIAxes object
            app.UIAxes.Position = [10,75,680,670];
            hold(app.UIAxes,'on')
            app.UIAxes.Visible = 'off'; % Switch off axis.
            
            % Initialize image and mask.
            imageFunction(app,'init')
            maskFunction(app,'init')
            
            % Set previous gap value to the default value.
            app.prev_slice_gap = 1;
            app.SlicetotrackSpinner.Value = 1;

            % Set current label number to 1
            app.currentLabel_nr = 1;
            
            % Set some default graphics properties
            app.polyLineWidth   = 1.5;
            app.polyFaceAlpha   = 0;
            app.splineLineWidth = 1.5;
            app.splineLineStyle = '-';
            app.maskAlpha       = 0.5;            
            app.showLabelName   = false;
            app.showObjects     = true;
            app.busyColor       = [1.00,0.41,0.16]; % orange
            app.readyColor      = [0,1,0]; % green
            app.errorColor      = [1,0,0]; % red
            
            % And set some other properties.
            app.isPressed       = {};
            app.isBusy          = false;
            app.UndoData        = {};
            app.viewangle       = 0;
            app.R               = [1 0;0 1];
            app.sigma           = 1;
            
            % Set default colors.
            app.defaultColors = [...                
                141,211,199;
                255,255,179;
                190,186,218;
                251,128,114;
                128,177,211;
                253,180,98;
                179,222,105;
                252,205,229;
                217,217,217;
                188,128,189;
                204,235,197;
                255,237,111] / 255;
            
            % Prefill table
            app.colors     = app.defaultColors;            
            app.nLabels  = size(app.colors,1);
            
            labelFunction(app,'init')           
            
        end

        % Callback function: StackSpinner, loadImageMenu
        function imageFunction(app, event)
            % This function executes all updates the images.            
            if ischar(event)
                action = event;
            else
                action = event.Source.Tag;
            end
            
            switch action
                case 'init'
                    % Create an empty image object.
                    app.h_img = image(app.UIAxes,...
                        zeros(2,2),...
                        'CDataMapping','scaled');
                    
                    % Set the default colormap as grayscale.
                    colormap(app.UIAxes,gray(2000))
                    app.UIAxes.CLim = [0 1];
                    axis(app.UIAxes,'off') % hide the x- and y-axis
                    axis(app.UIAxes,'equal')
                    
                    % Disable the built-in zoom/pan buttons.
%                     try
                        axtoolbar(app.UIAxes,{});
%                     catch
%                     end
                case 'load' % Load an image
                    % Select a file.
                    [file,path] = uigetfile({'*.nii;*.nii.gz;',...
                        'Image files (*.nii,*.nii.gz)'},...
                        'Select an image file',[],'MultiSelect','off');
                    % Bring the figure to the front again.
                    drawnow;
                    figure(app.mainFigure)
                    if file == 0
                        return
                    else
                        app.img_filename = fullfile(path,file);
                    end
                    
                    % Disable image interactions.
                    disableDefaultInteractivity(app.UIAxes)
                    
                    % Update status bar
                    app.StatusLabel.Text = sprintf('Loading %s...',file);
                    app.StatusLight.Color =  app.busyColor;
                    pause(0.01)
                    
                    % Load the data
                    app.img      = niftiread(app.img_filename); % image data
                    app.metadata = niftiinfo(app.img_filename); % metadata
                    
                    % Create x,y,z vectors. These positions indicate the centre of the voxel.
                    app.xvec = (0.5 : app.metadata.ImageSize(2)-0.5) * app.metadata.PixelDimensions(2);
                    app.yvec = (0.5 : app.metadata.ImageSize(1)-0.5) * app.metadata.PixelDimensions(1);
                    app.zvec = (0.5 : app.metadata.ImageSize(3)-0.5) * app.metadata.PixelDimensions(3); 
                    
                    app.StatusLabel.Text = sprintf('%s loaded',file);
                    app.StatusLight.Color = app.readyColor;
                    
                    % Check if 3D or 4D data was selected.
                    app.stack_nr = 1;
                    app.StackSpinner.Value = app.stack_nr;
                    if ndims(app.img) == 4
                        % The image is 4D. Enable the stack spinner.
                        app.StackLabel.Enable   = 'on';
                        app.StackSpinner.Enable = 'on';
                        app.StackSpinner.Limits = [1 size(app.img,4)];
                        app.img_4D = app.img;
                        app.img = app.img_4D(:,:,:,app.stack_nr);
                    elseif ndims(app.img) == 3
                        app.StackLabel.Enable   = 'off';
                        app.StackSpinner.Enable = 'off';
                        app.StackSpinner.Limits = [1 2];
                    else
                        errordlg(sprintf('SASHIMI only accepts 3-D or 4-D data, but %d-D data was selected.',ndim(app.img)),...
                            'Wrong image dimensions')
                        app.StatusLabel.Text = '';
                        app.StatusLight.Color =  app.readyColor;
                        return
                    end
                    
                    % Update slice slider
                    app.SliceSlider.Limits     = [1 app.metadata.ImageSize(3)];
                    majorTicks                 = round(linspace(0,app.metadata.ImageSize(3),11));
                    majorTicks(1)              = 1;
                    app.SliceSlider.MajorTicks = majorTicks;
                    app.SliceSlider.MinorTicks = [];
                    
                    % Set the current slice number to the middle slice.
                    app.slice_nr               = round(app.metadata.ImageSize(3)/2);
                    
                    % Update image filename
                    app.UIAxes.Title.String = [file,sprintf(' (%.2fx%.2fx%.2f mm)',...
                        app.metadata.PixelDimensions(1),app.metadata.PixelDimensions(2),app.metadata.PixelDimensions(3))];
                    set(app.UIAxes.Title,'Interpreter','None')
                    
                    % Create empty object cell with the correct dimensions.
                    app.ObjectData = cell2struct(cell(3,app.metadata.ImageSize(3)), {'position','label_nr','type'}, 1);
                    
                    % Reset the object table.
                    objectFunction(app,'updatetable');
                    
                    % Reset the object file name to an empty string.
                    app.objectFilename = [];
                    
                    % Set the default label table
                    labelFunction(app,'init')
                    
                    % Enable/disable objects
                    app.slice_up.Enable         = 'on';
                    app.slice_down.Enable       = 'on'; 
                    app.SliceSlider.Enable      = 'on';
                    app.SliceLabel.Enable       = 'on';
                    app.loadObjectMenu.Enable   = 'on';
                    app.saveObjectAsMenu.Enable = 'on';
                    app.saveObjectMenu.Enable   = 'off';
                    app.exportMaskMenu.Enable   = 'on';
                    app.UpdateButton.Enable     = 'off';  
                    set(get(app.ContrastPanel,'Children'),'Enable','On');
                    set(get(app.ObjectsPanel,'Children'),'Enable','On');
                    app.addObjectMenu.Enable = 'on';
                    app.removeObjectMenu.Enable = 'on';
                    app.deletePointMenu.Enable = 'on';
                    app.snapMenu.Enable        = 'on';
                    
                    % Set XData and YData for the current image
                    set(app.h_img,...
                        'XData',[app.xvec(1) app.xvec(end)],...
                        'YData',[app.yvec(1) app.yvec(end)],...
                        'CData',zeros(app.metadata.ImageSize(1:2)))
                    
                    % Set limits and values of contrast sliders to min/max of dataset.
                    data_range = double([min(app.img(:)),max(app.img(:))]);
                    app.minContrastSlider.Limits = data_range - [0 1];
                    app.maxContrastSlider.Limits = data_range + [1 0];            
                    app.minContrastSlider.Value = data_range(1);
                    app.maxContrastSlider.Value = data_range(2);
                    
                    % Create empty mask and RGB-mask object and set some properties
                    app.mask = zeros(app.metadata.ImageSize(1:3));
                    set(app.h_mask,...
                        'XData',[app.xvec(1) app.xvec(end)],...
                        'YData',[app.yvec(1) app.yvec(end)],...
                        'CData',zeros([app.metadata.ImageSize(1:2) 3]),...
                        'Visible','on');
                    
                    % Set axis properties
                    set(app.UIAxes,...
                        'XLim',[app.xvec(1) app.xvec(end)],...
                        'YLim',[app.yvec(1) app.yvec(end)],...
                        'YDir','reverse',...
                        'XDir','normal')
                    axis(app.UIAxes,'equal')
                    
                    % Update the image/object display
                    showSlice(app)
                    setContrast(app,'auto')
                    
                    % Initialize the undo data
                    undoFunction(app, 'init')
                    
                case 'updateslice'
                    % Update image 
                    set(app.h_img,...
                        'CData',app.img(:,:,app.slice_nr))
                    if app.holdContrast.Value == false
                        setContrast(app,'auto');
                    end
                case 'changestack'
                    % Get the stack number from the spinner.
                    app.stack_nr = app.StackSpinner.Value;
                    
                    % Update app.img
                    app.img = app.img_4D(:,:,:,app.stack_nr);
                    set(app.h_img,...
                        'CData',app.img(:,:,app.slice_nr));
            end
            
        end

        % Callback function: AddButton, ObjectTable, RemoveButton, 
        % addObjectMenu, deletePointMenu, loadObjectMenu, 
        % removeObjectMenu, saveObjectAsMenu, saveObjectMenu, 
        % showhideObjectsMenu, snapMenu
        function objectFunction(app, event)
            % This function executes all updates to objects.
            if ischar(event)
                action = event;
            else
                action = event.Source.Tag;
            end
            
            % In all cases, disable the track update button except for when
            % object visibility is changed.
            
            
            if ~strcmp(action,'visible')
                app.UpdateButton.Enable = 'off'; 
            end
            
            switch action
                case 'init'
                    % Make an empty object structure.
                    app.ObjectData = cell2struct(cell(3,1), {'position','label_nr','type'}, 1);
                case 'updatetable'
                    % This function fills the object table for the current slice. 
                    app.ObjectTable.ColumnFormat = {'char',app.label_str,'logical'};
                    if ~isempty(app.ObjectData(app.slice_nr).label_nr)
                        app.ObjectTable.Data = [app.LabelNames(app.ObjectData(app.slice_nr).label_nr),...
                            app.label_str(app.ObjectData(app.slice_nr).label_nr)',...
                            num2cell(strcmp(app.ObjectData(app.slice_nr).type,'spline'))'];
                    else
                        app.ObjectTable.Data = [];
                    end
                    objectTableColor = app.colors(app.ObjectData(app.slice_nr).label_nr,:);  
                    if isempty(objectTableColor)
                        % No objects are present in the table. Set to the default color.
                        objectTableColor = [0.8, 0.8, 0.8];
                    end
                    app.ObjectTable.BackgroundColor = objectTableColor;
                case 'updateslice'
                    % Update the polygon and spline graphics objects
                    % ... first, delete all existing graphics objects.
                    delete(findobj(app.UIAxes,'Type','images.roi.polygon'))
                    delete(findobj(app.UIAxes,'Type','line'))
                    app.h_poly   = [];
                    app.h_spline = [];
                    n_objects = length(app.ObjectData(app.slice_nr).position);
                    for obj_nr = 1 : n_objects
                        
                        % Create an interactive polygon and spline graphics object for each object in the slice.
                        isSpline = strcmp(app.ObjectData(app.slice_nr).type{1,obj_nr},'spline');
                        pos      = app.ObjectData(app.slice_nr).position{1,obj_nr};
                        label_nr = app.ObjectData(app.slice_nr).label_nr(1,obj_nr);                    
                        if app.showLabelName == true
                            lbl = app.TableData.Data{label_nr,2};
                        else
                            lbl = '';
                        end
                        app.h_poly(obj_nr) = images.roi.Polygon('Parent',app.UIAxes);
                        
                        if isSpline == true
                            StripeColor = 'k';
                        else
                            StripeColor = 'none';                    
                        end
                        set(app.h_poly(obj_nr),...
                            'Label',lbl,...
                            'Color',app.colors(label_nr,:),...
                            'FaceAlpha',app.polyFaceAlpha,...
                            'Position',pos,...
                            'InteractionsAllowed','all',...
                            'UserData',obj_nr,...
                            'LineWidth',app.polyLineWidth,...
                            'StripeColor',StripeColor,...
                            'FaceSelectable',false);
                        
                        % Add listeners for detecting changes in position/clicks.
                        addlistener(app.h_poly(obj_nr), 'MovingROI', @(src,event) objectListener(app,src,event));
                        addlistener(app.h_poly(obj_nr), 'ROIClicked', @(src,event) objectListener(app,src,event));
                        
                        % Update the spline curve.
                        SP = fit_spline(pos);
                        app.h_spline(obj_nr) = plot(app.UIAxes,...
                            SP(:,1),SP(:,2),...
                            'LineWidth',app.splineLineWidth,...
                            'Color',app.colors(label_nr,:),...
                            'Visible',isSpline);
                    end
                    
                    % Update the object table.    
                    objectFunction(app,'updatetable')
                    
                    % If no objects are available on the current slice, disable the track button.
                    if isempty(app.h_poly)
                        app.TrackButton.Enable = 'off';
                        app.trackObjectMenu.Enable = 'off';
                    else
                        app.TrackButton.Enable = 'on';
                        app.trackObjectMenu.Enable = 'on';
                    end
                case 'add'
                    % Save objects of current slice for undo action
                    undoFunction(app,...
                        {'add object',... % action
                        app.slice_nr,...  % slice number to be changed
                        app.ObjectData(app.slice_nr)}); % slice object data before change
                    
                    % Set the status label and color.
                    app.StatusLight.Color = app.busyColor;
                    app.StatusLabel.Text = 'Select corners. Press ''Enter'' to close polygon...';
                    
                    % Draw a polygon
                    obj_nr = length(app.h_poly) + 1;
                    app.isBusy = true;
                    app.h_poly(obj_nr) = drawpolygon(...
                        'Color',app.colors(app.currentLabel_nr,:),...
                        'FaceAlpha',app.polyFaceAlpha,...
                        'Parent',app.UIAxes,...   
                        'InteractionsAllowed','all',...
                        'UserData',obj_nr,...
                        'LineWidth',app.polyLineWidth,...
                        'StripeColor','none',...
                        'FaceSelectable',false);
                    app.isBusy = false;
                    
                    % Delete the object if fewer than 3 points were selected.
                    if size(get(app.h_poly(end),'Position'),1) < 3
                        app.StatusLabel.Text = 'Minimum of 3 points should be selected.';
                        app.StatusLight.Color = app.errorColor;
                        delete(app.h_poly(end))
                        app.h_poly(end) = [];
                        undoFunction(app,'cancel')
                        return                    
                    end
                    % Add listeners for updating ObjectData and masks when the objects is changed (moved, point added, point deleted).
                    addlistener(app.h_poly(obj_nr), 'MovingROI', @(src,event) objectListener(app,src,event));
                    addlistener(app.h_poly(obj_nr), 'ROIClicked', @(src,event) objectListener(app,src,event));
                    if app.showLabelName == true
                        set(app.h_poly(end),...
                            'Label',app.LabelNames{app.currentLabel_nr})
                    end
                    app.StatusLabel.Text = '';
                    app.StatusLight.Color = app.readyColor;                
                    
                    % Add position/label data to ObjectData
                    pos = get(app.h_poly(end),'Position');
                    app.ObjectData(app.slice_nr).position{1,obj_nr} = ...
                        pos;
                    app.ObjectData(app.slice_nr).label_nr(1,obj_nr) = ...
                        app.currentLabel_nr;
                    app.ObjectData(app.slice_nr).type{1,obj_nr} = ...
                        'polygon';            
                    
                    % Create a spline curve
                    C = fit_spline(pos);
                    app.h_spline(obj_nr) = plot(...
                        app.UIAxes,C(:,1),C(:,2),...
                        'Color',app.colors(app.currentLabel_nr,:),...
                        'LineStyle',app.splineLineStyle,...
                        'LineWidth',app.splineLineWidth,...
                        'Visible',false);
                    
                    % Update the mask
                    maskFunction(app,'updateslice') 
                    
                    % Update the object tables
                    app.ObjectTable.Data{obj_nr,1} = app.LabelNames{app.currentLabel_nr};
                    app.ObjectTable.Data{obj_nr,2} = app.label_str{app.currentLabel_nr};
                    app.ObjectTable.Data{obj_nr,3} = false;
%                     app.ObjectTable.Data{obj_nr,4} = true;
                    app.ObjectTable.BackgroundColor = app.colors(app.ObjectData(app.slice_nr).label_nr,:); 
    
%                     objectFunction(app,'updatetable')
                    
                    % Enable the track button and disable the update button.
                    app.TrackButton.Enable  = 'on';
                    app.trackObjectMenu.Enable = 'on';

                case 'remove'
                    % Remove object (s)
                    
                    % Update the status bar.
                    app.StatusLabel.Text = {'Click inside object to delete.','Press Esc to cancel.'};
                    app.StatusLight.Color = app.busyColor;
                    
                    % Select a point.
                    h = drawpoint(app.UIAxes,'Visible','off');
                    p = get(h,'Position');
                    if isempty(p)
                        % Point deletion was canceled. Update the status bar, delete the point object, and end the function.
                        app.StatusLabel.Text = '';
                        app.StatusLight.Color = app.readyColor;
                        delete(findobj(app.mainFigure,'Type','images.roi.Point'))
                        return
                    end
                    % Delete the point object.
                    delete(findobj(app.mainFigure,'Type','images.roi.Point'))
                    
                    isInside = false(length(app.h_poly),1);
                    for obj_nr = 1 : length(app.h_poly)
                        pos                 = get(app.h_poly(obj_nr),'Position');
                        isInside(obj_nr) = inpolygon(p(1,1),p(1,2),pos(:,1),pos(:,2));
                    end
                    
                    object_nrs_to_remove = find(isInside);
                    if ~isempty(object_nrs_to_remove)
                        % Save objects of current slice for undo action                        
                        undoFunction(app,...
                            {'remove object(s)',... % action
                            app.slice_nr,...  % slice number to be changed
                            app.ObjectData(app.slice_nr)}); % slice object data before change
                        
                        % remove graphics object and update ObjectData
                        delete(app.h_poly(object_nrs_to_remove))
                        delete(app.h_spline(object_nrs_to_remove))
                        app.ObjectData(app.slice_nr).position(object_nrs_to_remove) = [];
                        app.ObjectData(app.slice_nr).label_nr(object_nrs_to_remove) = [];
                        app.ObjectData(app.slice_nr).type(object_nrs_to_remove)     = [];
                        app.h_poly(object_nrs_to_remove)                           = [];
                        app.h_spline(object_nrs_to_remove)                         = [];
                        app.StatusLabel.Text = sprintf('%d object(s) removed.',length(object_nrs_to_remove));
                        
                        % Update the UserData (object numbers)
                        for obj_nr = 1 : length(app.h_poly)
                            set(app.h_poly(obj_nr),'UserData',obj_nr)
                        end
                        
                        % Update the mask
                        maskFunction(app,'updateslice') 
                        
                        % Update the object tables
                        app.ObjectTable.Data(object_nrs_to_remove,:) = [];
                        app.ObjectTable.BackgroundColor(object_nrs_to_remove,:) = [];
                        
                    end
                case 'deletepoint'
                    % Delete selected points.
                                        
                    [~,obj_nrs,idx] = selectPoints(app);
                    if ~isempty(obj_nrs)
                        % Save objects of current slice for undo action
                        undoFunction(app,...
                            {'delete point',...
                            app.slice_nr,...  
                            app.ObjectData(app.slice_nr)});
                        object_nrs_to_remove=[]; 
                        for obj_nr = unique(obj_nrs)'
                            % Delete selected point
                            pos = app.ObjectData(app.slice_nr).position{obj_nr};                            
                            id = idx(obj_nrs == obj_nr);
                            pos(id,:) = [];
                            if size(pos,1) < 3
                                % Delete the object if fewer than 3 points are left in the polygon.
                                object_nrs_to_remove = [object_nrs_to_remove obj_nr];
                            else
                                % Update ObjectData and the graphics objects
                                app.ObjectData(app.slice_nr).position{obj_nr} = pos;
                                set(app.h_poly(obj_nr),...
                                    'Position',pos)
                                SP = fit_spline(pos);
                                set(app.h_spline(obj_nr),...
                                    'XData',SP(:,1),...
                                    'YData',SP(:,2));
                            end                         
                        end
                        % Remove objects with few than 3 points left.
                        delete(app.h_poly(object_nrs_to_remove))
                        app.h_poly(object_nrs_to_remove) = [];
                        app.ObjectData(app.slice_nr).position(object_nrs_to_remove) = [];
                        app.ObjectData(app.slice_nr).label_nr(object_nrs_to_remove) = [];
                        app.ObjectData(app.slice_nr).type(object_nrs_to_remove)     = [];                        
                        objectFunction(app,'updateslice')
                        
                        % Update the mask.
                        maskFunction(app,'updateslice')  
                    end
                    % Update the status bar
                    app.StatusLight.Color = app.readyColor;
                    app.StatusLabel.Text = sprintf('%d point(s) deleted.',length(obj_nrs));
                case 'objecttable'
                    % Update the table data with the new data.
                    obj_nr = event.Indices(1);
                    col_nr = event.Indices(2);
                    app.ObjectTable.Data{obj_nr,col_nr} = event.NewData;
                    
                    % Check which value was changed and execute the appropriate action.
                    switch col_nr
                        case 1 % label name (non-editable)
                            % do nothing
                        case 2 % label number changed
                            
                            % Save objects of current slice for undo action
                            undoFunction(app,...
                                {'change label number',...
                                app.slice_nr,...
                                app.ObjectData(app.slice_nr)})
                            
                            label_nr = str2double(event.NewData);
                            app.ObjectData(app.slice_nr).label_nr(1,obj_nr) = label_nr;
                            
                            % Update object name and color
                            app.ObjectTable.Data{obj_nr,1} = app.LabelNames{label_nr};
                            app.ObjectTable.BackgroundColor(obj_nr,:) = app.colors(label_nr,:);
                            
                            % Set colors of graphics objects
                            set(app.h_poly(obj_nr),...
                                'Color',app.colors(label_nr,:));
                            set(app.h_spline(obj_nr),...
                                'Color',app.colors(label_nr,:));
                            
                            % Update mask
                            maskFunction(app,'updateslice')
                            
                        case 3 % toggle spline
                            
                            % Save objects of current slice for undo action
                            undoFunction(app,...
                                {'toggle spline',...
                                app.slice_nr,...
                                app.ObjectData(app.slice_nr)})
                            
                            % Update the object type and graphics objects   
                            isSpline = event.NewData;
                            set(app.h_spline(obj_nr),...
                                'Visible',isSpline)
                            if isSpline == true
                                app.ObjectData(app.slice_nr).type{1,obj_nr} = 'spline';
                                set(app.h_poly(obj_nr),...
                                    'StripeColor','k');
                            else
                                app.ObjectData(app.slice_nr).type{1,obj_nr} = 'polygon';
                                set(app.h_poly(obj_nr),...
                                    'StripeColor','none');
                            end
                            
                            % Update mask
                            maskFunction(app,'updateslice')

                        case 4 % enable/disable tracking
                            % Do nothing for now.
                    end
                case {'save','saveas'}
                    if strcmp(action,'saveas') || isempty(app.objectFilename)
                        % Select a file or set a new filename.
                        [~,defname] = fileparts(app.img_filename);
                        [~,defname] = fileparts(defname); % this is to make sure the '.nii' is removed from the filename if the extension is .nii.gz
                        [file,path] = uiputfile('*.mat','Select/set a filename to save objects to',[defname '_objects.mat']);
                        % Bring the figure to the front again.
                        figure(app.mainFigure)
                        if path == 0
                            return
                        else
                            app.objectFilename = fullfile(path,file);
                        end
                    end
                    
                    % Save ObjectData,LabelNames and colors to a MAT-file.
                    ObjectData = app.ObjectData;
                    LabelNames = app.LabelTable.Data(:,1);
                    colors     = app.colors;
                    save(app.objectFilename,'ObjectData','LabelNames','colors')
                    
                    % Update status bar.
                    [~,b,c] = fileparts(app.objectFilename);
                    app.StatusLabel.Text = sprintf('Objects saved as %s',[b,c]);
                    app.saveObjectMenu.Enable = 'on';
                    
                    % Stored last saved object data to determine if unsaved
                    % changes are present.
                    app.lastSavedObjectData = ObjectData;   
                case 'load'
                    % Load a previously saved object file.
                    [file,path] = uigetfile({'*.mat','Matlab MAT-file'},'Select an object file');
                    
                    % Bring the figure to the front again.
                    figure(app.mainFigure)
                    if file == 0
                        return
                    else
                        % Update the status light and label;
                        app.objectFilename = fullfile(path,file);
                        [~,a,b] = fileparts(app.objectFilename);
                        short_filename = [a b];
                        app.StatusLabel.Text = sprintf('Loading %s...',short_filename);
                        app.StatusLight.Color =  app.busyColor;
                        pause(0.01)
                        
                        % Load data
                        filedata = load(app.objectFilename);
                        if isfield(filedata,'ObjectData')
                            if length(filedata.ObjectData) ~= app.metadata.ImageSize(3)
                                errordlg(sprintf('Object could not be loaded because the selected object does not match the slice number of the current image.\nThe number of slices should be %d but was %d.',...
                                    app.metadata.ImageSize(3),length(filedata.ObjectData)),...
                                    'Wrong object dimensions')
                                app.objectFilename = [];
                                return
                            end
                                
                            % Update ObjectData, the label table and the color scheme.
                            app.ObjectData      = filedata.ObjectData;
                            app.LabelNames      = filedata.LabelNames;
                            app.colors          = filedata.colors;
                            app.LabelTable.BackgroundColor = app.colors;
                            app.LabelTable.Data = [app.LabelNames num2cell(true(app.nLabels,1))];
                            
                            % Update the mask and update the graphics objects.
                            for slice_nr = 1 : app.metadata.ImageSize(3)
                                app.mask(:,:,slice_nr) = object2mask(app,slice_nr);
                            end
                            
                            % Update the objects and masks.
                            objectFunction(app,'updateslice')
                            maskFunction(app,'updateslice')
                            
                            % Enable the save button.
                            app.saveObjectMenu.Enable = 'on';
                            
                            % Update the status light and label.
                            app.StatusLabel.Text = sprintf('%s loaded',short_filename);
                            app.StatusLight.Color = app.readyColor;
                            
                            % Initialize the undo data
                            undoFunction(app, 'init')
                            
                        else
                            app.objectFilename = [];
                            errordlg(sprintf('Variable ''ObjectData'' not found in %s. Object data could not be loaded.',app.objectFilename))                    
                            app.StatusLight.Color = app.readyColor;
                        end
                    end
                case 'visible'
                    % Toggle visibility of objects
                    app.showObjects = ~app.showObjects;
                    if app.showObjects == true
                        set(app.h_poly,'Visible','on')
                        
                        % Only show spline if object type is spline.
                        isSpline = strcmp(app.ObjectData(app.slice_nr).type,'spline');
                        set(app.h_spline(isSpline),'Visible','on')
                        
                    else
                        set(app.h_poly,'Visible','off')
                        set(app.h_spline,'Visible','off')
                    end
%                 case 'snapall'
%                    % Snap all points.
%                     % Save objects of current slice for undo action
%                     undoFunction(app,...
%                         {'snap all',...
%                         app.slice_nr,...
%                         app.ObjectData(app.slice_nr)})
%                     
%                     % Snap all points of all objects to edges.                    
%                     oldpts = app.ObjectData(app.slice_nr).position;
%                     newpts = snap_to_edge(...
%                         app.img(:,:,app.slice_nr),...
%                         oldpts,...
%                         'sigma',app.sigma,...
%                         'spacing',app.metadata.PixelDimensions(1:2),...
%                         'maxd',app.maxsnapSpinner.Value,...
%                         'method','linear');
%                     app.ObjectData(app.slice_nr).position = newpts;
%                     objectFunction(app,'updateslice')
%                     maskFunction(app,'updateslice')
%                     app.StatusLabel.Text = '';
%                     app.StatusLight.Color = app.readyColor;
                case 'snap'
                    % Snap selected points
                    [oldpts,obj_nrs,idx] = selectPoints(app);
                    if isempty(obj_nrs);return;end
                    
                    % Save objects of current slice for undo action
                    undoFunction(app,...
                        {'snap selected points',...
                        app.slice_nr,...
                        app.ObjectData(app.slice_nr)})
                    
                    % Snap points
%                     newpts = snap_to_edge(...
%                         app.img(:,:,app.slice_nr),...
%                         oldpts,...
%                         'sigma',app.sigma,...
%                         'spacing',app.metadata.PixelDimensions(1:2),...
%                         'maxd',app.maxsnapSpinner.Value,...
%                         'method','linear');
                    unique_object_nrs = unique(obj_nrs)';
                    newpts = snap_to_edge(...
                        app.img(:,:,app.slice_nr),...
                        app.ObjectData(app.slice_nr).position(unique_object_nrs),...
                        'sigma',app.sigma,...
                        'spacing',app.metadata.PixelDimensions(1:2),...
                        'maxd',app.maxsnapSpinner.Value,...
                        'method','linear');
                    
                    % Overwrite old points with the new points.
                    for i =  1 : length(unique_object_nrs)
                        obj_nr = unique_object_nrs(i);
                        id = idx(obj_nrs == obj_nr);                        
%                         app.ObjectData(app.slice_nr).position{obj_nr}(id,:) = newpts(obj_nrs == obj_nr,:);
                        app.ObjectData(app.slice_nr).position{obj_nr}(id,:) = newpts{i}(id,:);
                            
                    end
                    objectFunction(app,'updateslice')
                    maskFunction(app,'updateslice')
                    app.StatusLabel.Text = '';
                    app.StatusLight.Color = app.readyColor;
                    
            end % of switch
        end

        % Callback function: LabelTable, LabelTable, loadLabelsMenu, 
        % showhideLabelnamesMenu
        function labelFunction(app, event)
            % This function executes all actions related to labels.
            if ischar(event)
                action = event;
            else
                action = event.Source.Tag;
            end
            
            switch action
                case 'init'
                    % Fills the table with the default labels.
                    app.LabelNames = cell(app.nLabels,1);
                    for label_nr = 1 : app.nLabels
                        app.LabelNames{label_nr} = sprintf('Label %d',label_nr);
                    end
                    app.LabelTable.ColumnFormat = {'char','logical'};
                    app.LabelTable.Data = [app.LabelNames num2cell(true(app.nLabels,1))];
                    app.LabelTable.BackgroundColor = app.colors;
                                        
                    % Make a list of strings with label numbers, to be used to populate the dropdown menu of the object table.
                    app.label_str = cell(1,app.nLabels);
                    for label_nr = 1 : app.nLabels
                        app.label_str{label_nr} = sprintf('%d',label_nr);
                    end
                    
                    app.LabelTable.RowName = 'numbered';
                    
                case 'labeltable'
                    switch event.EventName
                        case 'CellSelection'
                            % Set selected label to the current object number.
                            app.currentLabel_nr = event.Indices(1);
                            app.StatusLabel.Text = sprintf('Current label set to %d',app.currentLabel_nr);
                        case 'CellEdit'
                            if event.Indices(2) == 1
                                % Update LabelNames with the new entry.
                                app.LabelNames = app.LabelTable.Data(:,1);
                                
                                % Update object table with new names
                                if ~isempty(app.ObjectTable.Data)
                                    app.ObjectTable.Data(cellfun(@str2double,app.ObjectTable.Data(:,2)) == event.Indices(1),1) = {event.NewData};
                                end
                            end
                    end
                case 'load'
                    % Select a MAT-file with label information.
                    [file,path] = uigetfile({'*.mat','Matlab MAT-file'},'Select a label file');
                    % Bring the figure to the front again.
                    figure(app.mainFigure)
                    if file == 0
                        return
                    end
                    % Update the status light and label;
                    labelFilename = fullfile(path,file);
                    [~,a,b] = fileparts(labelFilename);
                    short_filename = [a b];
                    app.StatusLabel.Text = sprintf('Loading %s...',short_filename);
                    app.StatusLight.Color =  app.busyColor;
                    pause(0.01)
                    
                    % Load data
                    filedata = load(labelFilename);
                    
                    % First, perform a few checks on the data.
                    if ~isfield(filedata,'LabelNames')
                        errordlg('The selected MAT-file does not contain the variable ''LabelNames''','Error loading label file')
                        return
                    else
                        % If a row vector of label names is provided, transpose to column vector.
                        [m,n] = size(filedata.LabelNames);
                        if m < n;filedata.LabelNames = filedata.LabelNames';end
                    end
                    if ~isfield(filedata,'colors')
                        errordlg('The selected MAT-file does not contain the variable ''colors''','Error loading label file')
                        return
                    end
                    if size(filedata.LabelNames,1) ~= size(filedata.colors,1)
                        errordlg('The variables ''LabelNames'' and ''colors'' do not have the same number of rows','Error loading label file');
                        return
                    end
                    if ~isempty(app.mask)
                        max_label_nr = max(app.mask(:));
                        nColors = size(filedata.colors,1);
                        if max_label_nr > nColors
                            errordlg(sprintf('The selected label file contains %d colors but the current mask has %d labels. Select a label file with a minimum of %d colors.',nColors,max_label_nr,nColors),...
                                'Error loading label file')
                            return
                        end
                    end
                    
                    % Update the label table.
                    app.LabelNames      = filedata.LabelNames;
                    app.colors          = filedata.colors;
                    app.nLabels         = size(app.colors,1);
                    labelFunction(app,'init')
                    
                    % Change the status label
                    app.StatusLabel.Text = sprintf('Labels loaded from %s...',short_filename);
                    app.StatusLight.Color =  app.readyColor;
                    
                    % Update objects and mask with new color scheme.
                    if ~isempty(app.img)
                        objectFunction(app,'updateslice')
                        maskFunction(app,'updateslice')
                    end
                case 'visible'
                    % toggle visibility of label names
                    if app.showLabelName == false
                        app.showLabelName = true;
                    else
                        app.showLabelName = false;
                    end
                    for obj_nr = 1 : length(app.h_poly)
                        if app.showLabelName == true
                            lbl = app.LabelNames{app.ObjectData(app.slice_nr).label_nr(obj_nr)};
                            app.StatusLabel.Text = 'Label names shown';
                        else
                            lbl = '';
                            app.StatusLabel.Text = 'Label names hidden';
                        end
                        set(app.h_poly(obj_nr),'Label',lbl)
                    end
            end % of switch labelFunction
        end

        % Callback function: SlicetotrackSpinner, TrackButton, 
        % UpdateButton, trackObjectMenu, updateTrackinguMenu
        function trackFunction(app, event)
            % This function executes all actions related to tracking.
            persistent backup
            
            if ischar(event)
                action = event;
            else
                action = event.Source.Tag;
            end
            switch action
                case {'track','update'}
                    % Register slices with the selected settings
                    switch action
                        case 'track'
                            % Track new slice. Set the reference and to-be-tracked slice numbers.
                            app.slice_nr_ref  = app.slice_nr;
                            app.slice_nr_to_track = app.slice_nr + app.SlicetotrackSpinner.Value;
                            
                            % Check if tracked slice number is inside image range.
                            if app.slice_nr_to_track < 1 || app.slice_nr_to_track > app.metadata.ImageSize(3)
                                errordlg(sprintf('Slice to be tracked (%d) is outside the image range (%d to %d).',...
                                    app.slice_nr_to_track,1,app.metadata.ImageSize(3)),...
                                    'Error tracking')
                                return
                            end
                            backup.ObjectData = app.ObjectData(app.slice_nr_to_track);
                            
                            % ------------- Determine which objects to track -------------
                            % Only track objects with label numbers for which the 'track' checkbox in the label table was set to true.
%                             object_nrs_to_track = find(cell2mat(app.ObjectTable.Data(:,4)));
                            label_nrs_to_track = find(cell2mat(app.LabelTable.Data(:,2)));
                            object_nrs_to_track = find(ismember(app.ObjectData(app.slice_nr_ref).label_nr,label_nrs_to_track));
                            if isempty(object_nrs_to_track)
                                app.StatusLabel.Text = 'Nothing to track.';
                                app.UpdateButton.Enable = 'off';
                                return
                            end
                            backup.object_nrs_to_track = object_nrs_to_track;
                        case 'update'
                            % Track again with updated settings.
                            app.ObjectData(app.slice_nr_to_track) = backup.ObjectData;
                            object_nrs_to_track = backup.object_nrs_to_track;
                    end
                                        
                    % Set UndoData before action
                    undoFunction(app,...
                        {'tracking',... % action
                        app.slice_nr_to_track,...  % reference/to track slice number
                        app.ObjectData(app.slice_nr_to_track)}); % slice object data before change
                
                    % ------------- Perform demons registration -------------
                    % Update the status label and set the status light to orange.
                    app.StatusLabel.Text = 'Registering images...';
                    app.StatusLight.Color = app.busyColor;
                    pause(0.01) % a short pause is set here to make sure the status light color changes.                        
                    tic
                    [~,new_pos] = ...
                        register_slices(...
                            app.img(:,:,app.slice_nr_to_track),app.img(:,:,app.slice_nr_ref),...
                            'N',                        app.IterationsSpinner.Value,...
                            'PyramidLevels',            app.PyramidlevelsSpinner.Value,...
                            'AccumulatedFieldSmoothing',app.SmoothingSpinner.Value,...
                            'points',                   app.ObjectData(app.slice_nr_ref).position(object_nrs_to_track),...
                            'spacing',                  app.metadata.PixelDimensions(1:2),...
                            'crop',                     true,...
                            'padding',                  10);
                    new_label_nr = app.ObjectData(app.slice_nr_ref).label_nr(object_nrs_to_track);
                    new_type     = app.ObjectData(app.slice_nr_ref).type(object_nrs_to_track);
                    t_elapsed = toc;
                    
                    % If edge-snapping is enable, snap the tracked objects.
                    if app.SnaptoedgesCheckBox.Value == true
                        new_pos = snap_to_edge(...
                            app.img(:,:,app.slice_nr_to_track),...
                            new_pos,...
                            'sigma',app.sigma,...
                            'spacing',app.metadata.PixelDimensions(1:2),...
                            'maxd',app.maxsnapSpinner.Value,...
                            'method','linear');
                    end
                    % Report back how long it took to register the images and change the status light back to green.
                    app.StatusLabel.Text = sprintf('It took %.2f sec to track %d object(s).',t_elapsed,length(object_nrs_to_track));
                    app.StatusLight.Color = app.readyColor;
                    
                    % ------------- Update position data and graphics objects  -------------
                    
                    % Determine which label numbers from the tracked slice should be kept/removed.
                    % First, make a list of label numbers that were tracked, and a list of label numbers that
                    % were already present in the tracked slice.
                    label_nrs_pred = unique(app.ObjectData(app.slice_nr_to_track).label_nr);
                    label_nrs_tracked = unique(app.ObjectData(app.slice_nr_ref).label_nr(object_nrs_to_track));
                    switch app.OverwriteLabelsDropdown.Value
                        case 'Tracked'
                            % Only keep objects in the tracked slice with label numbers other than the tracked objects.
                            object_nrs_to_keep = find(~ismember(label_nrs_pred,label_nrs_tracked));
                        case 'None'
                            % No objects should be removed. Keep all previously existing objects in the tracked slice.
                            object_nrs_to_keep = 1 : length(app.ObjectData(app.slice_nr_to_track).label_nr);
                        case 'All'
                            % All objects should be removed.
                            object_nrs_to_keep = [];
                    end
                    
                    % Add the tracked objects to the already existing objects.
                    app.ObjectData(app.slice_nr_to_track).position = [app.ObjectData(app.slice_nr_to_track).position(object_nrs_to_keep), new_pos];
                    app.ObjectData(app.slice_nr_to_track).label_nr = [app.ObjectData(app.slice_nr_to_track).label_nr(object_nrs_to_keep), new_label_nr];
                    app.ObjectData(app.slice_nr_to_track).type     = [app.ObjectData(app.slice_nr_to_track).type(object_nrs_to_keep), new_type];
                    
                    % Update the figure. Show the tracked slice.
                    app.slice_nr = app.slice_nr_to_track;
                    showSlice(app)
                    
                    % Enable the update button.
                    app.UpdateButton.Enable = 'on';

                case 'slice_to_track'
                    % This function makes sure that the value of the gap spinner is never 0.
                    value = app.SlicetotrackSpinner.Value;
                    if value == 0
                        if app.prev_slice_gap > 0
                            app.SlicetotrackSpinner.Value = -1;
                            app.prev_slice_gap = -1;
                        else
                            app.SlicetotrackSpinner.Value = 1;
                            app.prev_slice_gap = 1;
                        end
                    end                    
            end            
        end

        % Callback function: SliceSlider, slice_down, slice_up
        function showSlice(app, event)
            if nargin == 1
                action = '';
            elseif ischar(event)
                action = event;
            else
                action = event.Source.Tag;
            end
                    
            switch action
                case 'up'
                    % Scroll one slice up
                    app.slice_nr = min([app.metadata.ImageSize(3),app.slice_nr + 1]);
                case 'down'
                    % Scroll one slice down
                    app.slice_nr = max([1,app.slice_nr - 1]);
                case 'slider'
                    % Show the slider-selected slice
                    app.slice_nr = round(app.SliceSlider.Value);                    
                case 'track'
                    % Show the tracked slice
                    app.slice_nr = app.slice_nr_to_track;
            end
            if ~strcmp(action,'track')
                app.UpdateButton.Enable = 'off';
            end
            
            % Update slice slider
            app.SliceSlider.Value = app.slice_nr;
            app.SliceLabel.Text = sprintf('%d / %d',app.slice_nr,app.metadata.ImageSize(3));
            
            % Update image/mask/object
            imageFunction(app,'updateslice')
            maskFunction(app,'updateslice')
            objectFunction(app,'updateslice')
        end

        % Size changed function: mainFigure
        function changeSize(app, event)
            position = app.mainFigure.Position;
            minsize = [900 750];
            if position(3) < minsize(1)
                position(3) = minsize(1);                
            end
            if position(4) < minsize(2)
                position(4) = minsize(2);                
            end
            app.mainFigure.Position = position;
            
            % Status light and text
            p = app.StatusLight.Position;
            app.StatusLight.Position = [position(3)-30 p(2:4)];
            p = app.StatusLabel.Position;
            app.StatusLabel.Position = [position(3)-210 p(2:4)];
%             app.StatusLabel.Text = 'Status';
            
            % Object and tracking panel
            % Align bottom of tracking panel with bottom of image panel
            p = app.TrackingPanel.Position;
            app.TrackingPanel.Position = [position(3)-205 p(2) p(3:4)];
            
            % Place the contrast panel on top of the tracking panel with 5 pixels spacing.
            p = app.ContrastPanel.Position;
            yPos = app.TrackingPanel.Position(2) + app.TrackingPanel.Position(4) + 5;
            app.ContrastPanel.Position = [position(3)-205 yPos p(3:4)];
            
            % Place the object panel on top of the contrast panel with 5 pixel spacing.
            % Align the top of the contrast panel with the top of the image panel.
            p = app.ObjectsPanel.Position;
            yPos = app.ContrastPanel.Position(2) + app.ContrastPanel.Position(4) + 5;
            h = position(4) - yPos - 5;
            app.ObjectsPanel.Position = [position(3)-205 yPos  p(3) h];
            
            % Image panel
            p = app.UIAxes.Position;
            app.UIAxes.Position = [p(1) p(2) position(3)-220 position(4)-80];
            
            % Slice slider and stack spinners
            p = app.SliceSlider.Position;
            app.SliceSlider.Position = [p(1:2) position(3)-380 p(4)];
            p = app.slice_up.Position;
            app.slice_up.Position = [position(3)-320 p(2:4)];
            p = app.StackSpinner.Position;
            app.StackSpinner.Position = [position(3)-255 p(2:4)];
            p = app.StackLabel.Position;
            app.StackLabel.Position = [position(3)-295 p(2:4)];
            
            % Set axis equal again to mainrain stretch-to-fill behavior
            axis(app.UIAxes,'equal')
            
        end

        % Window key press function: mainFigure
        function keyPress(app, event)
            key = event.Key;
            
            % Add the pressed key to isPressed
            app.isPressed{end+1} = key;
            
            if app.isBusy == true
                return
            end
            
            % Don't do anything. If a modifier key is pressed.            
            if any(ismember(app.isPressed,{'control'}))                
                return
            end
            
            if startsWith(key,'numpad')
                % Remove 'numpad' from the key string if numbers on the numeric pad were pressed.
                key = key(7:end);
            end
            if strcmpi(key,'t') % track
                if strcmp(app.TrackButton.Enable,'on')
                    event.Source.Tag = 'track';
                    trackFunction(app,event)
                end
            elseif strcmpi(key,'u') % update tracking
                if strcmp(app.UpdateButton.Enable,'on')
                    event.Source.Tag = 'update';
                    trackFunction(app,event)
                end
            elseif strcmpi(key,'a') % add object
                if strcmp(app.AddButton.Enable, 'on')
                    objectFunction(app,'add')
                end
            elseif strcmpi(key,'r') % remove object
                if strcmp(app.RemoveButton.Enable, 'on')
                    objectFunction(app,'remove')
                end
            elseif strcmpi(key,'leftarrow') % show previous slice
                event.Source.Tag = 'down';
                showSlice(app,event);
            elseif strcmpi(key,'rightarrow') % show next slice
                event.Source.Tag = 'up';
                showSlice(app,event);
            elseif strcmpi(key,'d') % delete points
                % Remove point nearest to the last clicked cursor point.
                objectFunction(app,'deletepoint')
            elseif strcmpi(key,'l') % toggle label name visibility
                labelFunction(app,'visible')            
            elseif strcmp(key,'m') % toggle mask visibility
                maskFunction(app,'visible')
            elseif isnumeric(str2double(key)) && ~isnan(str2double(key)) % numeric input.
                % set selected number to the current label number
                num = real(str2double(key));
                if num ~= 0
                    app.currentLabel_nr = num;
                    app.StatusLabel.Text = sprintf('Current label set to %d',num);
                end
            elseif strcmpi(key,'period')
                % decrease mask transparency
                maskFunction(app,'decrease')
            elseif strcmpi(key,'comma')
                % Increase mask transparency
                maskFunction(app,'increase')
            elseif strcmpi(key,'s')
                % Toggle object visibility
                objectFunction(app,'visible')
            elseif strcmpi(key,'q')
                % Snap object(s) to edge
                objectFunction(app,'snap')
            end
        end

        % Menu selected function: decreaseMaskTransparency, 
        % exportMaskMenu, increaseMaskTransparency, showhideMaskMenu
        function maskFunction(app, event)
            % This function executes all updates to masks (incl. the rgb-mask for display)
            if ischar(event)                
                action = event;
            else
                action = event.Source.Tag;  
            end
            switch action
                case 'init'
                    % Create an rgb-mask object. Set the logo at startup.
                    logo_filename = 'sashimi-logo.png';
                    if exist(logo_filename,'file') == 2
                        cdata = imread(logo_filename);
                    else
                        cdata = NaN(2,2,3);
                    end
                    app.h_mask = image(app.UIAxes,...
                        'CData',cdata);
                    app.UIAxes.YDir='reverse';
                    
                    disableDefaultInteractivity(app.UIAxes)
                case 'updateslice'
                    % Update the mask for the current slice.
                    app.mask(:,:,app.slice_nr) = object2mask(app,app.slice_nr);
                    
                    % Update the rgb mask
                    R = zeros(app.metadata.ImageSize(1:2));
                    G = zeros(app.metadata.ImageSize(1:2));
                    B = zeros(app.metadata.ImageSize(1:2));
                    label_nrs = unique(app.ObjectData(app.slice_nr).label_nr);
                    for i = 1 : length(label_nrs)
                        label_nr = label_nrs(i);
                        R(app.mask(:,:,app.slice_nr) == label_nr) = app.colors(label_nr,1);
                        G(app.mask(:,:,app.slice_nr) == label_nr) = app.colors(label_nr,2);
                        B(app.mask(:,:,app.slice_nr) == label_nr) = app.colors(label_nr,3);
                    end
                    app.rgbmask = cat(3,R,G,B);
            
                    % Overlay the mask with the selected transparency. Pixels that are not labeled
                    % have a transparency of 0 (fully transparent).
                    set(app.h_mask,...
                        'CData',app.rgbmask,...
                        'AlphaData',(app.mask(:,:,app.slice_nr) ~= 0)*app.maskAlpha)
                case 'export'
                    % Select a file or set a new filename
                    [~,defname] = fileparts(app.img_filename);
                    [~,defname] = fileparts(defname); % This is to make sure that the .nii is removed from the filename if the original extension was .nii.gz
                    [file,path] = uiputfile({'*.nii;*.nii.gz','NIfTI image files'},'Export to NIfTI segmentation',[defname '_mask.nii.gz']);
                    if file == 0
                        return
                    end
                    fname_mask = fullfile(path,file); 
                    
                    if endsWith(fname_mask,'.gz')
                        fname_mask = fname_mask(1:end-3);
                    end               
                    
                    % Update the mask for all slices.
                    for slice_nr = 1 : app.metadata.ImageSize(3)
                        app.mask(:,:,slice_nr) = object2mask(app,slice_nr);
                    end
                    
                    % Update some of the header information and export as a NIfTI file.
                    info                 = app.metadata;
                    info.Filename        = fname_mask;
                    info.PixelDimensions = app.metadata.PixelDimensions(1:3);
                    info.ImageSize       = app.metadata.ImageSize(1:3);
                    info.BitsPerPixel    = 16;
                    info.Datatype        = 'int16';
                    info.MultiplicativeScaling = 1;
                    info.raw.dim(1)      = 3;
                    info.raw.dim(5)      = 1;
                    info.raw.datatype    = 4;
                    info.raw.bitpix      = 16;
                    info.raw.scl_slope   = 1;
                    info.raw.scl_inter   = 0;
                    niftiwrite(int16(app.mask),fname_mask,info,...
                        'Compressed',true,...
                        'Combined',true)
                    [~,b,c] = fileparts(fname_mask);
                    app.StatusLabel.Text = sprintf('Mask exported as %s',[b,c]);
                case 'visible'
                    if strcmp(get(app.h_mask,'visible'),'on')
                        set(app.h_mask,'visible','off')
                    else
                        set(app.h_mask,'visible','on')
                    end
                case 'increase'
                    % increase mask tranparency (= decrease alpha)
                    app.maskAlpha = max([0.2,app.maskAlpha - 0.1]);                    
                    set(app.h_mask,...
                        'ALphaData',(app.mask(:,:,app.slice_nr) ~= 0)*app.maskAlpha)
                    app.StatusLabel.Text = sprintf('Mask tranparency set to %.1f',app.maskAlpha);
                case 'decrease'
                    % decrease mask tranparency (= increase alpha)
                    app.maskAlpha = min([1,app.maskAlpha + 0.1]);
                    set(app.h_mask,...
                        'ALphaData',(app.mask(:,:,app.slice_nr) ~= 0)*app.maskAlpha)
                    app.StatusLabel.Text = sprintf('Mask tranparency set to %.1f',app.maskAlpha);
            end % of switch maskFunction            

        end

        % Callback function: autoContrastButton, maxContrastSlider, 
        % minContrastSlider
        function setContrast(app, event)
            if ischar(event)
                action = event;
            else
                action = event.Source.Tag;
            end
            switch action
                case 'min'
                    % minimum contrast slider changed.
                    app.clim(1) = app.minContrastSlider.Value;
                    if app.clim(1) >= app.maxContrastSlider.Value
                        app.clim(2) = app.clim(1) + 1;
                        app.maxContrastSlider.Value = app.clim(2);
                    end
                case 'max'
                    % maximum contrast slider changed.
                    app.clim(2) = app.maxContrastSlider.Value;
                    if app.clim(2) <= app.minContrastSlider.Value
                        app.clim(1) = app.clim(2) - 1;
                        app.minContrastSlider.Value = app.clim(1);
                    end
                case 'auto'
                    % Set contrast to minimum and maximum of current slice.
                    app.clim = double([min(min(get(app.h_img,'CData'))),max(max(get(app.h_img,'CData')))]);
                    app.minContrastSlider.Value = app.clim(1);
                    app.maxContrastSlider.Value = app.clim(2);                    
            end
            % Update range label
            app.contrastRangeLabel.Text = sprintf('Range: %.1f  - %.1f',app.clim(1),app.clim(2));
            set(app.UIAxes,'CLim',app.clim)
            
        end

        % Window key release function: mainFigure
        function keyRelease(app, event)
            key = event.Key;
            
            % Remove released key from isPressed array
            app.isPressed = app.isPressed(~strcmp(key,app.isPressed));
        end

        % Close request function: mainFigure
        function closeRequest(app, event)
            
            % Check if the current ObjectData is the same as the last saved version AND contains any data.
            % If not, ask the user to save the changes before closing SASHIMI.
            ObjectDataIsEmpty = all(all(cellfun(@(x)isempty(x),struct2cell(app.ObjectData))));
            isEqualToLastSaved = isequal(app.lastSavedObjectData,app.ObjectData);
            if ~isEqualToLastSaved && ~ObjectDataIsEmpty
                answer = questdlg('There are unsaved changes to the objects. Do you want to save the changes before closing SASHIMI?',...
                    'Unsaved changes','Yes','No (close SASHIMI)','Cancel','Yes');
                switch answer
                    case 'Yes' % Save changes and close SASHIMI.
                        objectFunction(app,'save')
                        delete(app)
                    case 'No (close SASHIMI)' % Do not save changes. Close SASHIMI.
                        delete(app)
                    case 'Cancel'
                        % Do nothing. Return to SASHIMI.
                end
            else
                % ObjectData is empty or no unsaved changes were found. Close SASHIMI.
                delete(app)                
            end
        end

        % Menu selected function: redoMenu, undoMenu
        function undoFunction(app, event)
            
            if ischar(event)
                action = event;
            elseif iscell(event)
                action = 'set';
            else
                action = event.Source.Tag;
            end
            
            switch action
                case 'init'
                    % Initialize undo
                    app.UndoData = cell(0,3);
                    app.undo_idx = 0;
                    app.RedoData = cell(0,3);
                    app.redo_idx = 0;
                    app.undoMenu.Enable = 'off';
                    app.redoMenu.Enable = 'off';
                case 'set'                        
                    % Add new undoable action.
                    if length(event) == 3
                        % before action                                                
                        app.undo_idx = app.undo_idx + 1;
                        app.UndoData(app.undo_idx,1:3) = event;
                        
                        if  size(app.UndoData,1) > app.undo_idx
                            % possible redo actions will be removed
                            app.UndoData(app.undo_idx+1:end,:) = [];
                        end
                        
                        % Remove redo actions.
                        app.redo_idx = 0;
                        app.RedoData = {};
                        
%                     elseif length(event) == 2
%                         % after action
%                         app.UndoData(app.undo_idx,4:5) = event;
%                         % Update the menu bar
%                         app.undoMenu.Enable = 'on';
%                         app.undoMenu.Text = sprintf('Undo %s', app.UndoData{app.undo_idx,1});                            
                    end
                case 'cancel'
                    % Action was not completed. Remove last row from UndoData.
                    app.UndoData(app.undo_idx,:) = [];
                    app.undo_idx = app.undo_idx - 1;
                    
                case 'undo'                    
                    % Undo last action.
                    if strcmp(app.undoMenu.Enable,'off');return;end
                    
                    action_to_undo  = app.UndoData{app.undo_idx,1};
                    slice_nr        = app.UndoData{app.undo_idx,2};
                    ObjectDataSlice = app.UndoData{app.undo_idx,3};
                    
                    % Before undoing the change, capture the current state for possible redo-action.
                    app.redo_idx = app.redo_idx + 1;
                    app.RedoData(app.redo_idx,:) = {action_to_undo,slice_nr,app.ObjectData(slice_nr)};
              
                    % Update object and display.
                    app.slice_nr = slice_nr;
                    app.ObjectData(app.slice_nr) = ObjectDataSlice;
%                     end
                    
                    % Update the axes.
                    showSlice(app)
                    
                    % Reduce the undo index by 1.
                    app.undo_idx = app.undo_idx - 1;
              
                    % Report in status bar the action that was undone.
                    app.StatusLabel.Text = sprintf('Undone: %s',action_to_undo);
                case 'redo'
                    % Redo last 'undone' action.
                    if strcmp(app.redoMenu.Enable,'off');return;end
                
                    action_to_redo  = app.RedoData{app.redo_idx,1};
                    slice_nr        = app.RedoData{app.redo_idx,2};
                    ObjectDataSlice = app.RedoData{app.redo_idx,3};
                    
                    % Update redo and undo indices.
                    app.redo_idx = app.redo_idx - 1;
                    app.undo_idx = app.undo_idx + 1;
                    
                    % Update object and display.
                    app.slice_nr = slice_nr;
                    app.ObjectData(app.slice_nr) = ObjectDataSlice;
                    showSlice(app)
                                        
                    % Report in status bar the action that was redone.
                    app.StatusLabel.Text = sprintf('Redone: %s',action_to_redo);
                        
            end % of switch undoFunction
            
            % Set undo/redo menu items            
            if app.redo_idx == 0
                app.redoMenu.Enable = 'off';
                app.redoMenu.Text = 'Redo';
            else 
                app.redoMenu.Enable = 'on';
                app.redoMenu.Text = sprintf('Redo %s',app.RedoData{app.redo_idx,1});                
            end
            
            if app.undo_idx == 0
                app.undoMenu.Enable = 'off';
                app.undoMenu.Text   = 'Undo';
            else 
                app.undoMenu.Enable = 'on';
                app.undoMenu.Text = sprintf('Undo %s',app.UndoData{app.undo_idx,1});                
            end
            
            % Cut the history to a maximum of 15 steps
            max_undo_steps = 15;
            if app.undo_idx > max_undo_steps
                app.undo_idx = max_undo_steps;
                app.UndoData = app.UndoData(end-max_undo_steps+1:end,:);
            end
            
        end

        % Menu selected function: flipBothMenu, flipxMenu, 
        % flipyMenu, showhideAxesMenu
        function viewFunction(app, event)
            % Changes view settings of axes.
            action = event.Source.Tag;
            switch action
                case 'x' % flip x axis
                    if strcmp(app.UIAxes.XDir,'normal')
                        app.UIAxes.XDir='reverse';
                    else
                        app.UIAxes.XDir='normal';
                    end
                case 'y' % flip y axis
                    if strcmp(app.UIAxes.YDir,'normal')
                        app.UIAxes.YDir='reverse';
                    else
                        app.UIAxes.YDir='normal';
                    end
                case 'both' % flip both axis
                    if strcmp(app.UIAxes.XDir,'normal')
                        app.UIAxes.XDir='reverse';
                    else
                        app.UIAxes.XDir='normal';
                    end
                    if strcmp(app.UIAxes.YDir,'normal')
                        app.UIAxes.YDir='reverse';
                    else
                        app.UIAxes.YDir='normal';
                    end
                case 'showaxes'
                      if strcmp(app.UIAxes.Visible,'on')
                          app.UIAxes.Visible = 'off';
                      else
                          app.UIAxes.Visible = 'on';
                      end
            end
            
%             % Axis rotation disabled for now because I could not get this to work
%             % properly with the custom panning, which requires conversion
%             % from image pixels to axes coordinates.
%             app.UIAxes.CameraUpVectorMode = 'auto';
%             axis(app.UIAxes,'on')
%             if any(strcmp(action,{'cw','ccw'}))
%                 camroll(app.UIAxes,app.viewangle)    
%                 switch action
%                     case 'cw'
%                         app.viewangle = app.viewangle + 90;
%                     case 'ccw'
% %                         app.viewangle = -90;
%                         app.viewangle = app.viewangle - 90;
%                 end
%                 if app.viewangle >= 360
%                     app.viewangle = app.viewangle - 360;
%                 elseif app.viewangle < 0
%                     app.viewangle = app.viewangle + 360;
%                 end
%                 UpVector = [-sind(app.viewangle), cosd(app.viewangle), 0];
%                 DAR      = get(app.UIAxes, 'DataAspectRatio');
%                 set(app.UIAxes, 'CameraUpVector', DAR .* UpVector);
%             end
            
        end

        % Window scroll wheel function: mainFigure
        function scrollwheelFunction(app, event)
            % Zoom in/out
            if any(ismember(app.isPressed,{'control'}))
                % if control is pressed at the same time, change the slice.
                if event.VerticalScrollCount > 0
                    showSlice(app,'up')
                else
                    showSlice(app,'down')
                end
                
            else
                % If the control is not pressed, zoom in/out.
                if event.VerticalScrollCount > 0
                    zoom(app.UIAxes,1/1.2)
                else
                    zoom(app.UIAxes,1.2)
                end
            end
            
        end

        % Window button up function: mainFigure
        function buttonUp(app, event)
            % Mouse button released.
            app.MouseDown = false;
        end

        % Window button down function: mainFigure
        function buttonDown(app, event)
            % Mouse button clicked. Detect which one was clicked.
            if strcmp(app.mainFigure.SelectionType,'extend')
                % If the middle mouse button was clicked, set the MouseDown
                % property to true.
                app.MouseDown = true;
                app.previousPos = get(app.mainFigure,'CurrentPoint');
                app.previousPos = app.previousPos(1,1:2);
                app.xlim = app.UIAxes.XLim;
                app.ylim = app.UIAxes.YLim;                
            else
                app.MouseDown = false;
            end
        end

        % Window button motion function: mainFigure
        function buttonMotion(app, event)
            % Custom panning
            if app.MouseDown == true
                currentPos = get(app.mainFigure,'CurrentPoint');
                currentPos = currentPos(1,1:2);
                changePos = app.previousPos - currentPos;
                sgn = [1 1];
                if strcmp(app.UIAxes.XDir,'reverse')
                    sgn(1) = -1;
                end
                if strcmp(app.UIAxes.YDir,'reverse')
                    sgn(2) = -1;
                end
                scale = [range(app.xlim) range(app.ylim)] ./ app.mainFigure.Position(3:4);
                app.UIAxes.XLim = app.xlim + sgn(1) * scale * changePos(1);
                app.UIAxes.YLim = app.ylim + sgn(2) * scale * changePos(2);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.mainFigure = uifigure('Visible', 'off');
            app.mainFigure.Name = 'SASHIMI Segmentation - v1.0';
            app.mainFigure.CloseRequestFcn = createCallbackFcn(app, @closeRequest, true);
            app.mainFigure.SizeChangedFcn = createCallbackFcn(app, @changeSize, true);
            app.mainFigure.WindowButtonDownFcn = createCallbackFcn(app, @buttonDown, true);
            app.mainFigure.WindowButtonUpFcn = createCallbackFcn(app, @buttonUp, true);
            app.mainFigure.WindowButtonMotionFcn = createCallbackFcn(app, @buttonMotion, true);
            app.mainFigure.WindowScrollWheelFcn = createCallbackFcn(app, @scrollwheelFunction, true);
            app.mainFigure.WindowKeyPressFcn = createCallbackFcn(app, @keyPress, true);
            app.mainFigure.WindowKeyReleaseFcn = createCallbackFcn(app, @keyRelease, true);
            app.FileMenu = uimenu(app.mainFigure);
            app.FileMenu.Text = 'File';
            app.loadImageMenu = uimenu(app.FileMenu);
            app.loadImageMenu.MenuSelectedFcn = createCallbackFcn(app, @imageFunction, true);
            app.loadImageMenu.Separator = 'on';
            app.loadImageMenu.Accelerator = 'o';
            app.loadImageMenu.Text = 'Load image...';
            app.loadObjectMenu = uimenu(app.FileMenu);
            app.loadObjectMenu.MenuSelectedFcn = createCallbackFcn(app, @objectFunction, true);
            app.loadObjectMenu.Enable = 'off';
            app.loadObjectMenu.Accelerator = 'l';
            app.loadObjectMenu.Text = 'Load objects...';
            app.saveObjectMenu = uimenu(app.FileMenu);
            app.saveObjectMenu.MenuSelectedFcn = createCallbackFcn(app, @objectFunction, true);
            app.saveObjectMenu.Enable = 'off';
            app.saveObjectMenu.Separator = 'on';
            app.saveObjectMenu.Accelerator = 's';
            app.saveObjectMenu.Text = 'Save objects';
            app.saveObjectAsMenu = uimenu(app.FileMenu);
            app.saveObjectAsMenu.MenuSelectedFcn = createCallbackFcn(app, @objectFunction, true);
            app.saveObjectAsMenu.Enable = 'off';
            app.saveObjectAsMenu.Text = 'Save objects as...';
            app.loadLabelsMenu = uimenu(app.FileMenu);
            app.loadLabelsMenu.MenuSelectedFcn = createCallbackFcn(app, @labelFunction, true);
            app.loadLabelsMenu.Separator = 'on';
            app.loadLabelsMenu.Text = 'Load custom labels...';
            app.exportMaskMenu = uimenu(app.FileMenu);
            app.exportMaskMenu.MenuSelectedFcn = createCallbackFcn(app, @maskFunction, true);
            app.exportMaskMenu.Enable = 'off';
            app.exportMaskMenu.Separator = 'on';
            app.exportMaskMenu.Accelerator = 'e';
            app.exportMaskMenu.Text = 'Export to NIfTI segmentation...';
            app.ObjectMenu = uimenu(app.mainFigure);
            app.ObjectMenu.Text = 'Object';
            app.undoMenu = uimenu(app.ObjectMenu);
            app.undoMenu.MenuSelectedFcn = createCallbackFcn(app, @undoFunction, true);
            app.undoMenu.Enable = 'off';
            app.undoMenu.Accelerator = 'z';
            app.undoMenu.Text = 'Undo';
            app.redoMenu = uimenu(app.ObjectMenu);
            app.redoMenu.MenuSelectedFcn = createCallbackFcn(app, @undoFunction, true);
            app.redoMenu.Enable = 'off';
            app.redoMenu.Accelerator = 'y';
            app.redoMenu.Text = 'Redo';
            app.addObjectMenu = uimenu(app.ObjectMenu);
            app.addObjectMenu.MenuSelectedFcn = createCallbackFcn(app, @objectFunction, true);
            app.addObjectMenu.Enable = 'off';
            app.addObjectMenu.Separator = 'on';
            app.addObjectMenu.Text = 'Add object (a)';
            app.removeObjectMenu = uimenu(app.ObjectMenu);
            app.removeObjectMenu.MenuSelectedFcn = createCallbackFcn(app, @objectFunction, true);
            app.removeObjectMenu.Enable = 'off';
            app.removeObjectMenu.Text = 'Remove objects (r)';
            app.deletePointMenu = uimenu(app.ObjectMenu);
            app.deletePointMenu.MenuSelectedFcn = createCallbackFcn(app, @objectFunction, true);
            app.deletePointMenu.Enable = 'off';
            app.deletePointMenu.Text = 'Delete points (d)';
            app.trackObjectMenu = uimenu(app.ObjectMenu);
            app.trackObjectMenu.MenuSelectedFcn = createCallbackFcn(app, @trackFunction, true);
            app.trackObjectMenu.Enable = 'off';
            app.trackObjectMenu.Separator = 'on';
            app.trackObjectMenu.Text = 'Track objects (t)';
            app.updateTrackinguMenu = uimenu(app.ObjectMenu);
            app.updateTrackinguMenu.MenuSelectedFcn = createCallbackFcn(app, @trackFunction, true);
            app.updateTrackinguMenu.Enable = 'off';
            app.updateTrackinguMenu.Text = 'Update tracking (u)';
            app.snapMenu = uimenu(app.ObjectMenu);
            app.snapMenu.MenuSelectedFcn = createCallbackFcn(app, @objectFunction, true);
            app.snapMenu.Enable = 'off';
            app.snapMenu.Separator = 'on';
            app.snapMenu.Text = 'Snap to edge (q)';
            app.ViewMenu = uimenu(app.mainFigure);
            app.ViewMenu.Text = 'View';
            app.FlipaxesMenu = uimenu(app.ViewMenu);
            app.FlipaxesMenu.Text = 'Flip axes';
            app.flipxMenu = uimenu(app.FlipaxesMenu);
            app.flipxMenu.MenuSelectedFcn = createCallbackFcn(app, @viewFunction, true);
            app.flipxMenu.Text = 'x-axis';
            app.flipyMenu = uimenu(app.FlipaxesMenu);
            app.flipyMenu.MenuSelectedFcn = createCallbackFcn(app, @viewFunction, true);
            app.flipyMenu.Text = 'y-axis';
            app.flipBothMenu = uimenu(app.FlipaxesMenu);
            app.flipBothMenu.MenuSelectedFcn = createCallbackFcn(app, @viewFunction, true);
            app.flipBothMenu.Text = 'Both';
            app.showhideAxesMenu = uimenu(app.ViewMenu);
            app.showhideAxesMenu.MenuSelectedFcn = createCallbackFcn(app, @viewFunction, true);
            app.showhideAxesMenu.Text = 'Show/hide axes';
            app.showhideMaskMenu = uimenu(app.ViewMenu);
            app.showhideMaskMenu.MenuSelectedFcn = createCallbackFcn(app, @maskFunction, true);
            app.showhideMaskMenu.Separator = 'on';
            app.showhideMaskMenu.Text = 'Show/hide mask (m)';
            app.increaseMaskTransparency = uimenu(app.ViewMenu);
            app.increaseMaskTransparency.MenuSelectedFcn = createCallbackFcn(app, @maskFunction, true);
            app.increaseMaskTransparency.Text = 'Increase mask transparency (, [comma])';
            app.decreaseMaskTransparency = uimenu(app.ViewMenu);
            app.decreaseMaskTransparency.MenuSelectedFcn = createCallbackFcn(app, @maskFunction, true);
            app.decreaseMaskTransparency.Text = 'Decrease mask transparency (. [dot])';
            app.showhideLabelnamesMenu = uimenu(app.ViewMenu);
            app.showhideLabelnamesMenu.MenuSelectedFcn = createCallbackFcn(app, @labelFunction, true);
            app.showhideLabelnamesMenu.Separator = 'on';
            app.showhideLabelnamesMenu.Text = 'Show/hide label names (l)';
            app.showhideObjectsMenu = uimenu(app.ViewMenu);
            app.showhideObjectsMenu.MenuSelectedFcn = createCallbackFcn(app, @objectFunction, true);
            app.showhideObjectsMenu.Text = 'Show/hide objects (s)';
            app.SliceLabel = uilabel(app.mainFigure);
            app.SliceLabel.Enable = 'off';
            app.SliceLabel.Text = 'Slice';
            app.SliceSlider = uislider(app.mainFigure);
            app.SliceSlider.Limits = [1 10];
            app.SliceSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.SliceSlider.ValueChangedFcn = createCallbackFcn(app, @showSlice, true);
            app.SliceSlider.MinorTicks = [1 1.1 1.2 1.3 1.4 1.5 1.6 1.7 1.8 1.9 2 2.1 2.2 2.3 2.4 2.5 2.6 2.7 2.8 2.9 3 3.1 3.2 3.3 3.4 3.5 3.6 3.7 3.8 3.9 4 4.1 4.2 4.3 4.4 4.5 4.6 4.7 4.8 4.9 5 5.1 5.2 5.3 5.4 5.5 5.6 5.7 5.8 5.9 6 6.1 6.2 6.3 6.4 6.5 6.6 6.7 6.8 6.9 7 7.1 7.2 7.3 7.4 7.5 7.6 7.7 7.8 7.9 8 8.1 8.2 8.3 8.4 8.5 8.6 8.7 8.8 8.9 9 9.1 9.2 9.3 9.4 9.5 9.6 9.7 9.8 9.9 10];
            app.SliceSlider.Enable = 'off';
            app.SliceSlider.Value = 1;
            app.ObjectsPanel = uipanel(app.mainFigure);
            app.ObjectsPanel.Title = 'Objects';
            app.AddButton = uibutton(app.ObjectsPanel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @objectFunction, true);
            app.AddButton.Enable = 'off';
            app.AddButton.Tooltip = {'Add polygon object.'; ''; 'hotkey = a'};
            app.AddButton.Text = 'Add';
            app.ObjectTable = uitable(app.ObjectsPanel);
            app.ObjectTable.ColumnName = {'Name'; 'L'; 'S'};
            app.ObjectTable.ColumnEditable = [false true true];
            app.ObjectTable.CellEditCallback = createCallbackFcn(app, @objectFunction, true);
            app.ObjectTable.Tooltip = {'L = label number'; 'S = spline'};
            app.RemoveButton = uibutton(app.ObjectsPanel, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @objectFunction, true);
            app.RemoveButton.Enable = 'off';
            app.RemoveButton.Tooltip = {'Remove object(s) which include the last-clicked location in the image.'; ''; 'hotkey = r'};
            app.RemoveButton.Text = 'Remove';
            app.LabelTable = uitable(app.ObjectsPanel);
            app.LabelTable.ColumnName = {'Label name'; 'T'};
            app.LabelTable.ColumnEditable = [true true];
            app.LabelTable.CellEditCallback = createCallbackFcn(app, @labelFunction, true);
            app.LabelTable.CellSelectionCallback = createCallbackFcn(app, @labelFunction, true);
            app.LabelTable.Tooltip = {'T = enable/disable tracking'};
            app.TrackingPanel = uipanel(app.mainFigure);
            app.TrackingPanel.Title = 'Tracking';
            app.SlicetotrackSpinnerLabel = uilabel(app.TrackingPanel);
            app.SlicetotrackSpinnerLabel.Text = 'Slice to track';
            app.SlicetotrackSpinner = uispinner(app.TrackingPanel);
            app.SlicetotrackSpinner.Limits = [-10 10];
            app.SlicetotrackSpinner.RoundFractionalValues = 'on';
            app.SlicetotrackSpinner.ValueDisplayFormat = '%d';
            app.SlicetotrackSpinner.ValueChangedFcn = createCallbackFcn(app, @trackFunction, true);
            app.SlicetotrackSpinner.Tooltip = {'Slice to track relative to current slice.'; ''; 'positive = track up'; 'negative = track down'};
            app.SlicetotrackSpinner.Value = 1;
            app.SmoothingSpinnerLabel = uilabel(app.TrackingPanel);
            app.SmoothingSpinnerLabel.Text = 'Smoothing';
            app.SmoothingSpinner = uispinner(app.TrackingPanel);
            app.SmoothingSpinner.Step = 0.5;
            app.SmoothingSpinner.Limits = [0.5 3];
            app.SmoothingSpinner.ValueDisplayFormat = '%3.1f';
            app.SmoothingSpinner.Tooltip = {'This parameter controls the amount of diffusion-like regularization.'; ''; 'imregdemons applies the standard deviation of the Gaussian smoothing to regularize the accumulated field at each iteration. Larger values result in smoother output displacement fields. Smaller values result in more localized deformation in the output displacement field.'};
            app.SmoothingSpinner.Value = 1.5;
            app.PyramidlevelsSpinnerLabel = uilabel(app.TrackingPanel);
            app.PyramidlevelsSpinnerLabel.Text = 'Pyramid levels';
            app.PyramidlevelsSpinner = uispinner(app.TrackingPanel);
            app.PyramidlevelsSpinner.Limits = [1 5];
            app.PyramidlevelsSpinner.ValueDisplayFormat = '%d';
            app.PyramidlevelsSpinner.Tooltip = {'Number of multi-resolution image pyramid levels to use.'};
            app.PyramidlevelsSpinner.Value = 3;
            app.IterationsSpinnerLabel = uilabel(app.TrackingPanel);
            app.IterationsSpinnerLabel.Text = 'Iterations';
            app.IterationsSpinner = uispinner(app.TrackingPanel);
            app.IterationsSpinner.Step = 50;
            app.IterationsSpinner.Limits = [50 500];
            app.IterationsSpinner.ValueDisplayFormat = '%3d';
            app.IterationsSpinner.Tooltip = {'Number of iterations per resolution level.'};
            app.IterationsSpinner.Value = 150;
            app.UpdateButton = uibutton(app.TrackingPanel, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @trackFunction, true);
            app.UpdateButton.Enable = 'off';
            app.UpdateButton.Tooltip = {'Update tracking.'; ''; 'hotkey = u'};
            app.UpdateButton.Text = 'Update';
            app.TrackButton = uibutton(app.TrackingPanel, 'push');
            app.TrackButton.ButtonPushedFcn = createCallbackFcn(app, @trackFunction, true);
            app.TrackButton.Enable = 'off';
            app.TrackButton.Tooltip = {'Track objects between slices.'; ''; 'hotkey = t'};
            app.TrackButton.Text = 'Track';
            app.OverwritelabelsDropDownLabel = uilabel(app.TrackingPanel);
            app.OverwritelabelsDropDownLabel.Text = 'Overwrite labels';
            app.OverwriteLabelsDropdown = uidropdown(app.TrackingPanel);
            app.OverwriteLabelsDropdown.Items = {'Tracked', 'All', 'None'};
            app.OverwriteLabelsDropdown.Tooltip = {'Determines which objects are overwritten when tracking a slice that already contains objects.'; ''; 'all = all objects are overwritten '; 'tracked = only tracked objects are overwritten'; 'none = no objects are overwritten'};
            app.OverwriteLabelsDropdown.Value = 'Tracked';
            app.SnaptoedgesCheckBox = uicheckbox(app.TrackingPanel);
            app.SnaptoedgesCheckBox.Text = 'Snap to edges';
            app.SnaptoedgesCheckBox.Value = true;
            app.mmSpinnerLabel = uilabel(app.TrackingPanel);
            app.mmSpinnerLabel.Text = 'mm';
            app.maxsnapSpinner = uispinner(app.TrackingPanel);
            app.maxsnapSpinner.Step = 0.1;
            app.maxsnapSpinner.Limits = [0.5 4];
            app.maxsnapSpinner.Value = 1.5;
            app.StatusLabel = uilabel(app.mainFigure);
            app.StatusLabel.Text = '';
            app.slice_up = uibutton(app.mainFigure, 'push');
            app.slice_up.ButtonPushedFcn = createCallbackFcn(app, @showSlice, true);
            app.slice_up.Enable = 'off';
            app.slice_up.Text = '>';
            app.slice_down = uibutton(app.mainFigure, 'push');
            app.slice_down.ButtonPushedFcn = createCallbackFcn(app, @showSlice, true);
            app.slice_down.Enable = 'off';
            app.slice_down.Text = '<';
            app.StatusLight = uilamp(app.mainFigure);
            app.ContrastPanel = uipanel(app.mainFigure);
            app.ContrastPanel.Title = 'Contrast';
            app.minContrastSlider = uislider(app.ContrastPanel);
            app.minContrastSlider.MajorTicks = [];
            app.minContrastSlider.ValueChangedFcn = createCallbackFcn(app, @setContrast, true);
            app.minContrastSlider.MinorTicks = [];
            app.minContrastSlider.Enable = 'off';
            app.maxContrastSlider = uislider(app.ContrastPanel);
            app.maxContrastSlider.MajorTicks = [];
            app.maxContrastSlider.ValueChangedFcn = createCallbackFcn(app, @setContrast, true);
            app.maxContrastSlider.MinorTicks = [];
            app.maxContrastSlider.Enable = 'off';
            app.maxContrastSlider.Value = 100;
            app.autoContrastButton = uibutton(app.ContrastPanel, 'push');
            app.autoContrastButton.ButtonPushedFcn = createCallbackFcn(app, @setContrast, true);
            app.autoContrastButton.Enable = 'off';
            app.autoContrastButton.Tooltip = {'Resets the contrast  to the data range of the current slice.'};
            app.autoContrastButton.Text = 'Auto';
            app.contrastRangeLabel = uilabel(app.ContrastPanel);
            app.contrastRangeLabel.Enable = 'off';
            app.contrastRangeLabel.Text = 'Range';
            app.holdContrast = uibutton(app.ContrastPanel, 'state');
            app.holdContrast.Enable = 'off';
            app.holdContrast.Tooltip = {'When pressed'; ' the contrast is held constant when scrolling between slices.'};
            app.holdContrast.Text = 'Hold';
            app.holdContrast.Value = true;
            app.MinLabel = uilabel(app.ContrastPanel);
            app.MinLabel.Enable = 'off';
            app.MinLabel.Text = 'Min';
            app.MaxLabel = uilabel(app.ContrastPanel);
            app.MaxLabel.Enable = 'off';
            app.MaxLabel.Text = 'Max';
            app.StackSpinner = uispinner(app.mainFigure);
            app.StackSpinner.Limits = [1 Inf];
            app.StackSpinner.ValueChangedFcn = createCallbackFcn(app, @imageFunction, true);
            app.StackSpinner.Enable = 'off';
            app.StackSpinner.Value = 1;
            app.StackLabel = uilabel(app.mainFigure);
            app.StackLabel.Enable = 'off';
            app.StackLabel.Text = 'Stack';
            app.UIAxes = uiaxes(app.mainFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.mainFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SASHIMI_v1_0

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.mainFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.mainFigure)
        end
    end
end