classdef Viewer3D < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SASHIMI3DViewerUIFigure         matlab.ui.Figure
        Axes3D                          matlab.ui.control.UIAxes
        SliceviewsPanel                 matlab.ui.container.Panel
        x_slider                        matlab.ui.control.Slider
        x_check                         matlab.ui.control.CheckBox
        y_slider                        matlab.ui.control.Slider
        y_check                         matlab.ui.control.CheckBox
        z_slider                        matlab.ui.control.Slider
        z_check                         matlab.ui.control.CheckBox
        z_label                         matlab.ui.control.Label
        y_label                         matlab.ui.control.Label
        x_label                         matlab.ui.control.Label
        AlphaSliderLabel                matlab.ui.control.Label
        AlphaSlider                     matlab.ui.control.Slider
        LabelTable                      matlab.ui.control.Table
        UpdateobjectsfromSASHIMIButton  matlab.ui.control.Button
    end

    
    properties (Access = public)
        main_app   % Main SASHIMI app object
%         ObjectData % structure with position of nodes of polygon objects for all slice and objects
%         img        % image data (3D - current stack if data is 4D)
%         metadata   % image NIfTI header
%         LabelNames % label names
%         clim       % clim (contrast) property of image
    end
    
    properties (Access = private)
        PlotData3D % data (position coordinates) for 3D plotting
        x_slice_nr % x slice currently displayed
        y_slice_nr % y slice currently displayed
        z_slice_nr % z slice currently displayed
        hs     % handles to slice plot objects
        ho
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main_app)
            
            % Set some tags.
            app.x_slider.Tag = 'x';
            app.x_check.Tag  = 'xvis';
            app.y_slider.Tag = 'y';
            app.y_check.Tag  = 'yvis';
            app.z_slider.Tag = 'z';
            app.z_check.Tag  = 'zvis';
            app.AlphaSlider.Tag = 'alpha';
            app.LabelTable.Tag = 'objectvis';
            app.UpdateobjectsfromSASHIMIButton.Tag = 'objects';
            app.main_app = main_app;
            updatePlot(app,'init')
        end

        % Callback function: AlphaSlider, LabelTable, 
        % UpdateobjectsfromSASHIMIButton, x_check, x_slider, 
        % y_check, y_slider, z_check, z_slider
        function updatePlot(app, event)
            if ischar(event)
                action = event;
            else
                action = event.Source.Tag;
            end
            
            switch action
                case 'init'
                    % Set some axes properties.
                    hold(app.Axes3D,'on')
                    axis(app.Axes3D,'on')
                    axis(app.Axes3D,'equal')
                    app.Axes3D.XColor = 'w';
                    app.Axes3D.YColor = 'w';
                    app.Axes3D.ZColor = 'w';
                    app.Axes3D.XLim = [app.main_app.xvec(1) app.main_app.xvec(end)];
                    app.Axes3D.YLim = [app.main_app.yvec(1) app.main_app.yvec(end)];
                    app.Axes3D.ZLim = [app.main_app.zvec(1) app.main_app.zvec(end)];
                    app.Axes3D.CLim = app.main_app.clim;
                    app.Axes3D.ZLabel.String = 'Z';
                    [~,a,b] = fileparts(app.main_app.img_filename);
                    app.Axes3D.Title.String = [a,b];
                    set(app.Axes3D.Title,'Interpreter','None')
                    colormap(app.Axes3D,gray(2000))
                    
                    app.Axes3D.Interactions = [rotateInteraction zoomInteraction];
                    disableDefaultInteractivity(app.Axes3D)          
                   
                    % Create plot objects for three orthogonal slices. Show
                    % the middle slices by default.    
                    app.x_slice_nr = round(length(app.main_app.xvec)/2);
                    app.y_slice_nr = round(length(app.main_app.yvec)/2);
                    app.z_slice_nr = round(length(app.main_app.zvec)/2);
                    app.hs = slice(app.Axes3D,...
                        app.main_app.xvec,...
                        app.main_app.yvec,...
                        app.main_app.zvec,...
                        double(permute(app.main_app.img,[2 1 3])),...
                        app.main_app.xvec(app.x_slice_nr),...
                        app.main_app.yvec(app.y_slice_nr),...
                        app.main_app.zvec(app.z_slice_nr));
                    set(app.hs,...
                        'EdgeColor','none',...
                        'FaceAlpha',app.AlphaSlider.Value)
                    
                    set(app.hs(1),'Tag','x','Visible','on') 
                    set(app.hs(2),'Tag','y','Visible','on')
                    set(app.hs(3),'Tag','z','Visible','on')
                    
                    % Create plot objects for all labels                    
                    app.ho = zeros(length(app.main_app.LabelNames));
                    for label_nr = 1 : length(app.main_app.LabelNames)                        
                        app.ho(label_nr) = plot3(app.Axes3D,...
                            NaN,NaN,NaN,...
                            'LineWidth',2,...
                            'Color',app.main_app.colors(label_nr,:));                     
                    end                    
                    updatePlot(app,'objects')
                    
                    % Set slider limits
                    app.z_slider.Limits = [1 app.main_app.metadata.ImageSize(3)];
                    app.z_slider.Value  = app.z_slice_nr;
                    app.z_label.Text    = int2str(app.z_slice_nr);
                    app.y_slider.Limits = [1 app.main_app.metadata.ImageSize(2)];
                    app.y_slider.Value  = app.y_slice_nr;
                    app.y_label.Text    = int2str(app.y_slice_nr);
                    app.x_slider.Limits = [1 app.main_app.metadata.ImageSize(1)];
                    app.x_slider.Value  = app.x_slice_nr;
                    app.x_label.Text    = int2str(app.x_slice_nr);
                    
                    app.Axes3D.Visible = 'on';
                    
                    % Update label table
                    app.LabelTable.ColumnFormat = {'char','logical'};
                    app.LabelTable.Data = [app.main_app.LabelNames num2cell(true(length(app.main_app.LabelNames),1))];
                    app.LabelTable.BackgroundColor = app.main_app.colors;
                    app.LabelTable.RowName = 'numbered';
%                     app.LabelTable.Data(:,2) = true(length(app.main_app.LabelNames),1);
                    
                case 'objects'
                    % Update display of objects.
                    app.PlotData3D = getPlotData3D(app.main_app.ObjectData,...
                        length(app.main_app.LabelNames),...
                        app.main_app.zvec);
                    for label_nr = 1 : length(app.main_app.LabelNames)
                        if isempty(app.PlotData3D{label_nr})
                            set(app.ho(label_nr),...
                                'XData',NaN,...
                                'YData',NaN,...
                                'ZData',NaN);
                        else
                            set(app.ho(label_nr),...
                                'XData',app.PlotData3D{label_nr}(:,1),...
                                'YData',app.PlotData3D{label_nr}(:,2),...
                                'ZData',app.PlotData3D{label_nr}(:,3));
                        end
                    end
                    
                    app.LabelTable.Data = [app.main_app.LabelNames num2cell(true(length(app.main_app.LabelNames),1))];
                    app.LabelTable.BackgroundColor = app.main_app.colors;
                case {'x','y','z'}
                    switch action
                        case 'x'
                            app.x_slice_nr = round(app.x_slider.Value);
                            app.x_slider.Value = app.x_slice_nr; % Round to nearest integer.
                            app.x_label.Text    = int2str(app.x_slice_nr);
                            XData = ones(app.main_app.metadata.ImageSize([1 3])) * app.main_app.xvec(app.x_slice_nr);
                            CData = squeeze(app.main_app.img(:,app.x_slice_nr,:));
                            set(findobj(app.hs,'Tag','x'),...
                                'CData',CData,...
                                'XData',XData);
                        case 'y'
                            app.y_slice_nr = round(app.y_slider.Value);
                            app.y_slider.Value = app.y_slice_nr; % Round to nearest integer.
                            app.y_label.Text    = int2str(app.y_slice_nr);
                            YData = ones(app.main_app.metadata.ImageSize([2 3])) * app.main_app.yvec(app.y_slice_nr);
                            CData = squeeze(app.main_app.img(app.y_slice_nr,:,:));
                            set(findobj(app.hs,'Tag','y'),...
                                'CData',CData,...
                                'YData',YData);
                        case 'z'
                            app.z_slice_nr = round(app.z_slider.Value);
                            app.z_slider.Value = app.z_slice_nr; % Round to nearest integer.
                            app.z_label.Text    = int2str(app.z_slice_nr);
                            ZData = ones(app.main_app.metadata.ImageSize([1 2])) * app.main_app.zvec(app.z_slice_nr);
                            CData = squeeze(app.main_app.img(:,:,app.z_slice_nr));
                            set(findobj(app.hs,'Tag','z'),...
                                'CData',CData,...
                                'ZData',ZData);

                    end
                    case {'xvis','yvis','zvis'}
                        switch action
                            case 'xvis'
                                if app.x_check.Value == true
                                    set(findobj(app.hs,'Tag','x'),'Visible','on')
                                else                                    
                                    set(findobj(app.hs,'Tag','x'),'Visible','off')
                                end
                            case 'yvis'
                                if app.y_check.Value == true
                                    set(findobj(app.hs,'Tag','y'),'Visible','on')
                                else                                    
                                    set(findobj(app.hs,'Tag','y'),'Visible','off')
                                end
                            case 'zvis'
                                if app.z_check.Value == true
                                    set(findobj(app.hs,'Tag','z'),'Visible','on')
                                else                                    
                                    set(findobj(app.hs,'Tag','z'),'Visible','off')
                                end
                        end
                case 'alpha' % change tranparency of slices
                    set(app.hs,'FaceAlpha',app.AlphaSlider.Value)
                case 'objectvis'                    
                    label_nr = event.Indices(1);
                    if event.NewData == 0
                        set(app.ho(label_nr),'visible','off')
                    else
                        set(app.ho(label_nr),'visible','on')
                    end
            end % of switch
            drawnow
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SASHIMI3DViewerUIFigure = uifigure('Visible', 'off');
            app.SASHIMI3DViewerUIFigure.Name = 'SASHIMI 3D Viewer';
            app.Axes3D = uiaxes(app.SASHIMI3DViewerUIFigure);
            title(app.Axes3D, 'Title')
            xlabel(app.Axes3D, 'X')
            ylabel(app.Axes3D, 'Y')
            app.SliceviewsPanel = uipanel(app.SASHIMI3DViewerUIFigure);
            app.SliceviewsPanel.Title = 'Slice views';
            app.x_slider = uislider(app.SliceviewsPanel);
            app.x_slider.MajorTicks = [];
            app.x_slider.ValueChangedFcn = createCallbackFcn(app, @updatePlot, true);
            app.x_slider.MinorTicks = [];
            app.x_check = uicheckbox(app.SliceviewsPanel);
            app.x_check.ValueChangedFcn = createCallbackFcn(app, @updatePlot, true);
            app.x_check.Text = 'x';
            app.x_check.Value = true;
            app.y_slider = uislider(app.SliceviewsPanel);
            app.y_slider.MajorTicks = [];
            app.y_slider.ValueChangedFcn = createCallbackFcn(app, @updatePlot, true);
            app.y_slider.MinorTicks = [];
            app.y_check = uicheckbox(app.SliceviewsPanel);
            app.y_check.ValueChangedFcn = createCallbackFcn(app, @updatePlot, true);
            app.y_check.Text = 'y';
            app.y_check.Value = true;
            app.z_slider = uislider(app.SliceviewsPanel);
            app.z_slider.MajorTicks = [];
            app.z_slider.ValueChangedFcn = createCallbackFcn(app, @updatePlot, true);
            app.z_slider.MinorTicks = [];
            app.z_check = uicheckbox(app.SliceviewsPanel);
            app.z_check.ValueChangedFcn = createCallbackFcn(app, @updatePlot, true);
            app.z_check.Text = 'z';
            app.z_check.Value = true;
            app.z_label = uilabel(app.SliceviewsPanel);
            app.z_label.Text = '#';
            app.y_label = uilabel(app.SliceviewsPanel);
            app.y_label.Text = '#';
            app.x_label = uilabel(app.SliceviewsPanel);
            app.x_label.Text = '#';
            app.AlphaSliderLabel = uilabel(app.SliceviewsPanel);
            app.AlphaSliderLabel.Text = 'Alpha';
            app.AlphaSlider = uislider(app.SliceviewsPanel);
            app.AlphaSlider.Limits = [0 1];
            app.AlphaSlider.MajorTicks = [];
            app.AlphaSlider.ValueChangedFcn = createCallbackFcn(app, @updatePlot, true);
            app.AlphaSlider.MinorTicks = [];
            app.AlphaSlider.Value = 0.5;
            app.LabelTable = uitable(app.SASHIMI3DViewerUIFigure);
            app.LabelTable.ColumnName = {'Label name'; 'Visible'};
            app.LabelTable.RowName = {'#'};
            app.LabelTable.ColumnEditable = [false true];
            app.LabelTable.CellEditCallback = createCallbackFcn(app, @updatePlot, true);
            app.UpdateobjectsfromSASHIMIButton = uibutton(app.SASHIMI3DViewerUIFigure, 'push');
            app.UpdateobjectsfromSASHIMIButton.ButtonPushedFcn = createCallbackFcn(app, @updatePlot, true);
            app.UpdateobjectsfromSASHIMIButton.Text = 'Update objects from SASHIMI';
            % Show the figure after all components are created
            app.SASHIMI3DViewerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Viewer3D(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SASHIMI3DViewerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SASHIMI3DViewerUIFigure)
        end
    end
end