classdef MuscleSegmenter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MuscleSegmenterv20UIFigure  matlab.ui.Figure
        FileMenu                    matlab.ui.container.Menu
        LoadimageMenu               matlab.ui.container.Menu
        SaveMenu                    matlab.ui.container.Menu
        SaveasMenu                  matlab.ui.container.Menu
        ExportmaskMenu              matlab.ui.container.Menu
        UIAxes                      matlab.ui.control.UIAxes
        SliceLabel                  matlab.ui.control.Label
        SliceSlider                 matlab.ui.control.Slider
        ObjectsPanel                matlab.ui.container.Panel
        AddobjectButton             matlab.ui.control.Button
        TracksettingsPanel          matlab.ui.container.Panel
        SkipslicesSpinnerLabel      matlab.ui.control.Label
        SkipslicesSpinner           matlab.ui.control.Spinner
        SmoothingSpinnerLabel       matlab.ui.control.Label
        SmoothingSpinner            matlab.ui.control.Spinner
        PyramidlevelsSpinnerLabel   matlab.ui.control.Label
        PyramidlevelsSpinner        matlab.ui.control.Spinner
        IterationsSpinnerLabel      matlab.ui.control.Label
        IterationsSpinner           matlab.ui.control.Spinner
        ButtonGroup                 matlab.ui.container.ButtonGroup
        UpButton                    matlab.ui.control.RadioButton
        DownButton                  matlab.ui.control.RadioButton
        DirectionLabel              matlab.ui.control.Label
        TrackallobjectsButton       matlab.ui.control.StateButton
        StatusLabel                 matlab.ui.control.Label
        Button                      matlab.ui.control.Button
        Button_2                    matlab.ui.control.Button
        Button_3                    matlab.ui.control.Button
        Button_6                    matlab.ui.control.Button
        Button_4                    matlab.ui.control.Button
        Button_5                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        img  % image data
        spacing % pixel spacing
        dim     % image dimensions
        poly % polygon structure
        slice_nr % number of currently displayed slice
        stack_nr % number of currently displayed stack (for multi-channel data)
        
    end
    
    methods (Access = private)
        
        function load_data(app,filename)
            
            % Update status bar
            app.StatusLabel.Text = 'Loading image...';
            I = load_untouch_nii(filename);
            app.img = I.img;
            app.dim = I.hdr.dime.dim(2:4);
            app.spacing = I.hdr.dime.pixdim(2:4);
            app.StatusLabel.Text = 'Waiting for user input';
            
            % Update slice slider
            app.SliceSlider.Limits     = [1 app.dim(3)];
            majorTicks                 = round(linspace(0,app.dim(3),11));
            majorTicks(1)              = 1;
            app.SliceSlider.MajorTicks = majorTicks;
            app.SliceSlider.MinorTicks = majorTicks;
            app.slice_nr               = round(app.dim(3)/2);
            app.SliceSlider.Value      = app.slice_nr;
            
            % Initialise the axes
            initialise_axes(app)
                
        end
        
        function initialise_axes(app)
%             axes(app.UIAxes)
            imshow(app.img(:,:,app.slice_nr),[],...
                'Parent',app.UIAxes)
            app.UIAxes.XLim = [1 app.dim(2)];
            app.UIAxes.YLim = [1 app.dim(1)];
%             zoom(app.UIAxes,'on')
%             pan(app.UIAxes,'on');          % or 'off'
%             zoom(app.UIAxes,'on');         % or 'off'
            
            
        end
    end
    

    methods (Access = private)

        % Menu selected function: LoadimageMenu
        function LoadimageMenuSelected(app, event)
            [filename,pathname] = uigetfile({'*.nii;*.nii.gz;',...
                'Image files (*.nii,*.nii.gz)'},...
                'Select an image file',[],'MultiSelect','off');
            if pathname == 0
                return
            else
                load_data(app,fullfile(pathname,filename));
            end
        end

        % Button pushed function: Button_2
        function zoom_in(app, event)
            zoom(app.UIAxes,1.5)
        end

        % Button pushed function: Button
        function zoom_out(app, event)
            zoom(app.UIAxes,1/1.5)
        end

        % Button pushed function: Button_3, Button_4, Button_5, 
        % Button_6
        function pan_fun(app, event)
            pan(app.UIAxes,'on')
            xlim = app.UIAxes.XLim;
            ylim = app.UIAxes.YLim;
            switch char(get(event.Source,'Tooltip'))
                case 'pan left'
                    app.UIAxes.XLim = xlim + 0.1*range(xlim);
                case 'pan right'
                    app.UIAxes.XLim = xlim - 0.1*range(xlim);                    
                case 'pan up'
                    app.UIAxes.YLim = ylim + 0.1*range(ylim);                    
                case 'pan down'
                    app.UIAxes.YLim = ylim - 0.1*range(ylim);  
            end                 
            
        end

        % Button pushed function: AddobjectButton
        function addObject(app, event)
            app.poly = drawpolygon(...
                'Color','b',...
                'FaceAlpha',0.2,...
                'Parent',app.UIAxes);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MuscleSegmenterv20UIFigure = uifigure;
            app.MuscleSegmenterv20UIFigure.Name = 'Muscle Segmenter v2.0';
            app.FileMenu = uimenu(app.MuscleSegmenterv20UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadimageMenu = uimenu(app.FileMenu);
            app.LoadimageMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadimageMenuSelected, true);
            app.LoadimageMenu.Text = 'Load image...';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.Enable = 'off';
            app.SaveMenu.Text = 'Save';
            app.SaveasMenu = uimenu(app.FileMenu);
            app.SaveasMenu.Text = 'Save as...';
            app.ExportmaskMenu = uimenu(app.FileMenu);
            app.ExportmaskMenu.Text = 'Export mask...';
            app.UIAxes = uiaxes(app.MuscleSegmenterv20UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.SliceLabel = uilabel(app.MuscleSegmenterv20UIFigure);
            app.SliceLabel.Text = 'Slice';
            app.SliceSlider = uislider(app.MuscleSegmenterv20UIFigure);
            app.SliceSlider.Limits = [1 10];
            app.SliceSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.SliceSlider.MinorTicks = [1 1.1 1.2 1.3 1.4 1.5 1.6 1.7 1.8 1.9 2 2.1 2.2 2.3 2.4 2.5 2.6 2.7 2.8 2.9 3 3.1 3.2 3.3 3.4 3.5 3.6 3.7 3.8 3.9 4 4.1 4.2 4.3 4.4 4.5 4.6 4.7 4.8 4.9 5 5.1 5.2 5.3 5.4 5.5 5.6 5.7 5.8 5.9 6 6.1 6.2 6.3 6.4 6.5 6.6 6.7 6.8 6.9 7 7.1 7.2 7.3 7.4 7.5 7.6 7.7 7.8 7.9 8 8.1 8.2 8.3 8.4 8.5 8.6 8.7 8.8 8.9 9 9.1 9.2 9.3 9.4 9.5 9.6 9.7 9.8 9.9 10];
            app.SliceSlider.Value = 1;
            app.ObjectsPanel = uipanel(app.MuscleSegmenterv20UIFigure);
            app.ObjectsPanel.Title = 'Objects';
            app.AddobjectButton = uibutton(app.ObjectsPanel, 'push');
            app.AddobjectButton.ButtonPushedFcn = createCallbackFcn(app, @addObject, true);
            app.AddobjectButton.Text = 'Add object...';
            app.TracksettingsPanel = uipanel(app.MuscleSegmenterv20UIFigure);
            app.TracksettingsPanel.Title = 'Track settings';
            app.SkipslicesSpinnerLabel = uilabel(app.TracksettingsPanel);
            app.SkipslicesSpinnerLabel.Text = 'Skip slices';
            app.SkipslicesSpinner = uispinner(app.TracksettingsPanel);
            app.SkipslicesSpinner.Limits = [1 Inf];
            app.SkipslicesSpinner.ValueDisplayFormat = '%d';
            app.SkipslicesSpinner.Tooltip = {'Select how many slices to skip.'};
            app.SkipslicesSpinner.Value = 1;
            app.SmoothingSpinnerLabel = uilabel(app.TracksettingsPanel);
            app.SmoothingSpinnerLabel.Text = 'Smoothing';
            app.SmoothingSpinner = uispinner(app.TracksettingsPanel);
            app.SmoothingSpinner.Step = 0.5;
            app.SmoothingSpinner.Limits = [0.5 3];
            app.SmoothingSpinner.ValueDisplayFormat = '%3.1f';
            app.SmoothingSpinner.Tooltip = {'This parameter controls the amount of diffusion-like regularization.imregdemons applies the standard deviation of the Gaussian smoothing to regularize the accumulated field at each iteration. Larger values result in smoother output displacement fields. Smaller values result in more localized deformation in the output displacement field. Values typically are in the range [0.5'; ' 3.0]. When you specify multiple PyramidLevels'; ' the standard deviation used in the Gaussian smoothing remains the same at each pyramid level.'};
            app.SmoothingSpinner.Value = 1.5;
            app.PyramidlevelsSpinnerLabel = uilabel(app.TracksettingsPanel);
            app.PyramidlevelsSpinnerLabel.Text = 'Pyramid levels';
            app.PyramidlevelsSpinner = uispinner(app.TracksettingsPanel);
            app.PyramidlevelsSpinner.Limits = [1 3];
            app.PyramidlevelsSpinner.ValueDisplayFormat = '%d';
            app.PyramidlevelsSpinner.Tooltip = {'Number of multi-resolution image pyramid levels to use.'};
            app.PyramidlevelsSpinner.Value = 1;
            app.IterationsSpinnerLabel = uilabel(app.TracksettingsPanel);
            app.IterationsSpinnerLabel.Text = 'Iterations';
            app.IterationsSpinner = uispinner(app.TracksettingsPanel);
            app.IterationsSpinner.Step = 50;
            app.IterationsSpinner.Limits = [50 500];
            app.IterationsSpinner.ValueDisplayFormat = '%3d';
            app.IterationsSpinner.Tooltip = {'Number of iterations per resolution level.'};
            app.IterationsSpinner.Value = 200;
            app.ButtonGroup = uibuttongroup(app.TracksettingsPanel);
            app.UpButton = uiradiobutton(app.ButtonGroup);
            app.UpButton.Text = 'Up';
            app.UpButton.Value = true;
            app.DownButton = uiradiobutton(app.ButtonGroup);
            app.DownButton.Text = 'Down';
            app.DirectionLabel = uilabel(app.TracksettingsPanel);
            app.DirectionLabel.Text = 'Direction';
            app.TrackallobjectsButton = uibutton(app.TracksettingsPanel, 'state');
            app.TrackallobjectsButton.Text = 'Track all objects';
            app.StatusLabel = uilabel(app.MuscleSegmenterv20UIFigure);
            app.StatusLabel.Text = 'Status';
            app.Button = uibutton(app.MuscleSegmenterv20UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @zoom_out, true);
            app.Button.Icon = 'minus.png';
            app.Button.Tooltip = {'zoom out'};
            app.Button.Text = '';
            app.Button_2 = uibutton(app.MuscleSegmenterv20UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @zoom_in, true);
            app.Button_2.Icon = 'plus.png';
            app.Button_2.Tooltip = {'zoom in'};
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.MuscleSegmenterv20UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @pan_fun, true);
            app.Button_3.Icon = 'arrow_left.png';
            app.Button_3.Tooltip = {'pan left'};
            app.Button_3.Text = '<';
            app.Button_6 = uibutton(app.MuscleSegmenterv20UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @pan_fun, true);
            app.Button_6.Icon = 'arrow_down.png';
            app.Button_6.Tooltip = {'pan down'};
            app.Button_6.Text = '';
            app.Button_4 = uibutton(app.MuscleSegmenterv20UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @pan_fun, true);
            app.Button_4.Icon = 'arrow_right.png';
            app.Button_4.Tooltip = {'pan right'};
            app.Button_4.Text = '';
            app.Button_5 = uibutton(app.MuscleSegmenterv20UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @pan_fun, true);
            app.Button_5.Icon = 'arrow_up.png';
            app.Button_5.Tooltip = {'pan up'};
            app.Button_5.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = MuscleSegmenter

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MuscleSegmenterv20UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MuscleSegmenterv20UIFigure)
        end
    end
end