classdef MuscleSegmenter_dev4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        mainFigure                 matlab.ui.Figure
        FileMenu                   matlab.ui.container.Menu
        loadImageMenu              matlab.ui.container.Menu
        loadObjectMenu             matlab.ui.container.Menu
        saveObjectMenu             matlab.ui.container.Menu
        saveObjectAsMenu           matlab.ui.container.Menu
        exportMaskMenu             matlab.ui.container.Menu
        SliceLabel                 matlab.ui.control.Label
        SliceSlider                matlab.ui.control.Slider
        ObjectsPanel               matlab.ui.container.Panel
        DrawButton                 matlab.ui.control.Button
        ObjectTable                matlab.ui.control.Table
        RemoveButton               matlab.ui.control.Button
        TrackingPanel              matlab.ui.container.Panel
        GapSpinnerLabel            matlab.ui.control.Label
        GapSpinner                 matlab.ui.control.Spinner
        SmoothingSpinnerLabel      matlab.ui.control.Label
        SmoothingSpinner           matlab.ui.control.Spinner
        PyramidlevelsSpinnerLabel  matlab.ui.control.Label
        PyramidlevelsSpinner       matlab.ui.control.Spinner
        IterationsSpinnerLabel     matlab.ui.control.Label
        IterationsSpinner          matlab.ui.control.Spinner
        UpdateButton               matlab.ui.control.Button
        TrackButton                matlab.ui.control.Button
        StatusLabel                matlab.ui.control.Label
        imagePanel                 matlab.ui.container.Panel
        slice_up                   matlab.ui.control.Button
        slice_down                 matlab.ui.control.Button
        StatusLight                matlab.ui.control.Lamp
    end

    
    properties (Access = private)
        img           % image data
        spacing       % pixel spacing
        dim           % image dimensions
        maxObjects    % maximum number objects
        currentLabel_nr % number of the selected object in the UITable
        objectPos     % cell with position of nodes of polygon objects for all slice and objects
        slice_nr      % number of currently displayed slice
        stack_nr      % number of currently displayed stack (for multi-channel data)
        colors        % color map
        labelNames    % list of label numbers and names
        h_img         % image object
        h_mask        % mask object
        mask          % mask array
        h_poly        % polygon graphics objects
        h_spline      % spline plot objects
        slice_nr_ref  % number of reference slice
        slice_nr_pred % number of predicted slice
        prev_gap      % previous gap value - used to exclude 0 gap
        objectFilename  % filename of object structure
        hdr           % image NIfTI header
        showLabelName % flag (true/false) to show/hide label names
        polyLineWidth % line width of polygon objects
        polyStripeColor % stripe color of polygon objects
        polyFaceAlpha % alpha-transparency value for polygon objects
        splineLineWidth % line width of spline
        splineLineStyle % line style of spline objects
        
    end
    
    properties (Access = public)
        h_ax      matlab.graphics.axis.Axes 
    end
    
    methods (Access = private)
        
        function loadImage(app,filename)
            
            % Update status bar
            app.StatusLabel.Text = 'Loading image...';
            app.StatusLight.Color = [1 0 0];
            
            % Load the data
            I = load_untouch_nii(filename);
            app.hdr = I.hdr;
            app.img = I.img;
            app.dim = I.hdr.dime.dim(2:4);
            app.spacing = I.hdr.dime.pixdim(2:4);
            
            [~,b,c] = fileparts(filename);
            app.StatusLabel.Text = sprintf('%s loaded',[b,c]);
            app.StatusLight.Color = [0 1 0];
            
            % Update slice slider
            app.SliceSlider.Limits     = [1 app.dim(3)];
            majorTicks                 = round(linspace(0,app.dim(3),11));
            majorTicks(1)              = 1;
            app.SliceSlider.MajorTicks = majorTicks;
            app.SliceSlider.MinorTicks = [];
            app.slice_nr               = round(app.dim(3)/2);
            
            % Update image filename
            [~,a,b] = fileparts(filename);
            app.imagePanel.Title = [a b];
            
            % Create empty object cell.
            app.objectPos = cell2struct(cell(2,app.dim(3)), {'position','label_nr'}, 1);
            
            % Reset the object table.
            resetTable(app)
            
            % Delete all graphics objects from the current figure.
            delete(findobj(app.h_ax,'Type','images.roi.polygon'))
            app.h_poly         = [];
            app.h_spline       =[];
            app.objectFilename = [];
            
            % Enable objects
            app.slice_up.Enable     = 'on';
            app.slice_down.Enable   = 'on'; 
            app.SliceSlider.Enable  = 'on'; 
            app.DrawButton.Enable    = 'on'; 
            app.RemoveButton.Enable = 'on';
            app.TrackButton.Enable  = 'on';
            app.loadImageMenu.Enable = 'on';
            app.loadObjectMenu.Enable = 'on';
            app.saveObjectAsMenu.Enable = 'on';
            app.saveObjectMenu.Enable = 'off';
            app.exportMaskMenu.Enable = 'on';
            
            % Display the middle slice and update the axes properties
            app.slice_nr = round(app.dim(3)/2);
            
            % Set XData and YData for the current image
            set(app.h_img,...
                'XData',[0 (app.dim(2)-1)*app.spacing(1)],...
                'YData',[0 (app.dim(1)-1)*app.spacing(2)],...
                'CData',zeros(app.dim(1:2)))
            
            % Set properties for the mask
            % Create empty RGB mask objects
            app.mask = cat(3,zeros(app.dim(1:2)),zeros(app.dim(1:2)),zeros(app.dim(1:2)));
            set(app.h_mask,...
                'XData',[0 (app.dim(2)-1)*app.spacing(1)],...
                'YData',[0 (app.dim(1)-1)*app.spacing(2)],...
                'CData',app.mask,...
                'Visible','off');
%             app.h_ax.XLimMode = 'auto';
%             app.h_ax.YLimMode = 'auto';
            
            app.h_ax.XLim = [0 (app.dim(2)-1)*app.spacing(1)];
            app.h_ax.YLim = [0 (app.dim(1)-1)*app.spacing(2)];
                        
            app.h_ax.Position = [0 0 1 1];
%             app.h_ax.OuterPosition = [0 0 1 1];
            axis(app.h_ax,'equal')
            
            % Update the image display
            showSlice(app)
            
            
            % Disable the update button
            app.UpdateButton.Enable = 'off';               
        end
              
        function addAxes(app)
            % Create classic Axes object into the Panel
            app.h_ax = axes(app.imagePanel,...
                'Position',[0,0,1,1]);
        end
        
        function updateObject(app,src,event)
            
            % Get the number of the graphics object which is stored in the UserData property.
            obj_nr = get(src,'UserData');
            switch event.EventName
                case 'ROIClicked'
                    if strcmp(event.SelectionType,'double')
                        % Point was inserted. Update the position.
                        pos = get(src,'Position');
                    else
                        return
                    end
                case {'MovingROI','ROIMoved'}
                    % Update the cell with object position data of the current polygon graphics 
                    % objects so that interactive changes are saved.
%                     obj_nr = cellfun(@(x) isequal(x,event.PreviousPosition),app.objectPos(app.slice_nr).position);
                    pos = event.CurrentPosition;
            end
            
            % Update objectPos with the new position data.
            app.objectPos(app.slice_nr).position{obj_nr} = pos;
            
            % Update the spline curve.
            SP = fit_closed_curve([pos;pos(1,:)]);
            set(app.h_spline,'XData',SP(:,1),'YData',SP(:,2))
        end
        
        function showSlice(app)
            
            % Update image.
            slice_data = app.img(:,:,app.slice_nr);
            set(app.h_img,...
                'CData',slice_data)
            
            % Automatically set the contrast to the data range
            set(app.h_ax,...
                'CLim',[min(slice_data(:)) max(slice_data(:))])
            
            % Update the polygon graphics objects
            % ... first, delete all existing graphics objects.
            delete(findobj(app.h_ax,'Type','images.roi.polygon'))
            delete(findobj(app.h_ax,'Type','line'))
%             delete(app.h_spline);
            app.h_poly   = [];
            app.h_spline = [];
            for obj_nr = 1 : length(app.objectPos(app.slice_nr).position)
                    % Create an interactive polygon.
                    pos = app.objectPos(app.slice_nr).position{obj_nr};
                    label_nr = app.objectPos(app.slice_nr).label_nr(obj_nr);                    
                    if app.showLabelName == true
                        lbl = app.labelNames{label_nr,2};
                    else
                        lbl = '';
                    end
                    app.h_poly(obj_nr) = images.roi.Polygon('Parent',app.h_ax);
                    set(app.h_poly(obj_nr),...
                        'Label',lbl,...
                        'Color',app.colors(label_nr,:),...
                        'FaceAlpha',app.polyFaceAlpha,...
                        'Position',pos,...
                        'InteractionsAllowed','all',...
                        'UserData',obj_nr,...
                        'LineWidth',app.polyLineWidth,...
                        'StripeColor',app.polyStripeColor);
                    
                    addlistener(app.h_poly(obj_nr), 'MovingROI', @(src,event) updateObject(app,src,event));
                    addlistener(app.h_poly(obj_nr), 'ROIClicked', @(src,event) updateObject(app,src,event));
                     SP = fit_closed_curve([pos;pos(1,:)]);
                     app.h_spline(obj_nr) = plot(app.h_ax,...
                         SP(:,1),SP(:,2),...
                         'LineWidth',app.splineLineWidth,...
                         'Color',app.colors(label_nr,:));
            end
            
            % If no objects are available on the current slice, disable the track button.
            if isempty(app.h_poly)
                app.TrackButton.Enable = 'off';
            else
                app.TrackButton.Enable = 'on';
            end
            
            % Disable the update button.
            app.UpdateButton.Enable = 'off';
            
            % Update slider
            app.SliceSlider.Value = app.slice_nr;
            app.SliceLabel.Text   = sprintf('%d/%d',app.slice_nr,app.dim(3));   
        end
        
        function toggleLabelName(app)
                if app.showLabelName == false
                    app.showLabelName = true;
                else
                    app.showLabelName = false;
                end
                for obj_nr = 1 : length(app.h_poly)
                    if app.showLabelName == true
                        lbl = app.labelNames{app.objectPos(app.slice_nr).label_nr(obj_nr),2};
                        app.StatusLabel.Text = 'Label names shown';
                    else
                        lbl = '';
                        app.StatusLabel.Text = 'Label names hidden';
                    end
                    set(app.h_poly(obj_nr),'Label',lbl)
                end
            
        end
        
        function deletePoint(app)
                % Delete the point closest to the last clicked location.
                p = get(app.h_ax,'CurrentPoint');
                
                % If the last clicked location is more than 4 pixels removed from the nearest point,
                % no point is deleted.
                mindist = 4*max(app.spacing(1:2));
                min_idx = [];
                min_obj_nr = [];
                for obj_nr = 1 : length(app.h_poly)
                    pos = get(app.h_poly(obj_nr),'Position');
                    [d,idx] = min(sqrt(sum( (pos-p(1,1:2)).^2,2)));
                    if d < mindist
                        mindist = d;
                        min_idx = idx;
                        min_obj_nr = obj_nr;
                    end
                end
                if ~isempty(min_idx)
                    pos = get(app.h_poly(min_obj_nr),'Position');
                    pos(min_idx,:) = [];
                    if size(pos,1) < 3
                        % Delete the object if fewer than 3 points are left in the polygon.
                        delete(app.h_poly(min_obj_nr))
                        app.h_poly(min_obj_nr) = [];
                        app.objectPos(app.slice_nr).position{min_obj_nr} = [];
                        app.objectPos(app.slice_nr).label_nr(min_obj_nr) = [];
                        app.StatusLabel.Text = 'Object removed because fewer than 3 points were left.';
                    else
                        % Update objectPos and the graphics object
                        set(app.h_poly(min_obj_nr),...
                            'Position',pos)
                        C = fit_closed_curve([pos;pos(1,:)]);
                        set(app.h_spline(min_obj_nr),...
                            'XData',C(:,1),...
                            'YData',C(:,2));
                        
                        app.objectPos(app.slice_nr).position{min_obj_nr} = pos;
                        app.StatusLabel.Text = 'One point deleted.';
                    end
                end            
            
        end
        
        function results = resetTable(app)
            % Resets the table.
            app.maxObjects = 12;
            app.labelNames = cell(app.maxObjects,2);
            for obj_nr = 1 : app.maxObjects
                app.labelNames{obj_nr ,1} = sprintf('%d',obj_nr);
                app.labelNames{obj_nr,2} = sprintf('Label%02d',obj_nr);
            end
            app.ObjectTable.Data = app.labelNames;
            app.colors = [...                
                141,211,199;
                255,255,179;
                190,186,218;
                251,128,114;
                128,177,211;
                253,180,98;
                179,222,105;
                252,205,229;
                217,217,217;
                188,128,189;
                204,235,197;
                255,237,111] / 255;
            
            app.ObjectTable.BackgroundColor = app.colors(1:app.maxObjects,:);            
        end
        
        
        function showMask(app)
            % Shows a preview of the mask.
            switch get(app.h_mask,'Visible')
                case 'on'
                    set(app.h_mask,'Visible','off')
                case 'off'
                    % Create a mask.
                    nObjects = length(app.objectPos(app.slice_nr).position);        
                    slice_label_mask = zeros(app.dim(1),app.dim(2),nObjects);
                    for obj_nr = 1 : nObjects
                        pos      = app.objectPos(app.slice_nr).position{obj_nr};
                        label_nr = app.objectPos(app.slice_nr).label_nr(obj_nr);
                        
                        % Convert polygon position from mri coordinates to pixel coordinates. Then create a binary mask.
                        p_voxel = pos./app.spacing(1:2) + 1;
                        slice_label_mask(:,:,obj_nr) = poly2mask(...
                            p_voxel(:,1),...
                            p_voxel(:,2),...
                            app.dim(1),app.dim(2)) * label_nr;
                        
                    end
                    label_mask = max(slice_label_mask,[],3);
                    
                    % Make an RGB object
                    label_nrs = unique(app.objectPos(app.slice_nr).label_nr);                
                    R = zeros(app.dim(1:2));
                    G = zeros(app.dim(1:2));
                    B = zeros(app.dim(1:2));
                    
                    for label_nr = label_nrs
                        R(label_mask == label_nr) = app.colors(label_nr,1);
                        G(label_mask == label_nr) = app.colors(label_nr,2);
                        B(label_mask == label_nr) = app.colors(label_nr,3);
                    end
                    rgbmask = cat(3,R,G,B);
                    set(app.h_mask,'CData',rgbmask,...
                        'AlphaData',(label_mask ~= 0)*0.2,...
                        'Visible','on')
            end
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                     
            % Prefill table
            resetTable(app)
            app.showLabelName = false;
            
            % Set some tags.
            app.slice_up.Tag     = 'up';
            app.slice_down.Tag   = 'down';
            app.SliceSlider.Tag  = 'slider';
            app.TrackButton.Tag  = 'track';
            app.UpdateButton.Tag = 'update';     
            app.saveObjectAsMenu.Tag   = 'saveas';
            app.saveObjectMenu.Tag     = 'save';
            
            % Add the axes object.
            app.addAxes;
            
%           % Create an image object.
            hold(app.h_ax,'on')
            app.h_img = image(app.h_ax,...
                zeros(2,2),...
                'CDataMapping','scaled');
            
            % Create a mask object.
            app.h_mask = image(app.h_ax,...
                zeros(2,2,3));
            
            % Set the default colormap as grayscale.
            colormap(app.h_ax,gray(2000))
            app.h_ax.CLim = [0 1];
            axis(app.h_ax,'off') % hide the x- and y-axis
            hold(app.h_ax,'on');
            
            % Set previous gap value to the default value.
            app.prev_gap = app.GapSpinner.Value;

            % Set default label number to 1
            app.currentLabel_nr = 1;
            
            % Set default linewidth/linestyle
            app.polyLineWidth   = 1;
            app.polyStripeColor = 'k';
            app.polyFaceAlpha   = 0;
            app.splineLineWidth = 2;
            app.splineLineStyle = '-';
            
%             % Disable objects
%             app.slice_up.Enable     = 'off';
%             app.slice_down.Enable   = 'off'; 
%             app.SliceSlider.Enable  = 'off'; 
%             app.DrawButton.Enable    = 'off'; 
%             app.RemoveButton.Enable = 'off';
%             app.TrackButton.Enable = 'off';
            
        end

        % Menu selected function: loadImageMenu
        function selectImage(app, event)
            [filename,pathname] = uigetfile({'*.nii;*.nii.gz;',...
                'Image files (*.nii,*.nii.gz)'},...
                'Select an image file',[],'MultiSelect','off');
            if pathname == 0
                return
            else
                loadImage(app,fullfile(pathname,filename));
            end
        end

        % Button pushed function: DrawButton
        function drawObject(app, event)
            % Get selected object from the labelTable.
            if isempty(app.currentLabel_nr)
                app.StatusLabel.Text = 'No label selected.';
                return
            end
            
            app.StatusLight.Color = [1.00,0.41,0.16];
            app.StatusLabel.Text = 'Select corners. Click enter to close polygon...';
            lbl = '';
            obj_nr = length(app.h_poly) + 1;
            app.h_poly(obj_nr) = drawpolygon(...
                'Color',app.colors(app.currentLabel_nr,:),...
                'FaceAlpha',app.polyFaceAlpha,...
                'Parent',app.h_ax,...
                'Label',lbl,...
                'InteractionsAllowed','all',...
                'UserData',obj_nr,...
                'LineWidth',app.polyLineWidth,...
                'StripeColor',app.polyStripeColor);
            
            if size(get(app.h_poly(end),'Position'),1) < 3
                app.StatusLabel.Text = 'Minimum of 3 points should be selected.';
                delete(app.h_poly(end))
                app.h_poly(end) = [];
                return                    
            end
            % Add listeners for updating objectPos and masks when the objects is changed (moved, point added, point deleted).
            addlistener(app.h_poly(obj_nr), 'MovingROI', @(src,event) updateObject(app,src,event));
            addlistener(app.h_poly(obj_nr), 'ROIClicked', @(src,event) updateObject(app,src,event));
            if app.showLabelName == true
                set(app.h_poly(end),...
                    'Label',app.labelNames{app.currentLabel_nr,2})
            end
            app.StatusLabel.Text = '';
            app.StatusLight.Color = [0 1 0];                
            
            % Add position/label data to objectPos
            pos = get(app.h_poly(end),'Position');
            app.objectPos(app.slice_nr).position{obj_nr} = ...
                pos;
            app.objectPos(app.slice_nr).label_nr(obj_nr) = ...
                app.currentLabel_nr;
            
            % Create and show the spline curve
            C = fit_closed_curve([pos;pos(1,:)]);
            app.h_spline(obj_nr) = plot(...
                app.h_ax,C(:,1),C(:,2),...
                'Color',app.colors(app.currentLabel_nr,:),...
                'LineStyle',app.splineLineStyle,...
                'LineWidth',app.splineLineWidth);
            
            % Enable the track button and disable the update button.
            app.TrackButton.Enable  = 'on';
            app.UpdateButton.Enable = 'off';
        end

        % Cell selection callback: ObjectTable
        function setCurrentObject(app, event)
            % Set selected label to the current object number.
            app.currentLabel_nr = event.Indices(1);
        end

        % Cell edit callback: ObjectTable
        function updateTable(app, event)
            app.labelNames{event.Indices(1),event.Indices(2)} = event.NewData;            
        end

        % Button pushed function: TrackButton, UpdateButton
        function trackObject(app, event)

            % Update the position data for the current slice.
%             updateObject(app)
            
            % Register slices with the selected settings            
            switch event.Source.Tag
                case 'track'
                    % Track new slice. Set the reference and to-be-predicted slice numbers.                   
                    app.slice_nr_ref  = app.slice_nr;
                    app.slice_nr_pred = app.slice_nr + app.GapSpinner.Value;
                case 'update'
                    % Track again with updated settings.
%                     app.slice_nr = app.slice_nr_pred;
            end
            
            if app.slice_nr_pred < 1 || app.slice_nr_pred > app.dim(3)
                warndlg('Tried to predict outside the image range. Change the slice or slice gap and try again',...
                    'Slice outside range')
                return
            end
            
            % Update the status label and set the status light to orange.
            app.StatusLabel.Text = 'Registering images...';
            app.StatusLight.Color = [1.00,0.41,0.16];            
            pause(0.01)
            
            % Perform demons registration
            tic
            [~,~,app.objectPos(app.slice_nr_pred).position] = ...
                register_slices(...
                app.img(:,:,app.slice_nr_pred),app.img(:,:,app.slice_nr_ref),...
                'N',                        app.IterationsSpinner.Value,...
                'PyramidLevels',            app.PyramidlevelsSpinner.Value,...
                'AccumulatedFieldSmoothing',app.SmoothingSpinner.Value,...
                'points',                   app.objectPos(app.slice_nr_ref).position,...
                'spacing',                  app.spacing(1:2));
            app.objectPos(app.slice_nr_pred).label_nr = app.objectPos(app.slice_nr_ref).label_nr;
            t_elapsed = toc;

            % Report back how long it took to register the images and change the status light back to green.
            app.StatusLabel.Text = sprintf('It took %.2f sec to track.',t_elapsed);
            app.StatusLight.Color = [0 1 0];            
            
            % Update the figure.
            app.slice_nr = app.slice_nr_pred;
            showSlice(app)
                        
            % Enable the update button.
            app.UpdateButton.Enable = 'on';
            
        end

        % Callback function: SliceSlider, slice_down, slice_up
        function changeSlice(app, event)
        
            switch event.Source.Tag
                case 'up'
                    % Scroll one slice up
%                     updateObject(app)
                    app.slice_nr = min([app.dim(3),app.slice_nr + 1]);
                    app.UpdateButton.Enable = 'off';
                case 'down'
                    % Scroll one slice down
%                     updateObject(app)
                    app.slice_nr = max([1,app.slice_nr - 1]);
                    app.UpdateButton.Enable = 'off';
                case 'slider'
                    % Show the slider-selected slice
%                     updateObject(app)
                    app.slice_nr = round(app.SliceSlider.Value);
                    app.UpdateButton.Enable = 'off';
                case 'track'
                    % Show the registration-predicted slice
                    app.slice_nr = app.slice_nr_pred;
            end
            
            showSlice(app)
        end

        % Button pushed function: RemoveButton
        function removeObject(app, event)
            % Delete the polygons which contain the last selected point on the screen.
            p = get(app.h_ax,'CurrentPoint');
            tobeRemoved = false(length(app.h_poly),1);
            for obj_nr = 1 : length(app.h_poly)
                pos                 = get(app.h_poly(obj_nr),'Position');
                tobeRemoved(obj_nr) = inpolygon(p(1,1),p(1,2),pos(:,1),pos(:,2));
            end
            if any(tobeRemoved)
                % remove graphics object and update objectPos
                delete(app.h_poly(tobeRemoved))
                delete(app.h_spline(tobeRemoved))
                app.objectPos(app.slice_nr).position(tobeRemoved) = [];
                app.objectPos(app.slice_nr).label_nr(tobeRemoved) = [];
                app.h_poly(tobeRemoved)                           = [];
                app.h_spline(tobeRemoved)                         = [];
                app.StatusLabel.Text = 'Object(s) removed.';
                
                % Update the UserData (object numbers)
                for obj_nr = 1 : length(app.h_poly)
                    set(app.h_poly(obj_nr),'UserData',obj_nr)
                end
            end
        end

        % Size changed function: mainFigure
        function changeSize(app, event)
            position = app.mainFigure.Position;
            minsize = [640 500];
            if position(3) < minsize(1)
                position(3) = minsize(1);                
            end
            if position(4) < minsize(2)
                position(4) = minsize(2);                
            end
            app.mainFigure.Position = position;
            
            % Status light and text
            p = app.StatusLight.Position;
            app.StatusLight.Position = [position(3)-30 p(2:4)];
            p = app.StatusLabel.Position;
            app.StatusLabel.Position = [position(3)-210 p(2:4)];
%             app.StatusLabel.Text = 'Status';
            
            % Object and tracking panel
            % ... lock bottom of tracking panel to bottom of image panel
            p = app.TrackingPanel.Position;
            app.TrackingPanel.Position = [position(3)-205 p(2) p(3:4)];
            
            % ... lock top of object panel to top of image panel
            p = app.ObjectsPanel.Position;
            y = app.TrackingPanel.Position(2)+app.TrackingPanel.Position(4)+5;
            h = position(4) - y - 5;
            app.ObjectsPanel.Position = [position(3)-205 y  p(3) h];
            
            % Image panel
            p = app.imagePanel.Position;
            app.imagePanel.Position = [p(1) p(2) position(3)-220 position(4)-80];
            
            % Slider
            p = app.SliceSlider.Position;
            app.SliceSlider.Position = [p(1:2) position(3)-290 p(4)];
            p = app.slice_up.Position;
            app.slice_up.Position = [position(3)-230 p(2:4)];
            
            % Set axis equal again to mainrain stretch-to-fill behavior
            axis(app.h_ax,'equal')
            
        end

        % Window key press function: mainFigure
        function keyPress(app, event)
            key = event.Key;
            if startsWith(key,'numpad')
                key = key(7:end);
            end
            if strcmpi(key,'t') % track
                if strcmp(app.TrackButton.Enable,'on')
                    event.Source.Tag = 'track';
                    trackObject(app,event)
                end
            elseif strcmpi(key,'u') % update tracking
                if strcmp(app.UpdateButton.Enable,'on')
                    event.Source.Tag = 'update';
                    trackObject(app,event)
                end
            elseif strcmpi(key,'d') % draw object
                if strcmp(app.DrawButton.Enable, 'on')
                    drawObject(app,event)
                end
            elseif strcmpi(key,'r') % remove object
                if strcmp(app.RemoveButton.Enable, 'on')
                    removeObject(app,event)
                end
                %             elseif strcmpi(key,'z') % zoom to region
                %                 r = drawrectangle(app.h_ax,...
                %                     'Color','y',...
                %                     'FaceAlpha',0);
                %                 pos = get(r,'Position');
                %                 set(app.h_ax,...
                %                     'XLim',[pos(1) pos(1)+pos(3)],...
                %                     'YLim',[pos(2) pos(2)+pos(4)])
                %                 delete(r)
                %                 axis(app.h_ax,'equal')
                
            elseif strcmpi(key,'downarrow') % show previous slice
                event.Source.Tag = 'down';
                changeSlice(app,event);
            elseif strcmpi(key,'uparrow') % show next slice
                event.Source.Tag = 'up';
                changeSlice(app,event);
            elseif any(strcmpi(key,{'z','delete'})) % delete points
                % Remove point nearest to the last clicked cursor point.
                deletePoint(app)
            elseif strcmpi(key,'v') % toggle label name visibility
                toggleLabelName(app)                
            elseif strcmp(key,'m') % preview mask
                showMask(app)
            elseif ~isempty(str2double(key)) % numeric input.
                % set selected number to the current label number
                num = real(str2double(key));
                if num ~= 0 &&  ~isnan(num)
                    app.currentLabel_nr = num;
                    app.StatusLabel.Text = sprintf('Label set to %d',num);
                end
            end
        end

        % Value changed function: GapSpinner
        function gapSpinner(app, event)
            % This function makes sure that the value of the gap spinner is never 0.
            value = app.GapSpinner.Value;
            if value == 0
                if app.prev_gap > 0
                    app.GapSpinner.Value = -1;
                    app.prev_gap = -1;
                else
                    app.GapSpinner.Value = 1;
                    app.prev_gap = 1;
                end
            end
                
        end

        % Menu selected function: saveObjectAsMenu, saveObjectMenu
        function saveObject(app, event)
            if strcmp(event.Source.Tag,'saveas') || isempty(app.objectFilename)
                % Select a file or set a new filename.
                [file,path] = uiputfile('*.mat','Select/set a filename to save object to');
                if path == 0
                    return
                else
                    app.objectFilename = fullfile(path,file);
                end
            end
            objectPos = app.objectPos;
            labelNames = app.labelNames;
            save(app.objectFilename,'objectPos','labelNames')
            [~,b,c] = fileparts(app.objectFilename);
            app.StatusLabel.Text = sprintf('Object saved as %s',[b,c]);
            app.saveObjectMenu.Enable = 'on';            
        end

        % Menu selected function: exportMaskMenu
        function exportMask(app, event)
            
            % Select a file or set a new filename
            [file,path] = uiputfile({'*.nii;*.nii.gz','NIfTI image files'},'Select a mask file','mask.nii.gz');
            if file == 0
                return
            end
            
            % Create a mask from the polygon objects.
            mask = zeros(app.dim,'int16');
            for slice_nr = 1 : app.dim(3)
                nObjects = length(app.objectPos(slice_nr).position);
                if nObjects == 0;continue;end
                slice_label_mask = zeros(app.dim(1),app.dim(2),nObjects);
                for obj_nr = 1 : nObjects
                    pos      = app.objectPos(slice_nr).position{obj_nr};
                    label_nr = app.objectPos(slice_nr).label_nr(obj_nr);
%                     if ~isempty(pos)
                    % Convert polygon position from mri coordinates to pixel coordinates. Then create a binary mask.
                    p_voxel = pos./app.spacing(1:2) + 1;
                    slice_label_mask(:,:,obj_nr) = poly2mask(...
                        p_voxel(:,1),...
                        p_voxel(:,2),...
                        app.dim(1),app.dim(2)) * label_nr;
%                     end
                end
                mask(:,:,slice_nr) = max(slice_label_mask,[],3);
            end
            
            % Write mask as NifTI file
            fname_mask = fullfile(path,file);
            if endsWith(fname_mask,'.nii')
                fname_mask = [fname_mask '.gz'];                
            end
            % Update some of the header information.
            M.img = mask;
            M.hdr = app.hdr;
            M.hdr.dime.datatype = 4;
            M.hdr.dime.bitpix = 16;
            M.hdr.dime.glmin = 0;
            M.hdr.dime.glmax = max(mask(:));
            M.hdr.dime.scl_slope = 1;
            M.hdr.dime.scl_inter = 0;
            M.hdr.dime.dim(1) = 3;
            M.hdr.dime.dim(5) = 1; 
            M.untouch = 1;
            save_untouch_nii(M,fname_mask)
            [~,b,c] = fileparts(fname_mask);
            app.StatusLabel.Text = sprintf('Mask exported as %s',[b,c]);
        end

        % Menu selected function: loadObjectMenu
        function loadObject(app, event)
            [file,path] = uigetfile({'*.mat','Matlab MAT-file'},'Select an object file');
            if file == 0
                return
            else
                filedata = load(fullfile(path,file));
                if isfield(filedata,'objectPos')
                    if length(filedata.objectPos) ~= app.dim(3)
                        errordlg(sprintf('Object could not be loaded because the selected object does not match the slice number of the current image.\nThe number of slices should be %d but was %d.',...
                            app.dim(3),length(filedata.objectPos)),...
                            'Wrong object dimensions')
                        return
                    end
                        
                    % Update objectPos and the object table
                    app.objectPos        = filedata.objectPos;
                    app.ObjectTable.Data = filedata.labelNames;
                    app.StatusLabel.Text = sprintf('Object file %s loaded',file);
                    
                    % Update the graphics objects
                    showSlice(app)
                else
                    errordlg('Variable ''objectPos'' not found in the selecte MAT-file. Object data could not be loaded.')
                end
            end
                
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.mainFigure = uifigure;
            app.mainFigure.Name = 'SASHIMI Segmentation - v1.0';
            app.mainFigure.SizeChangedFcn = createCallbackFcn(app, @changeSize, true);
            app.mainFigure.WindowKeyPressFcn = createCallbackFcn(app, @keyPress, true);
            app.FileMenu = uimenu(app.mainFigure);
            app.FileMenu.Text = 'File';
            app.loadImageMenu = uimenu(app.FileMenu);
            app.loadImageMenu.MenuSelectedFcn = createCallbackFcn(app, @selectImage, true);
            app.loadImageMenu.Separator = 'on';
            app.loadImageMenu.Accelerator = 'o';
            app.loadImageMenu.Text = 'Load image...';
            app.loadObjectMenu = uimenu(app.FileMenu);
            app.loadObjectMenu.MenuSelectedFcn = createCallbackFcn(app, @loadObject, true);
            app.loadObjectMenu.Enable = 'off';
            app.loadObjectMenu.Accelerator = 'l';
            app.loadObjectMenu.Text = 'Load object...';
            app.saveObjectMenu = uimenu(app.FileMenu);
            app.saveObjectMenu.MenuSelectedFcn = createCallbackFcn(app, @saveObject, true);
            app.saveObjectMenu.Enable = 'off';
            app.saveObjectMenu.Separator = 'on';
            app.saveObjectMenu.Accelerator = 's';
            app.saveObjectMenu.Text = 'Save object';
            app.saveObjectAsMenu = uimenu(app.FileMenu);
            app.saveObjectAsMenu.MenuSelectedFcn = createCallbackFcn(app, @saveObject, true);
            app.saveObjectAsMenu.Enable = 'off';
            app.saveObjectAsMenu.Text = 'Save object as...';
            app.exportMaskMenu = uimenu(app.FileMenu);
            app.exportMaskMenu.MenuSelectedFcn = createCallbackFcn(app, @exportMask, true);
            app.exportMaskMenu.Enable = 'off';
            app.exportMaskMenu.Separator = 'on';
            app.exportMaskMenu.Accelerator = 'e';
            app.exportMaskMenu.Text = 'Export object as mask...';
            app.SliceLabel = uilabel(app.mainFigure);
            app.SliceLabel.Enable = 'off';
            app.SliceLabel.Text = 'Slice';
            app.SliceSlider = uislider(app.mainFigure);
            app.SliceSlider.Limits = [1 10];
            app.SliceSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.SliceSlider.ValueChangedFcn = createCallbackFcn(app, @changeSlice, true);
            app.SliceSlider.MinorTicks = [1 1.1 1.2 1.3 1.4 1.5 1.6 1.7 1.8 1.9 2 2.1 2.2 2.3 2.4 2.5 2.6 2.7 2.8 2.9 3 3.1 3.2 3.3 3.4 3.5 3.6 3.7 3.8 3.9 4 4.1 4.2 4.3 4.4 4.5 4.6 4.7 4.8 4.9 5 5.1 5.2 5.3 5.4 5.5 5.6 5.7 5.8 5.9 6 6.1 6.2 6.3 6.4 6.5 6.6 6.7 6.8 6.9 7 7.1 7.2 7.3 7.4 7.5 7.6 7.7 7.8 7.9 8 8.1 8.2 8.3 8.4 8.5 8.6 8.7 8.8 8.9 9 9.1 9.2 9.3 9.4 9.5 9.6 9.7 9.8 9.9 10];
            app.SliceSlider.Enable = 'off';
            app.SliceSlider.Value = 1;
            app.ObjectsPanel = uipanel(app.mainFigure);
            app.ObjectsPanel.Title = 'Objects';
            app.DrawButton = uibutton(app.ObjectsPanel, 'push');
            app.DrawButton.ButtonPushedFcn = createCallbackFcn(app, @drawObject, true);
            app.DrawButton.Enable = 'off';
            app.DrawButton.Tooltip = {'Draw polygon object.'; ''; 'short-key = d'};
            app.DrawButton.Text = 'Draw';
            app.ObjectTable = uitable(app.ObjectsPanel);
            app.ObjectTable.ColumnName = {'#'; 'Name'};
            app.ObjectTable.RowName = {};
            app.ObjectTable.ColumnEditable = [false true];
            app.ObjectTable.CellEditCallback = createCallbackFcn(app, @updateTable, true);
            app.ObjectTable.CellSelectionCallback = createCallbackFcn(app, @setCurrentObject, true);
            app.RemoveButton = uibutton(app.ObjectsPanel, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @removeObject, true);
            app.RemoveButton.Enable = 'off';
            app.RemoveButton.Tooltip = {'Remove object.'; ''; 'short-key = r'};
            app.RemoveButton.Text = 'Remove';
            app.TrackingPanel = uipanel(app.mainFigure);
            app.TrackingPanel.Title = 'Tracking';
            app.GapSpinnerLabel = uilabel(app.TrackingPanel);
            app.GapSpinnerLabel.Text = 'Gap';
            app.GapSpinner = uispinner(app.TrackingPanel);
            app.GapSpinner.Limits = [-10 10];
            app.GapSpinner.RoundFractionalValues = 'on';
            app.GapSpinner.ValueDisplayFormat = '%d';
            app.GapSpinner.ValueChangedFcn = createCallbackFcn(app, @gapSpinner, true);
            app.GapSpinner.Tooltip = {'Select how many slices to skip.'; 'positive = track up'; 'negative = track down'};
            app.GapSpinner.Value = 1;
            app.SmoothingSpinnerLabel = uilabel(app.TrackingPanel);
            app.SmoothingSpinnerLabel.Text = 'Smoothing';
            app.SmoothingSpinner = uispinner(app.TrackingPanel);
            app.SmoothingSpinner.Step = 0.5;
            app.SmoothingSpinner.Limits = [0.5 3];
            app.SmoothingSpinner.ValueDisplayFormat = '%3.1f';
            app.SmoothingSpinner.Tooltip = {'This parameter controls the amount of diffusion-like regularization.'; ''; 'imregdemons applies the standard deviation of the Gaussian smoothing to regularize the accumulated field at each iteration. Larger values result in smoother output displacement fields. Smaller values result in more localized deformation in the output displacement field.'};
            app.SmoothingSpinner.Value = 1.5;
            app.PyramidlevelsSpinnerLabel = uilabel(app.TrackingPanel);
            app.PyramidlevelsSpinnerLabel.Text = 'Pyramid levels';
            app.PyramidlevelsSpinner = uispinner(app.TrackingPanel);
            app.PyramidlevelsSpinner.Limits = [1 3];
            app.PyramidlevelsSpinner.ValueDisplayFormat = '%d';
            app.PyramidlevelsSpinner.Tooltip = {'Number of multi-resolution image pyramid levels to use.'};
            app.PyramidlevelsSpinner.Value = 1;
            app.IterationsSpinnerLabel = uilabel(app.TrackingPanel);
            app.IterationsSpinnerLabel.Text = 'Iterations';
            app.IterationsSpinner = uispinner(app.TrackingPanel);
            app.IterationsSpinner.Step = 50;
            app.IterationsSpinner.Limits = [50 500];
            app.IterationsSpinner.ValueDisplayFormat = '%3d';
            app.IterationsSpinner.Tooltip = {'Number of iterations per resolution level.'};
            app.IterationsSpinner.Value = 200;
            app.UpdateButton = uibutton(app.TrackingPanel, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @trackObject, true);
            app.UpdateButton.Enable = 'off';
            app.UpdateButton.Tooltip = {'Update tracking.'; ''; 'short-key = u'};
            app.UpdateButton.Text = 'Update';
            app.TrackButton = uibutton(app.TrackingPanel, 'push');
            app.TrackButton.ButtonPushedFcn = createCallbackFcn(app, @trackObject, true);
            app.TrackButton.Enable = 'off';
            app.TrackButton.Tooltip = {'Track objects between slices.'; ''; 'short-key = t'};
            app.TrackButton.Text = 'Track';
            app.StatusLabel = uilabel(app.mainFigure);
            app.StatusLabel.Text = '';
            app.imagePanel = uipanel(app.mainFigure);
            app.imagePanel.Title = '<filename>';
            app.slice_up = uibutton(app.mainFigure, 'push');
            app.slice_up.ButtonPushedFcn = createCallbackFcn(app, @changeSlice, true);
            app.slice_up.Enable = 'off';
            app.slice_up.Text = '>';
            app.slice_down = uibutton(app.mainFigure, 'push');
            app.slice_down.ButtonPushedFcn = createCallbackFcn(app, @changeSlice, true);
            app.slice_down.Enable = 'off';
            app.slice_down.Text = '<';
            app.StatusLight = uilamp(app.mainFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = MuscleSegmenter_dev4

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.mainFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.mainFigure)
        end
    end
end