classdef MuscleSegmenter_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MuscleSegmenterv20UIFigure  matlab.ui.Figure
        FileMenu                    matlab.ui.container.Menu
        LoadimageMenu               matlab.ui.container.Menu
        LoadobjectMenu              matlab.ui.container.Menu
        SaveobjectMenu              matlab.ui.container.Menu
        SaveobjectasMenu            matlab.ui.container.Menu
        ExportobjectasmaskMenu      matlab.ui.container.Menu
        SliceLabel                  matlab.ui.control.Label
        SliceSlider                 matlab.ui.control.Slider
        ObjectsPanel                matlab.ui.container.Panel
        AddButton                   matlab.ui.control.Button
        UITable                     matlab.ui.control.Table
        RemoveButton                matlab.ui.control.Button
        TrackingPanel               matlab.ui.container.Panel
        GapSpinnerLabel             matlab.ui.control.Label
        GapSpinner                  matlab.ui.control.Spinner
        SmoothingSpinnerLabel       matlab.ui.control.Label
        SmoothingSpinner            matlab.ui.control.Spinner
        PyramidlevelsSpinnerLabel   matlab.ui.control.Label
        PyramidlevelsSpinner        matlab.ui.control.Spinner
        IterationsSpinnerLabel      matlab.ui.control.Label
        IterationsSpinner           matlab.ui.control.Spinner
        UpdateButton                matlab.ui.control.Button
        TrackButton                 matlab.ui.control.Button
        StatusLabel                 matlab.ui.control.Label
        imagePanel                  matlab.ui.container.Panel
        slice_up                    matlab.ui.control.Button
        slice_down                  matlab.ui.control.Button
        StatusLight                 matlab.ui.control.Lamp
    end

    
    properties (Access = private)
        img           % image data
        spacing       % pixel spacing
        dim           % image dimensions
        maxObjects    % maximum number objects
        currentObject_nr % number of the selected object in the UITable
        objectPos     % cell with position of nodes of polygon objects for all slice and objects
        slice_nr      % number of currently displayed slice
        stack_nr      % number of currently displayed stack (for multi-channel data)
        colors        % color map
        tableData     % list of label numbers and names
        h_img         % image object        
        h_obj         % polygon roi objects
        slice_nr_ref  % number of reference slice
        slice_nr_pred % number of predicted slice
        prev_gap      % previous gap value - used to exclude 0 gap
        fname_object  % filename of object structure
        hdr           % image NIfTI header
    end
    
    properties (Access = public)
        h_ax      matlab.graphics.axis.Axes 
    end
    
    methods (Access = private)
        
        function load_data(app,filename)
            
            % Update status bar
            app.StatusLabel.Text = 'Loading image...';
            app.StatusLight.Color = [1 0 0];
            
            % Load the data
            I = load_untouch_nii(filename);
            app.hdr = I.hdr;
            app.img = I.img;
            app.dim = I.hdr.dime.dim(2:4);
            app.spacing = I.hdr.dime.pixdim(2:4);
            
            [~,b,c] = fileparts(filename);
            app.StatusLabel.Text = sprintf('%s loaded',[b,c]);
            app.StatusLight.Color = [0 1 0];
            
            % Update slice slider
            app.SliceSlider.Limits     = [1 app.dim(3)];
            majorTicks                 = round(linspace(0,app.dim(3),11));
            majorTicks(1)              = 1;
            app.SliceSlider.MajorTicks = majorTicks;
            app.SliceSlider.MinorTicks = [];
            app.slice_nr               = round(app.dim(3)/2);
%             app.SliceSlider.Value      = app.slice_nr;
            
            % Update filename
            [~,a,b] = fileparts(filename);
            app.imagePanel.Title = [a b];
%             app.imagePanel.Tooltip = filename;
            
            % Create empty object cell.            
            app.objectPos = cell(app.maxObjects,app.dim(3));
            app.h_obj     = NaN(1,app.maxObjects);
            app.fname_object = [];
            app.SaveobjectMenu.Enable = 'off';
            
            % Enable objects
            app.slice_up.Enable     = 'on';
            app.slice_down.Enable   = 'on'; 
            app.SliceSlider.Enable  = 'on'; 
            app.AddButton.Enable    = 'on'; 
            app.RemoveButton.Enable = 'on';
            app.TrackButton.Enable  = 'on';
            
            % Update the plot
            event.Source.Tag        = 'init';
            updatePlot(app,event)
                
        end
              
        function funcAddProgComponents(app)
            % Create classic Axes object into the Panel
            app.h_ax = axes(app.imagePanel,...
                'Position',[0,0,1,1]);
        end
        
        function updateObject(app)
            % Update the cell with object position data of the current polygon graphics 
            % objects so that interactive changes are saved.
            for obj_nr = 1 : app.maxObjects
                if isgraphics(app.h_obj(obj_nr))
                    app.objectPos{obj_nr,app.slice_nr} =...
                        get(app.h_obj(obj_nr),'Position');
                else
                    app.objectPos{obj_nr,app.slice_nr} = [];
                end
            end
            
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.funcAddProgComponents;
%             app.MuscleSegmenterv20UIFigure.AutoResizeChildren = 'on';
            % Prefill table
            app.maxObjects = 12;
            app.tableData = cell(app.maxObjects,2);
            for label_nr = 1 : app.maxObjects
                app.tableData{label_nr,1} = sprintf('%d',label_nr);
                app.tableData{label_nr,2} = sprintf('StatusLabel%02d',label_nr);
            end
            app.UITable.Data = app.tableData;
            app.colors = [...                
                141,211,199;
                255,255,179;
                190,186,218;
                251,128,114;
                128,177,211;
                253,180,98;
                179,222,105;
                252,205,229;
                217,217,217;
                188,128,189;
                204,235,197;
                255,237,111] / 255;
            
            app.UITable.BackgroundColor = app.colors(1:app.maxObjects,:);
            
            % Set some tags.
            app.slice_up.Tag     = 'up';
            app.slice_down.Tag   = 'down';
            app.SliceSlider.Tag  = 'slider';
            app.TrackButton.Tag  = 'track';
            app.UpdateButton.Tag = 'update';     
            app.SaveobjectasMenu.Tag   = 'saveas';
            app.SaveobjectMenu.Tag     = 'save';
            
%           % Create an image object
%             app.h_img = imshow(zeros(2,2),[0 1],...
%                 'Parent',app.h_ax);
            app.h_img = image(app.h_ax,...
                zeros(2,2),...
                'CDataMapping','scaled');
            
            colormap(app.h_ax,gray(2000))
            app.h_ax.CLim = [0 1];
            axis(app.h_ax,'off')
            
            % Set previous gap value
            app.prev_gap = app.GapSpinner.Value;
%             set(app.h_ax,'Visible','off')
            
%             % Disable objects
%             app.slice_up.Enable     = 'off';
%             app.slice_down.Enable   = 'off'; 
%             app.SliceSlider.Enable  = 'off'; 
%             app.AddButton.Enable    = 'off'; 
%             app.RemoveButton.Enable = 'off';
%             app.TrackButton.Enable = 'off';
            
        end

        % Menu selected function: LoadimageMenu
        function selectImage(app, event)
            [filename,pathname] = uigetfile({'*.nii;*.nii.gz;',...
                'Image files (*.nii,*.nii.gz)'},...
                'Select an image file',[],'MultiSelect','off');
            if pathname == 0
                return
            else
                load_data(app,fullfile(pathname,filename));
            end
        end

        % Button pushed function: AddButton
        function addObject(app, event)
            % Get selected object from the UITable.
            if isempty(app.currentObject_nr)
                warndlg('No label selected from the table. Select a label and try again.',...
                    'No label selected','OK')
            else
                % If an object of the selected label already exists, delete this object first
                % before defining a new one.
                pos = get(app.objectPos{app.currentObject_nr,app.slice_nr},'Position');
                if ~isempty(pos)
                    delete(app.objectPos{app.currentObject_nr,app.slice_nr})
                    app.h_obj(app.currentObject_nr) = NaN;
                end
                app.StatusLight.Color = [1.00,0.41,0.16];
                app.StatusLabel.Text = 'Select corners. Click enter to close polygon...';
                lbl = '';
                app.h_obj(app.currentObject_nr) = drawpolygon(...
                    'Color',app.colors(app.currentObject_nr,:),...
                    'FaceAlpha',0.2,...
                    'Parent',app.h_ax,...
                    'Label',lbl);
                app.StatusLabel.Text = '';
                app.StatusLight.Color = [0 1 0];                
                
                % Add added position to objectPos
                updateObject(app)
                % Enable the track button and disable the update button.
                app.TrackButton.Enable = 'on';
                app.UpdateButton.Enable = 'off';
            end
        end

        % Cell selection callback: UITable
        function showCellMenu(app, event)
            % Set selected label to the current object number.
            app.currentObject_nr = event.Indices(1);
        end

        % Cell edit callback: UITable
        function updateTable(app, event)
            indices = event.Indices;
            newData = event.NewData;
            app.tableData{indices(1),indices(2)} = newData;
            
        end

        % Button pushed function: TrackButton, UpdateButton
        function trackObject(app, event)

            % Update the position data.
            updateObject(app)
            
            % Register slices with the selected settings
            
            switch event.Source.Tag
                case 'track'
                    % Track new slice.
                    app.slice_nr_ref  = app.slice_nr;
                    app.slice_nr_pred = app.slice_nr + app.GapSpinner.Value;
                case 'update'
                    % Track again with updated settings.
                    app.slice_nr = app.slice_nr_pred;
            end
            
            if app.slice_nr_pred < 1 || app.slice_nr_pred > app.dim(3)
                warndlg('Tried to predict outside the image range. Change the slice or slice gap and try again',...
                    'Slice outside range')
                return
            end
            
            slice1    = app.img(:,:,app.slice_nr_ref);
            slice2    = app.img(:,:,app.slice_nr_pred);
            
            app.StatusLabel.Text = 'Registering images...';
            app.StatusLight.Color = [1 0 0];
            tic
            [~,~,app.objectPos(:,app.slice_nr_pred)] = register_slices(slice2,slice1,...
                'N',                        app.IterationsSpinner.Value,...
                'PyramidLevels',            app.PyramidlevelsSpinner.Value,...
                'AccumulatedFieldSmoothing',app.SmoothingSpinner.Value,...
                'points',                   app.objectPos(:,app.slice_nr_ref));
            t_elapsed = toc;
%             app.StatusLabel.Text = '';
            app.StatusLabel.Text = sprintf('It took %.2f sec to track.',t_elapsed);
            app.StatusLight.Color = [0 1 0];            
            
            % Update the figure.
            updatePlot(app,event)
                        
            % Enable the update button.
            app.UpdateButton.Enable = 'on';
            
        end

        % Callback function: SliceSlider, slice_down, slice_up
        function updatePlot(app, event)
            
            % Update object position cell.
            if ~any(strcmp(event.Source.Tag,{'update','loadObj'}))
                updateObject(app)
            end
            
            switch event.Source.Tag
                case 'up'
                    % Scroll one slice up
                    app.slice_nr = min([app.dim(3),app.slice_nr + 1]);
                    app.UpdateButton.Enable = 'off';
                case 'down'
                    % Scroll one slice down
                    app.slice_nr = max([1,app.slice_nr - 1]);
                    app.UpdateButton.Enable = 'off';
                case 'slider'
                    % Show the slider-selected slice
                    app.slice_nr = round(app.SliceSlider.Value);
                    app.UpdateButton.Enable = 'off';
                case {'track','update'}
                    % Show the registration-predicted slice
                    app.slice_nr = app.slice_nr_pred;
                case 'init'
                    % Show the middle slice
                    app.slice_nr = round(app.dim(3)/2);
                    app.h_ax.XLimMode = 'auto';
                    app.h_ax.YLimMode = 'auto';
                    app.h_ax.Position = [0 0 1 1];
%                     app.h_ax.DataAspectRatio = 1./app.spacing;
                    axis(app.h_ax,'equal')
%                      maxdim = max(app.dim(1:2));                     
%                     app.h_ax.XLim = [1 maxdim] + (app.dim(2)-maxdim)/2;
%                     app.h_ax.YLim = [1 maxdim] + (app.dim(1)-maxdim)/2;
                    slice_data = app.img(:,:,app.slice_nr);
                    set(app.h_ax,...
                        'CLim',[min(slice_data(:)) max(slice_data(:))])
                    app.UpdateButton.Enable = 'off';
            end
            
            % Update slider
            app.SliceSlider.Value = app.slice_nr;
            app.SliceLabel.Text   = sprintf('%d/%d',app.slice_nr,app.dim(3));
            
            % Update image.
            set(app.h_img,...
                'CData',app.img(:,:,app.slice_nr))
            
            % Update the polygon graphics objects
            for obj_nr = 1 : app.maxObjects
                % Check if position data is available for the current slice and label.
                pos = app.objectPos{obj_nr,app.slice_nr};
                if ~isempty(pos)
                    % Position data is available. Create an interactive polygon.
                    lbl = '';
                    if isgraphics(app.h_obj(obj_nr))
                        delete(app.h_obj(obj_nr))
                    end
                    app.h_obj(obj_nr) = images.roi.Polygon('Parent',app.h_ax);
                    set(app.h_obj(obj_nr),...
                        'Label',lbl,...
                        'Color',app.colors(obj_nr,:),...
                        'FaceAlpha',0.2,...
                        'Position',pos,...
                        'InteractionsAllowed','all');
                else
                    % Position data is not available. Remove interactive polygon, if existing.
                    if isgraphics(app.h_obj(obj_nr))
                        delete(app.h_obj(obj_nr))
                        app.h_obj(obj_nr) = NaN;
                    end
                end
            end
            % If no objects are available on the current slice, disable the track button.
            if all(isnan(app.h_obj))
                app.TrackButton.Enable = 'off';
            else
                app.TrackButton.Enable = 'on';
            end
            % Disable the update button.
            app.UpdateButton.Enable = 'off';
        end

        % Button pushed function: RemoveButton
        function removeObject(app, event)
            % Get selected object from the UITable.
            if isempty(app.currentObject_nr)
                warndlg('No object selected from the table. Select a cell in the table and try again.',...
                    'No object selected','OK')
            else
                pos = app.objectPos{app.currentObject_nr,app.slice_nr};
                if ~isempty(pos)
                    % Delete the roi object and create an empty object
                    delete(app.h_obj(app.currentObject_nr))
                    app.h_obj(app.currentObject_nr) = NaN;
                    
                    % Remove position from objectPos
                    app.objectPos{app.currentObject_nr,app.slice_nr} = [];
                    
                end
            end            
        end

        % Size changed function: MuscleSegmenterv20UIFigure
        function changeSize(app, event)
            position = app.MuscleSegmenterv20UIFigure.Position;
            minsize = [640 500];
            if position(3) < minsize(1)
                position(3) = minsize(1);                
            end
            if position(4) < minsize(2)
                position(4) = minsize(2);                
            end
            app.MuscleSegmenterv20UIFigure.Position = position;
            
            % Status light and text
            p = app.StatusLight.Position;
            app.StatusLight.Position = [position(3)-30 p(2:4)];
            p = app.StatusLabel.Position;
            app.StatusLabel.Position = [position(3)-210 p(2:4)];
%             app.StatusLabel.Text = 'Status';
            
            % Object and tracking panel
            p = app.ObjectsPanel.Position;
            app.ObjectsPanel.Position = [position(3)-205 position(4)-215 p(3:4)];
            
            p = app.TrackingPanel.Position;
            app.TrackingPanel.Position = [position(3)-205 position(4)-425 p(3:4)];
            
            % Image panel
            p = app.imagePanel.Position;
            app.imagePanel.Position = [p(1) p(2) position(3)-220 position(4)-80];
            
            % Slider
            p = app.SliceSlider.Position;
            app.SliceSlider.Position = [p(1:2) position(3)-290 p(4)];
            p = app.slice_up.Position;
            app.slice_up.Position = [position(3)-230 p(2:4)];
            
            % Set axis equal again to mainrain stretch-to-fill behavior
            axis(app.h_ax,'equal')
            
        end

        % Key press function: MuscleSegmenterv20UIFigure
        function keyPress(app, event)
            key = event.Key;
            if strcmpi(key,'t')
                if strcmp(app.TrackButton.Enable,'on')
                    event.Source.Tag = 'track';
                    trackObject(app,event)
                end
            elseif strcmpi(key,'u')
                if strcmp(app.UpdateButton.Enable,'on')
                    event.Source.Tag = 'update';
                    trackObject(app,event)
                end
            elseif strcmpi(key,'a')
                addObject(app,event)
            elseif strcmpi(key,'r')
                removeObject(app,event)
            end
            
        end

        % Value changed function: GapSpinner
        function gapSpinner(app, event)
            value = app.GapSpinner.Value;
            if value == 0
                if app.prev_gap > 0
                    app.GapSpinner.Value = -1;
                    app.prev_gap = -1;
                else
                    app.GapSpinner.Value = 1;
                    app.prev_gap = 1;
                end
            end
                
        end

        % Menu selected function: SaveobjectMenu, SaveobjectasMenu
        function saveObject(app, event)
            if strcmp(event.Source.Tag,'saveas') || isempty(app.fname_object)
                % Select a file or set a new filename.
                [file,path] = uiputfile('*.mat','Select/set a filename to save object to');
                if path == 0
                    return
                else
                    app.fname_object = fullfile(path,file);
                end
            end
            objectPos = app.objectPos;
            save(app.fname_object,'objectPos')
            [~,b,c] = fileparts(app.fname_object);
            app.StatusLabel.Text = sprintf('Object saved to %s',[b,c]);
            app.SaveobjectMenu.Enable = 'on';
            
        end

        % Menu selected function: ExportobjectasmaskMenu
        function exportMask(app, event)
            % Create a mask from the polygon objects.
            mask = zeros(app.dim,'int16');
            for s_nr = 1 : app.dim(3)
                slice_label_mask = zeros(app.dim(1),app.dim(2),app.maxObjects);
                for obj_nr = 1 : app.maxObjects
                    pos = app.objectPos{obj_nr,s_nr};
                    if ~isempty(pos)
                        slice_label_mask(:,:,obj_nr) = poly2mask(pos(:,1),pos(:,2),app.dim(1),app.dim(2)) * obj_nr;
                    end
                end
                mask(:,:,s_nr) = max(slice_label_mask,[],3);
            end
            % Select a file
            [file,path] = uiputfile({'*.nii;*.nii.gz','NIfTI image files'},'Select a mask file','mask.nii.gz');
            fname_mask = fullfile(path,file);
            if endsWith(fname_mask,'.nii')
                fname_mask = [fname_mask '.gz'];                
            end
            M.img = mask;
            M.hdr = app.hdr;
            M.hdr.dime.datatype = 4;
            M.hdr.dime.bitpix = 16;
            M.hdr.dime.glmin = 0;
            M.hdr.dime.glmax = max(mask(:));
            M.hdr.dime.scl_slope = 1;
            M.hdr.dime.scl_inter = 0;
            M.hdr.dime.dim(1) = 3;
            M.hdr.dime.dim(5) = 1; 
            M.untouch = 1;
            save_untouch_nii(M,fname_mask)
            [~,b,c] = fileparts(fname_mask);
            app.StatusLabel.Text = sprintf('Mask saved as %s',[b,c]);
            
            
        end

        % Menu selected function: LoadobjectMenu
        function loadObject(app, event)
            [file,path] = uigetfile({'*.mat','Matlab MAT-file'},'Select an object file');
            if file == 0
                return
            else
                filedata = load(fullfile(path,file));
                if isfield(filedata,'objectPos')
                    if size(filedata.objectPos,2) ~= app.dim(3)
                        warndlg(sprintf('Selected object does not match the slice number of the current image.\nThe number of slices should be %d but was %d.',...
                            app.dim(3),size(filedata.objectPos,2)),...
                            'Wrong object dimensions')
                        return                        
                    end
                    app.objectPos = filedata.objectPos;
                    app.StatusLabel.Text = sprintf('Object file %s loaded',file);
                    % Update the graphics objects
                    events.Source.Tag = 'loadObj';
                    updatePlot(app,events)
                else
                    warndlg('Variable ''objectPos'' not found in the selecte MAT-file. Object data could not be loaded.')
                end
            end
                
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MuscleSegmenterv20UIFigure = uifigure;
            app.MuscleSegmenterv20UIFigure.Name = 'Muscle Segmenter v2.0';
            app.MuscleSegmenterv20UIFigure.SizeChangedFcn = createCallbackFcn(app, @changeSize, true);
            app.MuscleSegmenterv20UIFigure.KeyPressFcn = createCallbackFcn(app, @keyPress, true);
            app.FileMenu = uimenu(app.MuscleSegmenterv20UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadimageMenu = uimenu(app.FileMenu);
            app.LoadimageMenu.MenuSelectedFcn = createCallbackFcn(app, @selectImage, true);
            app.LoadimageMenu.Text = 'Load image...';
            app.LoadobjectMenu = uimenu(app.FileMenu);
            app.LoadobjectMenu.MenuSelectedFcn = createCallbackFcn(app, @loadObject, true);
            app.LoadobjectMenu.Text = 'Load object...';
            app.SaveobjectMenu = uimenu(app.FileMenu);
            app.SaveobjectMenu.MenuSelectedFcn = createCallbackFcn(app, @saveObject, true);
            app.SaveobjectMenu.Enable = 'off';
            app.SaveobjectMenu.Text = 'Save object';
            app.SaveobjectasMenu = uimenu(app.FileMenu);
            app.SaveobjectasMenu.MenuSelectedFcn = createCallbackFcn(app, @saveObject, true);
            app.SaveobjectasMenu.Text = 'Save object as...';
            app.ExportobjectasmaskMenu = uimenu(app.FileMenu);
            app.ExportobjectasmaskMenu.MenuSelectedFcn = createCallbackFcn(app, @exportMask, true);
            app.ExportobjectasmaskMenu.Text = 'Export object as mask...';
            app.SliceLabel = uilabel(app.MuscleSegmenterv20UIFigure);
            app.SliceLabel.Enable = 'off';
            app.SliceLabel.Text = 'Slice';
            app.SliceSlider = uislider(app.MuscleSegmenterv20UIFigure);
            app.SliceSlider.Limits = [1 10];
            app.SliceSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.SliceSlider.ValueChangedFcn = createCallbackFcn(app, @updatePlot, true);
            app.SliceSlider.MinorTicks = [1 1.1 1.2 1.3 1.4 1.5 1.6 1.7 1.8 1.9 2 2.1 2.2 2.3 2.4 2.5 2.6 2.7 2.8 2.9 3 3.1 3.2 3.3 3.4 3.5 3.6 3.7 3.8 3.9 4 4.1 4.2 4.3 4.4 4.5 4.6 4.7 4.8 4.9 5 5.1 5.2 5.3 5.4 5.5 5.6 5.7 5.8 5.9 6 6.1 6.2 6.3 6.4 6.5 6.6 6.7 6.8 6.9 7 7.1 7.2 7.3 7.4 7.5 7.6 7.7 7.8 7.9 8 8.1 8.2 8.3 8.4 8.5 8.6 8.7 8.8 8.9 9 9.1 9.2 9.3 9.4 9.5 9.6 9.7 9.8 9.9 10];
            app.SliceSlider.Enable = 'off';
            app.SliceSlider.Value = 1;
            app.ObjectsPanel = uipanel(app.MuscleSegmenterv20UIFigure);
            app.ObjectsPanel.Title = 'Objects';
            app.AddButton = uibutton(app.ObjectsPanel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @addObject, true);
            app.AddButton.Enable = 'off';
            app.AddButton.Tooltip = {'Add object.'; ''; 'short-key = a'};
            app.AddButton.Text = 'Add';
            app.UITable = uitable(app.ObjectsPanel);
            app.UITable.ColumnName = {'#'; 'Name'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @updateTable, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @showCellMenu, true);
            app.RemoveButton = uibutton(app.ObjectsPanel, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @removeObject, true);
            app.RemoveButton.Enable = 'off';
            app.RemoveButton.Tooltip = {'Remove object.'; ''; 'short-key = r'};
            app.RemoveButton.Text = 'Remove';
            app.TrackingPanel = uipanel(app.MuscleSegmenterv20UIFigure);
            app.TrackingPanel.Title = 'Tracking';
            app.GapSpinnerLabel = uilabel(app.TrackingPanel);
            app.GapSpinnerLabel.Text = 'Gap';
            app.GapSpinner = uispinner(app.TrackingPanel);
            app.GapSpinner.Limits = [-10 10];
            app.GapSpinner.RoundFractionalValues = 'on';
            app.GapSpinner.ValueDisplayFormat = '%d';
            app.GapSpinner.ValueChangedFcn = createCallbackFcn(app, @gapSpinner, true);
            app.GapSpinner.Tooltip = {'Select how many slices to skip.'; 'positive = track up'; 'negative = track down'};
            app.GapSpinner.Value = 1;
            app.SmoothingSpinnerLabel = uilabel(app.TrackingPanel);
            app.SmoothingSpinnerLabel.Text = 'Smoothing';
            app.SmoothingSpinner = uispinner(app.TrackingPanel);
            app.SmoothingSpinner.Step = 0.5;
            app.SmoothingSpinner.Limits = [0.5 3];
            app.SmoothingSpinner.ValueDisplayFormat = '%3.1f';
            app.SmoothingSpinner.Tooltip = {'This parameter controls the amount of diffusion-like regularization.imregdemons applies the standard deviation of the Gaussian smoothing to regularize the accumulated field at each iteration. Larger values result in smoother output displacement fields. Smaller values result in more localized deformation in the output displacement field. Values typically are in the range [0.5'; ' 3.0]. When you specify multiple PyramidLevels'; ' the standard deviation used in the Gaussian smoothing remains the same at each pyramid level.'};
            app.SmoothingSpinner.Value = 1.5;
            app.PyramidlevelsSpinnerLabel = uilabel(app.TrackingPanel);
            app.PyramidlevelsSpinnerLabel.Text = 'Pyramid levels';
            app.PyramidlevelsSpinner = uispinner(app.TrackingPanel);
            app.PyramidlevelsSpinner.Limits = [1 3];
            app.PyramidlevelsSpinner.ValueDisplayFormat = '%d';
            app.PyramidlevelsSpinner.Tooltip = {'Number of multi-resolution image pyramid levels to use.'};
            app.PyramidlevelsSpinner.Value = 1;
            app.IterationsSpinnerLabel = uilabel(app.TrackingPanel);
            app.IterationsSpinnerLabel.Text = 'Iterations';
            app.IterationsSpinner = uispinner(app.TrackingPanel);
            app.IterationsSpinner.Step = 50;
            app.IterationsSpinner.Limits = [50 500];
            app.IterationsSpinner.ValueDisplayFormat = '%3d';
            app.IterationsSpinner.Tooltip = {'Number of iterations per resolution level.'};
            app.IterationsSpinner.Value = 200;
            app.UpdateButton = uibutton(app.TrackingPanel, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @trackObject, true);
            app.UpdateButton.Enable = 'off';
            app.UpdateButton.Tooltip = {'Update tracking.'; ''; 'short-key = u'};
            app.UpdateButton.Text = 'Update';
            app.TrackButton = uibutton(app.TrackingPanel, 'push');
            app.TrackButton.ButtonPushedFcn = createCallbackFcn(app, @trackObject, true);
            app.TrackButton.Enable = 'off';
            app.TrackButton.Tooltip = {'Track objects between slices.'; ''; 'short-key = t'};
            app.TrackButton.Text = 'Track';
            app.StatusLabel = uilabel(app.MuscleSegmenterv20UIFigure);
            app.StatusLabel.Text = '';
            app.imagePanel = uipanel(app.MuscleSegmenterv20UIFigure);
            app.imagePanel.Title = '<filename>';
            app.slice_up = uibutton(app.MuscleSegmenterv20UIFigure, 'push');
            app.slice_up.ButtonPushedFcn = createCallbackFcn(app, @updatePlot, true);
            app.slice_up.Enable = 'off';
            app.slice_up.Text = '>';
            app.slice_down = uibutton(app.MuscleSegmenterv20UIFigure, 'push');
            app.slice_down.ButtonPushedFcn = createCallbackFcn(app, @updatePlot, true);
            app.slice_down.Enable = 'off';
            app.slice_down.Text = '<';
            app.StatusLight = uilamp(app.MuscleSegmenterv20UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = MuscleSegmenter_v2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MuscleSegmenterv20UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MuscleSegmenterv20UIFigure)
        end
    end
end